classdef TT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LimpiarButton                   matlab.ui.control.Button
        GenerarvideoButton              matlab.ui.control.Button
        DetenerButton                   matlab.ui.control.Button
        SimularButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MembranaTab                     matlab.ui.container.Tab
        ZSpinner                        matlab.ui.control.Spinner
        ZSpinnerLabel                   matlab.ui.control.Label
        YSpinner                        matlab.ui.control.Spinner
        YSpinnerLabel                   matlab.ui.control.Label
        XSpinner                        matlab.ui.control.Spinner
        XSpinnerLabel                   matlab.ui.control.Label
        IntervalodeTiempoSlider         matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel    matlab.ui.control.Label
        ConstantedeamortiguamientoSlider  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel  matlab.ui.control.Label
        ConstantederesorteSlider        matlab.ui.control.Slider
        ConstantederesorteSliderLabel   matlab.ui.control.Label
        DimensindelamallaEditField      matlab.ui.control.NumericEditField
        DimensindelamallaEditFieldLabel  matlab.ui.control.Label
        NmerodepartculasEditField       matlab.ui.control.NumericEditField
        NmerodepartculasEditFieldLabel  matlab.ui.control.Label
        FuerzaExternaLabel              matlab.ui.control.Label
        ParmetrosLabel                  matlab.ui.control.Label
        CuerdaTab                       matlab.ui.container.Tab
        NmerodepartculasEditField_2     matlab.ui.control.NumericEditField
        NmerodepartculasEditField_2Label  matlab.ui.control.Label
        ZSpinner_2                      matlab.ui.control.Spinner
        ZSpinnerLabel_2                 matlab.ui.control.Label
        YSpinner_2                      matlab.ui.control.Spinner
        YSpinnerLabel_2                 matlab.ui.control.Label
        XSpinner_2                      matlab.ui.control.Spinner
        XSpinnerLabel_2                 matlab.ui.control.Label
        IntervalodeTiempoSlider_2       matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_2  matlab.ui.control.Label
        ConstantedeamortiguamientoSlider_2  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_2  matlab.ui.control.Label
        ConstantederesorteSlider_2      matlab.ui.control.Slider
        ConstantederesorteSliderLabel_2  matlab.ui.control.Label
        FuerzaExternaLabel_2            matlab.ui.control.Label
        ParmetrosLabel_2                matlab.ui.control.Label
        VigaTab                         matlab.ui.container.Tab
        NmerodepartculasEditField_3     matlab.ui.control.NumericEditField
        NmerodepartculasEditField_3Label  matlab.ui.control.Label
        ZSpinner_3                      matlab.ui.control.Spinner
        ZSpinnerLabel_3                 matlab.ui.control.Label
        YSpinner_3                      matlab.ui.control.Spinner
        YSpinnerLabel_3                 matlab.ui.control.Label
        XSpinner_3                      matlab.ui.control.Spinner
        XSpinnerLabel_3                 matlab.ui.control.Label
        IntervalodeTiempoSlider_3       matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_3  matlab.ui.control.Label
        ConstantedeamortiguamientoSlider_3  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_3  matlab.ui.control.Label
        ConstantederesorteSlider_3      matlab.ui.control.Slider
        ConstantederesorteSliderLabel_3  matlab.ui.control.Label
        DimensindelamallaEditFieldLabel_3  matlab.ui.control.Label
        FuerzaExternaLabel_3            matlab.ui.control.Label
        ParmetrosLabel_3                matlab.ui.control.Label
        VigaCompuestaTab                matlab.ui.container.Tab
        NmerodepartculasEditField_4     matlab.ui.control.NumericEditField
        NmerodepartculasEditField_4Label  matlab.ui.control.Label
        ZSpinner_4                      matlab.ui.control.Spinner
        ZSpinnerLabel_4                 matlab.ui.control.Label
        YSpinner_4                      matlab.ui.control.Spinner
        YSpinnerLabel_4                 matlab.ui.control.Label
        XSpinner_4                      matlab.ui.control.Spinner
        XSpinnerLabel_4                 matlab.ui.control.Label
        IntervalodeTiempoSlider_4       matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_4  matlab.ui.control.Label
        ConstantedeamortiguamientoSlider_4  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_4  matlab.ui.control.Label
        ConstantederesorteSlider_4      matlab.ui.control.Slider
        ConstantederesorteSliderLabel_4  matlab.ui.control.Label
        DimensindelamallaEditFieldLabel_4  matlab.ui.control.Label
        FuerzaExternaLabel_4            matlab.ui.control.Label
        ParmetrosLabel_4                matlab.ui.control.Label
        ESCOMIPNLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SimulacindevibracionesencuerposslidosLabel  matlab.ui.control.Label
        SimulationAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Flag to control the simulation loop
        StopFlag = false;
        SelectedTab string = 'Membrana';

        % Constantes:
        KResorte = 5;
        KAmortiguamiento = 0.5;
        KTiempo = 0.01;
        NumCal = 400;
        sizeEjeX = 10;
        sizeEjeY = 5;
        sizeEjeZ = 1;
        NivRompimiento = 0.4;
        FuerzaEjeX = 0;
        FuerzaEjeY = 0;
        FuerzaEjeZ = -5;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            
            % Simulation parameters
            vec = 2; % vecinos considerados en calculo
            K = app.KResorte; % Constante del resorte entre la masa
            K2 = app.KAmortiguamiento; % Amortiguamiento
            T = app.KTiempo; % Tiempo
            D = 60; % Dimension
            
            % Initialization
            H = zeros(D); % posición 
            Hf = zeros(D); % Auxiliar
            V = zeros(D); % velocidad
            H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
            H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso
        
            % Main loop for calculations
            for m = 1:1000
                % Check if the stop button was pressed
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                end

                % Plot the current state
                surf(app.SimulationAxes, H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                camlight(app.SimulationAxes, 'right');
                axis(app.SimulationAxes, [0, D, 0, D, -1, 1]);
                drawnow;
                
                % Perform the calculations
                for j = vec+1:D-vec
                    for i = vec+1:D-vec
                        Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                        X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                        V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                        Hf(i, j) = H(i, j) + V(i, j) * T;
                    end
                end
                H = Hf;
                pause(0.001);
            end
        end

        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;

            % Simulation parameters
            K1 = app.KResorte; % Constante de resorte
            K2 = app.KAmortiguamiento; % Amortiguamiento
            rompimiento = 100; % Nivel de rompimiento
            Fext = [app.FuerzaEjeX; app.FuerzaEjeY; app.FuerzaEjeY]; % Fuerza externa
            T = app.KTiempo; % Intervalo de tiempo
            numgrap = 0; % Indice de graficas
            sonido = 1; % Indice de onda
            
            % Initialization
            numPart = app.NumCal;
            k = 0; % Indice para particulas
            for x = 1:numPart
                k = k + 1;
                p(k).r = [x; 0; 0];
                p(k).v = [0; 0; 0];
                p(k).fija = 0;
                w = 0; % Indice para vecinos
                for nx = x-1:2:x+1 % Vecinos de la particula + ella misma
                    if (1 <= nx && nx <= numPart)
                        w = w + 1;
                        p(k).n(w).ind = nx; % Indice del vecino w
                        p(k).n(w).disteq = 0.1;
                    end
                end
                p(k).numVec = w;
            end
            p(1).fija = 1;
            p(numPart).fija = 1;
            pf = p; % Auxiliar

            % Simulation loop
            numcalculos = 2000; % 24cuad p/seg * 10 seg * 5 calculos por cuadro
            Amp = 0;
            for k = 1:numcalculos % Calculos

                 if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                 end

                % Calculos
                for in = 2:numPart-1
                    if (in == floor(numPart/3) && k < 70)
                        Fext = [0; -30; 0];
                    else
                        Fext = [0; 0; 0];
                    end % Libera sistema de fuerza externa
                    if (p(in).fija == 0) % Exeptua el calculo particulas fijas
                        DX = [0; 0; 0]; % Calcula el desplazamiento
                        for w = 1:p(in).numVec % Vecinos de la particula + ella misma
                            if (p(in).n(w).ind ~= 0) % Existe o no esta roto
                                DR = p(in).r - p(p(in).n(w).ind).r; % Vector posicion relativa
                                dr = norm(DR);
                            end 
                            if 0 % dr>(rompimiento*p(in).n(w).disteq) % Rompimiento es la fraccion a superar
                                pf(in).n(w).ind = 0;
                            end
                            r = DR/dr; % Unitario en la direccion de desplazamiento
                            DX = DX + (dr - p(in).n(w).disteq) * r; % Vector desplazamiento
                        end        
                        % Calculos
                        pf(in).v = p(in).v + (-K1 * DX - K2 * p(in).v + Fext) * T;
                        pf(in).r = p(in).r + pf(in).v * T;
                    end % Exeptua particulas fijas
                end
                p = pf;
                % Graficacion
                if mod(k, 2) == 0 % Para formar onda
                    S(sonido) = p(numPart/2).r(2); % 4indPtoMed).r(2);
                    sonido = sonido + 1;
                end
                if 1 % Graficacion
                    XX = zeros(1, numPart);
                    YY = zeros(1, numPart);
                    for x = 1:numPart
                        XX(x) = p(x).r(1);
                        YY(x) = p(x).r(2);
                    end
                    plot(app.SimulationAxes, XX, YY, '.-');
                    axis(app.SimulationAxes, [-1, numPart + 0.5, -1, 1]);
                    drawnow;
                    pause(0.001);
                end
            end % Terminan k calculos
            % figure; plot(S);
        end
        
        function initializeViga(app)
            numcalculos=round(str2double(app.NumCal)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            K1=app.KResorte ;%%constante de resorte
            K2=app.KAmortiguamiento ;%%amortiguamiento
            rompimiento=app.NiveldeRompimientoSlider.Value ;%%nivel de rompimiento
            gravedad=[app.FuerzaEjeX;app.FuerzaEjeY;app.FuerzaEjeZ];%%Fuerza externa
            T=app.KTiempo ;%%intervalo de tiempo
            d1=round(str2double(app.sizeEjeX));%%dimension x de la malla
            d2=round(str2double(app.sizeEjeY));%%dimension y de la malla
            d3=round(str2double(app.sizeEjeZ));%%dimension z de la malla
            numpuntos=d1*d2*d3;
            indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
            k=0;%%indice para partículas
            numgrap=1;%%indice de graficas
            sonido=1;%%indice de onda
            %%Bloque para considerar mallas de espesor 1
                if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                end
                if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                end
                if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                end
            %%termina bloque
            for z=0:d3-1%%Bloque de Inicialización de posiciones
               for y=0:d2-1
                  for x=1:d1
                     k=k+1;
                     p(k).r=[x;y;z];%%posición inicial de las partículas
                     p(k).v=[0;0;0];%%velocidad inicial
                     w=1;%%indice para vecinos
                     for nz=z-1:z+1
                        for ny=y-1:y+1
         		            for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                              if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                 p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                 p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	            else p(k).n(w).ind=0;
                              end
                              w=w+1;
                           end
                        end
                     end
                     p(k).n(14).ind=0;%particula misma
                  end
               end
            end%%Termina inicialización
            pf=p;%auxiliar
            for k=1:numcalculos%%calculos
                k
                %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    drawnow;
                    break;
                end
  	            %% CALCULOS del automata celular
	            for z=0:(d3-1)%%%%%todos 0:d3-1
     	            for y=0:(d2-1)%%%todos 0:d2-1
        	            for x=1:d1%%%todos 1:d1
                            ind=x+d1*y+d1*d2*z;
     			            DX=[0;0;0];
     			            for w=1:27% vecinos de la particula + ellamisma
        			            if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				            DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                    dr=norm(DR);
                                    if dr>(rompimiento*p(ind).n(w).disteq)
                                        pf(ind).n(w).ind=0;
                                    end            
                                    r=DR/dr;%unitario en la dirección de desplazamiento              
                                    DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                end
                            end        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                 if (x==round(d1/2)); gravedad=[0;0;-20];
                                 else gravedad=[0;0;-2];
                                 end;
  	  				            pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                pf(ind).r=p(ind).r+pf(ind).v*T;              
                            end                  
     		            end%x
    	            end%y 
                end%z    
                p=pf;
                %% terminan calculos del automata celular  
                if (mod(k,5)==0)%para formar onda 
                  S(sonido)=p(indPtoMed).r(2);
                  sonido=sonido+1;
                end
                %%empieza graficación
                if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	            numpolig=0;clear X;clear Y;clear Z;
   	            if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		            for z=0:incz:lzp
                        for y=0:ly
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*(y+1)+d1*d2*z;
            			            ind4=x+d1*(y+1)+d1*d2*z;
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			            Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end%x
	  		             end%y
   		            end%z
   	            end%%plano z
                if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		            for y=0:incy:lyp
      		            for z=0:lz
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*y+d1*d2*(z+1);
            			            ind4=x+d1*y+d1*d2*(z+1);
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
		   	                end%x
                         end%y
		             end%z
                 end%%plano y
   	            if (d2>1 & d3>1)%%calcula poligonos de planos X
   		            for x=1:incx:lxp
      		            for z=0:lz
         		            for y=0:ly
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+d1*y+d1*d2*(z+1);
            			            ind3=x+d1*(y+1)+d1*d2*(z+1);
			                        ind4=x+d1*(y+1)+d1*d2*z;
         			               XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end               
                        end
		             end
                 end%%plano x
                  
                  %%grafica en matlab
                    fill3(app.SimulationAxes,X,Y,Z,Z);
                    axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                    xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                    M(numgrap)=getframe(app.SimulationAxes);
		            numgrap=numgrap+1;
               end%graficacion
            end;%%terminan k calculos
            movie(app.SimulationAxes,M);
        end

        function initializeVigaCompuesta(app)
            numcalculos=round(str2double(app.NumCal)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            K1=app.KResorte ;%%constante de resorte
            K2=app.KAmortiguamiento ;%%amortiguamiento
            rompimiento=app.NiveldeRompimientoSlider.Value ;%%nivel de rompimiento
            gravedad=[app.FuerzaEjeX;app.FuerzaEjeY;app.FuerzaEjeZ];%%Fuerza externa
            T=app.KTiempo ;%%intervalo de tiempo
            d1=round(str2double(app.sizeEjeX));%%dimension x de la malla
            d2=round(str2double(app.sizeEjeY));%%dimension y de la malla
            d3=round(str2double(app.sizeEjeZ));%%dimension z de la malla
            numpuntos=d1*d2*d3;
            indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
            k=0;%%indice para partículas
            numgrap=1;%%indice de graficas
            sonido=1;%%indice de onda
            %%Bloque para considerar mallas de espesor 1
                if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                end
                if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                end
                if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                end
            %%termina bloque
            for z=0:d3-1%%Bloque de Inicialización de posiciones
               for y=0:d2-1
                  for x=1:d1
                     k=k+1;
                     p(k).r=[x;y;z];%%posición inicial de las partículas
                     p(k).v=[0;0;0];%%velocidad inicial
                     w=1;%%indice para vecinos
                     for nz=z-1:z+1
                        for ny=y-1:y+1
         		            for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                              if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                 p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                 p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	            else p(k).n(w).ind=0;
                              end
                              w=w+1;
                           end
                        end
                     end
                     p(k).n(14).ind=0;%particula misma
                  end
               end
            end%%Termina inicialización
            pf=p;%auxiliar
            for k=1:numcalculos%%calculos
                k
                %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    drawnow;
                    break;
                end
  	            %% CALCULOS del automata celular
	            for z=0:(d3-1)%%%%%todos 0:d3-1
     	            for y=0:(d2-1)%%%todos 0:d2-1
        	            for x=1:d1%%%todos 1:d1
                            ind=x+d1*y+d1*d2*z;
     			            DX=[0;0;0];
     			            for w=1:27% vecinos de la particula + ellamisma
        			            if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				            DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                    dr=norm(DR);
                                    if dr>(rompimiento*p(ind).n(w).disteq)
                                        pf(ind).n(w).ind=0;
                                    end            
                                    r=DR/dr;%unitario en la dirección de desplazamiento              
                                    DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                end
                            end        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                 if (x==round(d1/2)); gravedad=[0;0;-20];
                                 else gravedad=[0;0;-2];
                                 end;
  	  				            pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                pf(ind).r=p(ind).r+pf(ind).v*T;              
                            end                  
     		            end%x
    	            end%y 
                end%z    
                p=pf;
                %% terminan calculos del automata celular  
                if (mod(k,5)==0)%para formar onda 
                  S(sonido)=p(indPtoMed).r(2);
                  sonido=sonido+1;
                end
                %%empieza graficación
                if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	            numpolig=0;clear X;clear Y;clear Z;
   	            if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		            for z=0:incz:lzp
                        for y=0:ly
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*(y+1)+d1*d2*z;
            			            ind4=x+d1*(y+1)+d1*d2*z;
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			            Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end%x
	  		             end%y
   		            end%z
   	            end%%plano z
                if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		            for y=0:incy:lyp
      		            for z=0:lz
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*y+d1*d2*(z+1);
            			            ind4=x+d1*y+d1*d2*(z+1);
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
		   	                end%x
                         end%y
		             end%z
                 end%%plano y
   	            if (d2>1 & d3>1)%%calcula poligonos de planos X
   		            for x=1:incx:lxp
      		            for z=0:lz
         		            for y=0:ly
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+d1*y+d1*d2*(z+1);
            			            ind3=x+d1*(y+1)+d1*d2*(z+1);
			                        ind4=x+d1*(y+1)+d1*d2*z;
         			               XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end               
                        end
		             end
                 end%%plano x
                  
                  %%grafica en matlab
                    fill3(app.SimulationAxes,X,Y,Z,Z);
                    axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                    xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                    M(numgrap)=getframe(app.SimulationAxes);
		            numgrap=numgrap+1;
               end%graficacion
            end;%%terminan k calculos
            movie(app.SimulationAxes,M);
        end

        function updateButtonColors(app, selectedButton)
                % Reset colors of all buttons
                app.CuerdaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.MembranaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.VigaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.VigacompuestaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color

                % Set the selected button color
                selectedButton.BackgroundColor = [0.52, 0.59, 0.81]; % Green color for selected button
                
                % Update the selected button property
                app.SelectedButton = selectedButton;            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NmerodepartculasEditField
        function NmerodepartculasEditFieldValueChanged(app, event)
            app.NumCal = app.NumerodecalculosEditField.Value;
        end

        % Value changed function: ConstantederesorteSlider
        function ConstantederesorteSliderValueChanged(app, event)
            app.KResorte = app.ConstantederesorteSlider.Value;
            
        end

        % Value changed function: ConstantedeamortiguamientoSlider
        function ConstantedeamortiguamientoSliderValueChanged(app, event)
            app.KAmortiguamiento = app.ConstantedeamortiguamientoSlider.Value;
            
        end

        % Value changed function: IntervalodeTiempoSlider
        function IntervalodeTiempoSliderValueChanged(app, event)
            app.KTiempo = app.IntervalodeTiempoSlider.Value;
            
        end

        % Value changed function: XSpinner
        function XSpinnerValueChanged(app, event)
            app.FuerzaEjeX = app.EjeXSpinner.Value;
            
        end

        % Value changed function: YSpinner
        function YSpinnerValueChanged(app, event)
            app.FuerzaEjeY = app.EjeYSpinner.Value;
            
        end

        % Value changed function: ZSpinner
        function ZSpinnerValueChanged(app, event)
            app.FuerzaEjeZ = app.EjeZSpinner.Value;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            app.StopFlag = false;
            app.KResorte = app.ConstantederesorteSlider.Value; % Actualiza KResorte antes de iniciar la simulación
            app.KAmortiguamiento = app.ConstantedeamortiguamientoSlider.Value;
            app.KTiempo = app.IntervalodeTiempoSlider.Value;
            
            if strcmp(app.SelectedTab, 'Membrana')
                app.initializeMalla();
            elseif strcmp(app.SelectedTab, 'Cuerda')
                app.initializeCuerda();
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            % Set the stop flag to true
            app.StopFlag = true;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.StopFlag = true;
            cla(app.SimulationAxes);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.SelectedTab = app.TabGroup.SelectedTab.Title;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Tag = 'SimulationAxes';
            app.SimulacindevibracionesencuerposslidosLabel = uilabel(app.UIFigure);
            app.SimulacindevibracionesencuerposslidosLabel.Text = 'Simulación de vibraciones en cuerpos sólidos';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ESCOMIPNLabel = uilabel(app.UIFigure);
            app.ESCOMIPNLabel.Text = 'ESCOM - IPN ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MembranaTab = uitab(app.TabGroup);
            app.MembranaTab.Title = 'Membrana';
            app.ParmetrosLabel = uilabel(app.MembranaTab);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.FuerzaExternaLabel = uilabel(app.MembranaTab);
            app.FuerzaExternaLabel.Text = 'Fuerza Externa';
            app.NmerodepartculasEditFieldLabel = uilabel(app.MembranaTab);
            app.NmerodepartculasEditFieldLabel.Text = 'Número de partículas';
            app.NmerodepartculasEditField = uieditfield(app.MembranaTab, 'numeric');
            app.NmerodepartculasEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodepartculasEditFieldValueChanged, true);
            app.DimensindelamallaEditFieldLabel = uilabel(app.MembranaTab);
            app.DimensindelamallaEditFieldLabel.Text = 'Dimensión de la malla';
            app.DimensindelamallaEditField = uieditfield(app.MembranaTab, 'numeric');
            app.ConstantederesorteSliderLabel = uilabel(app.MembranaTab);
            app.ConstantederesorteSliderLabel.Text = 'Constante de resorte';
            app.ConstantederesorteSlider = uislider(app.MembranaTab);
            app.ConstantederesorteSlider.Limits = [1 1500];
            app.ConstantederesorteSlider.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ConstantederesorteSlider.ValueChangedFcn = createCallbackFcn(app, @ConstantederesorteSliderValueChanged, true);
            app.ConstantederesorteSlider.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel = uilabel(app.MembranaTab);
            app.ConstantedeamortiguamientoSliderLabel.Text = 'Constante de amortiguamiento';
            app.ConstantedeamortiguamientoSlider = uislider(app.MembranaTab);
            app.ConstantedeamortiguamientoSlider.Limits = [0.001 1];
            app.ConstantedeamortiguamientoSlider.ValueChangedFcn = createCallbackFcn(app, @ConstantedeamortiguamientoSliderValueChanged, true);
            app.ConstantedeamortiguamientoSlider.Value = 0.001;
            app.IntervalodeTiempoSliderLabel = uilabel(app.MembranaTab);
            app.IntervalodeTiempoSliderLabel.Text = 'Intervalo de Tiempo';
            app.IntervalodeTiempoSlider = uislider(app.MembranaTab);
            app.IntervalodeTiempoSlider.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.IntervalodeTiempoSlider.ValueChangedFcn = createCallbackFcn(app, @IntervalodeTiempoSliderValueChanged, true);
            app.IntervalodeTiempoSlider.Value = 0.0001;
            app.XSpinnerLabel = uilabel(app.MembranaTab);
            app.XSpinnerLabel.Text = 'X';
            app.XSpinner = uispinner(app.MembranaTab);
            app.XSpinner.ValueChangedFcn = createCallbackFcn(app, @XSpinnerValueChanged, true);
            app.YSpinnerLabel = uilabel(app.MembranaTab);
            app.YSpinnerLabel.Text = 'Y';
            app.YSpinner = uispinner(app.MembranaTab);
            app.YSpinner.ValueChangedFcn = createCallbackFcn(app, @YSpinnerValueChanged, true);
            app.ZSpinnerLabel = uilabel(app.MembranaTab);
            app.ZSpinnerLabel.Text = 'Z';
            app.ZSpinner = uispinner(app.MembranaTab);
            app.ZSpinner.ValueChangedFcn = createCallbackFcn(app, @ZSpinnerValueChanged, true);
            app.CuerdaTab = uitab(app.TabGroup);
            app.CuerdaTab.Title = 'Cuerda';
            app.ParmetrosLabel_2 = uilabel(app.CuerdaTab);
            app.ParmetrosLabel_2.Text = 'Parámetros';
            app.FuerzaExternaLabel_2 = uilabel(app.CuerdaTab);
            app.FuerzaExternaLabel_2.Text = 'Fuerza Externa';
            app.ConstantederesorteSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantederesorteSliderLabel_2.Text = 'Constante de resorte';
            app.ConstantederesorteSlider_2 = uislider(app.CuerdaTab);
            app.ConstantederesorteSlider_2.Limits = [1 1500];
            app.ConstantederesorteSlider_2.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ConstantederesorteSlider_2.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantedeamortiguamientoSliderLabel_2.Text = 'Constante de amortiguamiento';
            app.ConstantedeamortiguamientoSlider_2 = uislider(app.CuerdaTab);
            app.ConstantedeamortiguamientoSlider_2.Limits = [0.001 1];
            app.ConstantedeamortiguamientoSlider_2.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.IntervalodeTiempoSliderLabel_2.Text = 'Intervalo de Tiempo';
            app.IntervalodeTiempoSlider_2 = uislider(app.CuerdaTab);
            app.IntervalodeTiempoSlider_2.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider_2.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.IntervalodeTiempoSlider_2.Value = 0.0001;
            app.XSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.XSpinnerLabel_2.Text = 'X';
            app.XSpinner_2 = uispinner(app.CuerdaTab);
            app.YSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.YSpinnerLabel_2.Text = 'Y';
            app.YSpinner_2 = uispinner(app.CuerdaTab);
            app.ZSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.ZSpinnerLabel_2.Text = 'Z';
            app.ZSpinner_2 = uispinner(app.CuerdaTab);
            app.NmerodepartculasEditField_2Label = uilabel(app.CuerdaTab);
            app.NmerodepartculasEditField_2Label.Text = 'Número de partículas';
            app.NmerodepartculasEditField_2 = uieditfield(app.CuerdaTab, 'numeric');
            app.VigaTab = uitab(app.TabGroup);
            app.VigaTab.Title = 'Viga';
            app.ParmetrosLabel_3 = uilabel(app.VigaTab);
            app.ParmetrosLabel_3.Text = 'Parámetros';
            app.FuerzaExternaLabel_3 = uilabel(app.VigaTab);
            app.FuerzaExternaLabel_3.Text = 'Fuerza Externa';
            app.DimensindelamallaEditFieldLabel_3 = uilabel(app.VigaTab);
            app.DimensindelamallaEditFieldLabel_3.Text = 'Dimensión de la malla';
            app.ConstantederesorteSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantederesorteSliderLabel_3.Text = 'Constante de resorte';
            app.ConstantederesorteSlider_3 = uislider(app.VigaTab);
            app.ConstantederesorteSlider_3.Limits = [1 1500];
            app.ConstantederesorteSlider_3.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ConstantederesorteSlider_3.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantedeamortiguamientoSliderLabel_3.Text = 'Constante de amortiguamiento';
            app.ConstantedeamortiguamientoSlider_3 = uislider(app.VigaTab);
            app.ConstantedeamortiguamientoSlider_3.Limits = [0.001 1];
            app.ConstantedeamortiguamientoSlider_3.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_3 = uilabel(app.VigaTab);
            app.IntervalodeTiempoSliderLabel_3.Text = 'Intervalo de Tiempo';
            app.IntervalodeTiempoSlider_3 = uislider(app.VigaTab);
            app.IntervalodeTiempoSlider_3.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider_3.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.IntervalodeTiempoSlider_3.Value = 0.0001;
            app.XSpinnerLabel_3 = uilabel(app.VigaTab);
            app.XSpinnerLabel_3.Text = 'X';
            app.XSpinner_3 = uispinner(app.VigaTab);
            app.YSpinnerLabel_3 = uilabel(app.VigaTab);
            app.YSpinnerLabel_3.Text = 'Y';
            app.YSpinner_3 = uispinner(app.VigaTab);
            app.ZSpinnerLabel_3 = uilabel(app.VigaTab);
            app.ZSpinnerLabel_3.Text = 'Z';
            app.ZSpinner_3 = uispinner(app.VigaTab);
            app.NmerodepartculasEditField_3Label = uilabel(app.VigaTab);
            app.NmerodepartculasEditField_3Label.Text = 'Número de partículas';
            app.NmerodepartculasEditField_3 = uieditfield(app.VigaTab, 'numeric');
            app.VigaCompuestaTab = uitab(app.TabGroup);
            app.VigaCompuestaTab.Title = 'Viga Compuesta';
            app.ParmetrosLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ParmetrosLabel_4.Text = 'Parámetros';
            app.FuerzaExternaLabel_4 = uilabel(app.VigaCompuestaTab);
            app.FuerzaExternaLabel_4.Text = 'Fuerza Externa';
            app.DimensindelamallaEditFieldLabel_4 = uilabel(app.VigaCompuestaTab);
            app.DimensindelamallaEditFieldLabel_4.Text = 'Dimensión de la malla';
            app.ConstantederesorteSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantederesorteSliderLabel_4.Text = 'Constante de resorte';
            app.ConstantederesorteSlider_4 = uislider(app.VigaCompuestaTab);
            app.ConstantederesorteSlider_4.Limits = [1 1500];
            app.ConstantederesorteSlider_4.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ConstantederesorteSlider_4.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantedeamortiguamientoSliderLabel_4.Text = 'Constante de amortiguamiento';
            app.ConstantedeamortiguamientoSlider_4 = uislider(app.VigaCompuestaTab);
            app.ConstantedeamortiguamientoSlider_4.Limits = [0.001 1];
            app.ConstantedeamortiguamientoSlider_4.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.IntervalodeTiempoSliderLabel_4.Text = 'Intervalo de Tiempo';
            app.IntervalodeTiempoSlider_4 = uislider(app.VigaCompuestaTab);
            app.IntervalodeTiempoSlider_4.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider_4.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.IntervalodeTiempoSlider_4.Value = 0.0001;
            app.XSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.XSpinnerLabel_4.Text = 'X';
            app.XSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.YSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.YSpinnerLabel_4.Text = 'Y';
            app.YSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.ZSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ZSpinnerLabel_4.Text = 'Z';
            app.ZSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.NmerodepartculasEditField_4Label = uilabel(app.VigaCompuestaTab);
            app.NmerodepartculasEditField_4Label.Text = 'Número de partículas';
            app.NmerodepartculasEditField_4 = uieditfield(app.VigaCompuestaTab, 'numeric');
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GenerarvideoButton = uibutton(app.UIFigure, 'push');
            app.GenerarvideoButton.Text = 'Generar video';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end