classdef TT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParametrosLabel                 matlab.ui.control.Label
        NumerodecalculosEditField       matlab.ui.control.EditField
        NumerodecalculosEditFieldLabel  matlab.ui.control.Label
        EjeZEditField                   matlab.ui.control.EditField
        EjeZEditFieldLabel              matlab.ui.control.Label
        EjeYEditField                   matlab.ui.control.EditField
        EjeYEditFieldLabel              matlab.ui.control.Label
        EjeXEditField                   matlab.ui.control.EditField
        EjeXEditFieldLabel              matlab.ui.control.Label
        DimensiondelamallaLabel         matlab.ui.control.Label
        FuerzaexternaLabel              matlab.ui.control.Label
        EjeZSpinner                     matlab.ui.control.Spinner
        EjeZSpinnerLabel                matlab.ui.control.Label
        EjeYSpinner                     matlab.ui.control.Spinner
        EjeYSpinnerLabel                matlab.ui.control.Label
        EjeXSpinner                     matlab.ui.control.Spinner
        EjeXSpinnerLabel                matlab.ui.control.Label
        IntervalodeTiempoSliderLabel    matlab.ui.control.Label
        IntervalodeTiempoSlider         matlab.ui.control.Slider
        NiveldeRompimientoSlider        matlab.ui.control.Slider
        NiveldeRompimientoSliderLabel   matlab.ui.control.Label
        ConstantedeamortiguamientoSlider  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel  matlab.ui.control.Label
        ConstantederesorteSlider        matlab.ui.control.Slider
        ConstantederesorteSliderLabel   matlab.ui.control.Label
        ESCOMIPNLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        VigacompuestaButton             matlab.ui.control.Button
        VigaButton                      matlab.ui.control.Button
        CuerdaButton                    matlab.ui.control.Button
        MembranaButton                  matlab.ui.control.Button
        SeleccionalaestructuraasimularLabel  matlab.ui.control.Label
        SimulacindevibracionesencuerposslidosLabel  matlab.ui.control.Label
        LimpiarButton                   matlab.ui.control.Button
        DetenersimulacinButton          matlab.ui.control.Button
        SimularButton                   matlab.ui.control.Button
        SimulationAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Flag to control the simulation loop
        StopFlag = false;
        SelectedButton matlab.ui.control.Button

        % Constantes:
        KResorte = 5;
        KAmortiguamiento = 0.5;
        KTiempo = 0.01;
        NumCal = 400;
        sizeEjeX = 10;
        sizeEjeY = 5;
        sizeEjeZ = 1;
        NivRompimiento = 0.4;
        FuerzaEjeX = 0;
        FuerzaEjeY = 0;
        FuerzaEjeZ = -5;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            
            % Simulation parameters
            vec = 2; % vecinos considerados en calculo
            K = app.KResorte; % Constante del resorte entre la masa
            K2 = app.KAmortiguamiento; % Amortiguamiento
            T = app.KTiempo; % Tiempo
            D = app.sizeEjeY; % Dimension
            
            % Initialization
            H = zeros(D); % posición 
            Hf = zeros(D); % Auxiliar
            V = zeros(D); % velocidad
            H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
            H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso
        
            % Main loop for calculations
            for m = 1:app.NumCal
                % Check if the stop button was pressed
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                end

                % Plot the current state
                surf(app.SimulationAxes, H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                camlight(app.SimulationAxes, 'right');
                axis(app.SimulationAxes, [0, D, 0, D, -1, 1]);
                drawnow;
                
                % Perform the calculations
                for j = vec+1:D-vec
                    for i = vec+1:D-vec
                        Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                        X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                        V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                        Hf(i, j) = H(i, j) + V(i, j) * T;
                    end
                end
                H = Hf;
                pause(0.001);
            end
        end

        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;

            % Simulation parameters
            K1 = app.KResorte; % Constante de resorte
            K2 = app.KAmortiguamiento; % Amortiguamiento
            rompimiento = 100; % Nivel de rompimiento
            Fext = [app.FuerzaEjeX; app.FuerzaEjeY; app.FuerzaEjeY]; % Fuerza externa
            T = app.KTiempo; % Intervalo de tiempo
            numgrap = 0; % Indice de graficas
            sonido = 1; % Indice de onda
            
            % Initialization
            numPart = app.NumCal;
            k = 0; % Indice para particulas
            for x = 1:numPart
                k = k + 1;
                p(k).r = [x; 0; 0];
                p(k).v = [0; 0; 0];
                p(k).fija = 0;
                w = 0; % Indice para vecinos
                for nx = x-1:2:x+1 % Vecinos de la particula + ella misma
                    if (1 <= nx && nx <= numPart)
                        w = w + 1;
                        p(k).n(w).ind = nx; % Indice del vecino w
                        p(k).n(w).disteq = 0.1;
                    end
                end
                p(k).numVec = w;
            end
            p(1).fija = 1;
            p(numPart).fija = 1;
            pf = p; % Auxiliar

            % Simulation loop
            numcalculos = 2000; % 24cuad p/seg * 10 seg * 5 calculos por cuadro
            Amp = 0;
            for k = 1:numcalculos % Calculos

                 if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                 end

                % Calculos
                for in = 2:numPart-1
                    if (in == floor(numPart/3) && k < 70)
                        Fext = [0; -30; 0];
                    else
                        Fext = [0; 0; 0];
                    end % Libera sistema de fuerza externa
                    if (p(in).fija == 0) % Exeptua el calculo particulas fijas
                        DX = [0; 0; 0]; % Calcula el desplazamiento
                        for w = 1:p(in).numVec % Vecinos de la particula + ella misma
                            if (p(in).n(w).ind ~= 0) % Existe o no esta roto
                                DR = p(in).r - p(p(in).n(w).ind).r; % Vector posicion relativa
                                dr = norm(DR);
                            end 
                            if 0 % dr>(rompimiento*p(in).n(w).disteq) % Rompimiento es la fraccion a superar
                                pf(in).n(w).ind = 0;
                            end
                            r = DR/dr; % Unitario en la direccion de desplazamiento
                            DX = DX + (dr - p(in).n(w).disteq) * r; % Vector desplazamiento
                        end        
                        % Calculos
                        pf(in).v = p(in).v + (-K1 * DX - K2 * p(in).v + Fext) * T;
                        pf(in).r = p(in).r + pf(in).v * T;
                    end % Exeptua particulas fijas
                end
                p = pf;
                % Graficacion
                if mod(k, 2) == 0 % Para formar onda
                    S(sonido) = p(numPart/2).r(2); % 4indPtoMed).r(2);
                    sonido = sonido + 1;
                end
                if 1 % Graficacion
                    XX = zeros(1, numPart);
                    YY = zeros(1, numPart);
                    for x = 1:numPart
                        XX(x) = p(x).r(1);
                        YY(x) = p(x).r(2);
                    end
                    plot(app.SimulationAxes, XX, YY, '.-');
                    axis(app.SimulationAxes, [-1, numPart + 0.5, -1, 1]);
                    drawnow;
                    pause(0.001);
                end
            end % Terminan k calculos
            % figure; plot(S);
        end
        
        function initializeViga(app)
            numcalculos=round(str2double(app.NumCal)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            K1=app.KResorte ;%%constante de resorte
            K2=app.KAmortiguamiento ;%%amortiguamiento
            rompimiento=app.NiveldeRompimientoSlider.Value ;%%nivel de rompimiento
            gravedad=[app.FuerzaEjeX;app.FuerzaEjeY;app.FuerzaEjeZ];%%Fuerza externa
            T=app.KTiempo ;%%intervalo de tiempo
            d1=round(str2double(app.sizeEjeX));%%dimension x de la malla
            d2=round(str2double(app.sizeEjeY));%%dimension y de la malla
            d3=round(str2double(app.sizeEjeZ));%%dimension z de la malla
            numpuntos=d1*d2*d3;
            indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
            k=0;%%indice para partículas
            numgrap=1;%%indice de graficas
            sonido=1;%%indice de onda
            %%Bloque para considerar mallas de espesor 1
                if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                end
                if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                end
                if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                end
            %%termina bloque
            for z=0:d3-1%%Bloque de Inicialización de posiciones
               for y=0:d2-1
                  for x=1:d1
                     k=k+1;
                     p(k).r=[x;y;z];%%posición inicial de las partículas
                     p(k).v=[0;0;0];%%velocidad inicial
                     w=1;%%indice para vecinos
                     for nz=z-1:z+1
                        for ny=y-1:y+1
         		            for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                              if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                 p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                 p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	            else p(k).n(w).ind=0;
                              end
                              w=w+1;
                           end
                        end
                     end
                     p(k).n(14).ind=0;%particula misma
                  end
               end
            end%%Termina inicialización
            pf=p;%auxiliar
            for k=1:numcalculos%%calculos
                k
                %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    drawnow;
                    break;
                end
  	            %% CALCULOS del automata celular
	            for z=0:(d3-1)%%%%%todos 0:d3-1
     	            for y=0:(d2-1)%%%todos 0:d2-1
        	            for x=1:d1%%%todos 1:d1
                            ind=x+d1*y+d1*d2*z;
     			            DX=[0;0;0];
     			            for w=1:27% vecinos de la particula + ellamisma
        			            if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				            DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                    dr=norm(DR);
                                    if dr>(rompimiento*p(ind).n(w).disteq)
                                        pf(ind).n(w).ind=0;
                                    end            
                                    r=DR/dr;%unitario en la dirección de desplazamiento              
                                    DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                end
                            end        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                 if (x==round(d1/2)); gravedad=[0;0;-20];
                                 else gravedad=[0;0;-2];
                                 end;
  	  				            pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                pf(ind).r=p(ind).r+pf(ind).v*T;              
                            end                  
     		            end%x
    	            end%y 
                end%z    
                p=pf;
                %% terminan calculos del automata celular  
                if (mod(k,5)==0)%para formar onda 
                  S(sonido)=p(indPtoMed).r(2);
                  sonido=sonido+1;
                end
                %%empieza graficación
                if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	            numpolig=0;clear X;clear Y;clear Z;
   	            if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		            for z=0:incz:lzp
                        for y=0:ly
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*(y+1)+d1*d2*z;
            			            ind4=x+d1*(y+1)+d1*d2*z;
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			            Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end%x
	  		             end%y
   		            end%z
   	            end%%plano z
                if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		            for y=0:incy:lyp
      		            for z=0:lz
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*y+d1*d2*(z+1);
            			            ind4=x+d1*y+d1*d2*(z+1);
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
		   	                end%x
                         end%y
		             end%z
                 end%%plano y
   	            if (d2>1 & d3>1)%%calcula poligonos de planos X
   		            for x=1:incx:lxp
      		            for z=0:lz
         		            for y=0:ly
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+d1*y+d1*d2*(z+1);
            			            ind3=x+d1*(y+1)+d1*d2*(z+1);
			                        ind4=x+d1*(y+1)+d1*d2*z;
         			               XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end               
                        end
		             end
                 end%%plano x
                  
                  %%grafica en matlab
                    fill3(app.SimulationAxes,X,Y,Z,Z);
                    axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                    xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                    M(numgrap)=getframe(app.SimulationAxes);
		            numgrap=numgrap+1;
               end%graficacion
            end;%%terminan k calculos
            movie(app.SimulationAxes,M);
        end

        function initializeVigaCompuesta(app)
            numcalculos=round(str2double(app.NumCal)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            K1=app.KResorte ;%%constante de resorte
            K2=app.KAmortiguamiento ;%%amortiguamiento
            rompimiento=app.NiveldeRompimientoSlider.Value ;%%nivel de rompimiento
            gravedad=[app.FuerzaEjeX;app.FuerzaEjeY;app.FuerzaEjeZ];%%Fuerza externa
            T=app.KTiempo ;%%intervalo de tiempo
            d1=round(str2double(app.sizeEjeX));%%dimension x de la malla
            d2=round(str2double(app.sizeEjeY));%%dimension y de la malla
            d3=round(str2double(app.sizeEjeZ));%%dimension z de la malla
            numpuntos=d1*d2*d3;
            indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
            k=0;%%indice para partículas
            numgrap=1;%%indice de graficas
            sonido=1;%%indice de onda
            %%Bloque para considerar mallas de espesor 1
                if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                end
                if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                end
                if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                end
            %%termina bloque
            for z=0:d3-1%%Bloque de Inicialización de posiciones
               for y=0:d2-1
                  for x=1:d1
                     k=k+1;
                     p(k).r=[x;y;z];%%posición inicial de las partículas
                     p(k).v=[0;0;0];%%velocidad inicial
                     w=1;%%indice para vecinos
                     for nz=z-1:z+1
                        for ny=y-1:y+1
         		            for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                              if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                 p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                 p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	            else p(k).n(w).ind=0;
                              end
                              w=w+1;
                           end
                        end
                     end
                     p(k).n(14).ind=0;%particula misma
                  end
               end
            end%%Termina inicialización
            pf=p;%auxiliar
            for k=1:numcalculos%%calculos
                k
                %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    drawnow;
                    break;
                end
  	            %% CALCULOS del automata celular
	            for z=0:(d3-1)%%%%%todos 0:d3-1
     	            for y=0:(d2-1)%%%todos 0:d2-1
        	            for x=1:d1%%%todos 1:d1
                            ind=x+d1*y+d1*d2*z;
     			            DX=[0;0;0];
     			            for w=1:27% vecinos de la particula + ellamisma
        			            if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				            DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                    dr=norm(DR);
                                    if dr>(rompimiento*p(ind).n(w).disteq)
                                        pf(ind).n(w).ind=0;
                                    end            
                                    r=DR/dr;%unitario en la dirección de desplazamiento              
                                    DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                end
                            end        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                 if (x==round(d1/2)); gravedad=[0;0;-20];
                                 else gravedad=[0;0;-2];
                                 end;
  	  				            pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                pf(ind).r=p(ind).r+pf(ind).v*T;              
                            end                  
     		            end%x
    	            end%y 
                end%z    
                p=pf;
                %% terminan calculos del automata celular  
                if (mod(k,5)==0)%para formar onda 
                  S(sonido)=p(indPtoMed).r(2);
                  sonido=sonido+1;
                end
                %%empieza graficación
                if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	            numpolig=0;clear X;clear Y;clear Z;
   	            if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		            for z=0:incz:lzp
                        for y=0:ly
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*(y+1)+d1*d2*z;
            			            ind4=x+d1*(y+1)+d1*d2*z;
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			            Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end%x
	  		             end%y
   		            end%z
   	            end%%plano z
                if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		            for y=0:incy:lyp
      		            for z=0:lz
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*y+d1*d2*(z+1);
            			            ind4=x+d1*y+d1*d2*(z+1);
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
		   	                end%x
                         end%y
		             end%z
                 end%%plano y
   	            if (d2>1 & d3>1)%%calcula poligonos de planos X
   		            for x=1:incx:lxp
      		            for z=0:lz
         		            for y=0:ly
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+d1*y+d1*d2*(z+1);
            			            ind3=x+d1*(y+1)+d1*d2*(z+1);
			                        ind4=x+d1*(y+1)+d1*d2*z;
         			               XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end               
                        end
		             end
                 end%%plano x
                  
                  %%grafica en matlab
                    fill3(app.SimulationAxes,X,Y,Z,Z);
                    axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                    xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                    M(numgrap)=getframe(app.SimulationAxes);
		            numgrap=numgrap+1;
               end%graficacion
            end;%%terminan k calculos
            movie(app.SimulationAxes,M);
        end

        function updateButtonColors(app, selectedButton)
                % Reset colors of all buttons
                app.CuerdaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.MembranaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.VigaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color
                app.VigacompuestaButton.BackgroundColor = [0.94, 0.94, 0.94]; % Default button color

                % Set the selected button color
                selectedButton.BackgroundColor = [0.52, 0.59, 0.81]; % Green color for selected button
                
                % Update the selected button property
                app.SelectedButton = selectedButton;            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ConstantedeResorteKEditFieldValueChanged(app, event)

        end

        % Callback function
        function AmortiguamientoEditFieldValueChanged(app, event)

        end

        % Callback function
        function TiempoEditFieldValueChanged(app, event)
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            app.StopFlag = false;
            selectedSimulation = app.SelectedButton.Text;
            app.KResorte = app.ConstantederesorteSlider.Value; % Actualiza KResorte antes de iniciar la simulación
            app.KAmortiguamiento = app.ConstantedeamortiguamientoSlider.Value;
            app.NivRompimiento = app.NiveldeRompimientoSlider.Value;
            app.KTiempo = app.IntervalodeTiempoSlider.Value;
            app.sizeEjeX = app.EjeXEditField.Value;
            app.sizeEjeY = app.EjeYEditField.Value;
            app.sizeEjeZ = app.EjeZEditField.Value;
            app.NumCal = app.NumerodecalculosEditField.Value;
            app.FuerzaEjeX = app.EjeXSpinner.Value;
            app.FuerzaEjeY = app.EjeYSpinner.Value;
            app.FuerzaEjeZ = app.EjeZSpinner.Value;
            
            if strcmp(selectedSimulation, 'Membrana')
                app.initializeMalla();
            elseif strcmp(selectedSimulation, 'Cuerda')
                app.initializeCuerda();
            elseif strcmp(selectedSimulation, 'Viga')
                app.initializeViga();
            elseif strcmp(selectedSimulation, 'Viga compuesta')
                app.initializeVigaCompuesta();
            end
        end

        % Button pushed function: DetenersimulacinButton
        function DetenersimulacinButtonPushed(app, event)
         % Set the stop flag to true
            app.StopFlag = true;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.StopFlag = true;
            cla(app.SimulationAxes);
            app.DimensiondelamallaLabel.Visible = "on";
            app.EjeXEditField.Visible = "on";
            app.EjeXEditFieldLabel.Visible = "on";
            app.EjeYEditField.Visible = "on";
            app.EjeYEditFieldLabel.Visible = "on";
            app.EjeZEditField.Visible = "on";
            app.EjeZEditFieldLabel.Visible = "on";
            app.EjeYEditFieldLabel.Text = "Eje Y";
            app.NumerodecalculosEditFieldLabel.Text = "Numero de calculos";
            app.NiveldeRompimientoSlider.Visible = "off";
            app.NiveldeRompimientoSliderLabel.Visible = "off";
            app.FuerzaexternaLabel.Visible = "off";
            app.EjeZSpinner.Visible = "off";
            app.EjeXSpinner.Visible = "off";
            app.EjeYSpinner.Visible = "off";
        end

        % Button pushed function: MembranaButton
        function MembranaButtonPushed(app, event)
            updateButtonColors(app, app.MembranaButton);
            app.DimensiondelamallaLabel.Visible = "on";
            app.EjeXEditField.Visible = "on";
            app.EjeXEditFieldLabel.Visible = "on";
            app.EjeYEditField.Visible = "on";
            app.EjeYEditFieldLabel.Visible = "on";
            app.EjeZEditField.Visible = "on";
            app.EjeZEditFieldLabel.Visible = "on";
            app.NumerodecalculosEditFieldLabel.Text = "Numero de calculos";
            app.EjeXEditField.Visible = "off";
            app.EjeXEditFieldLabel.Visible = "off";
            app.EjeZEditField.Visible = "off";
            app.EjeZEditFieldLabel.Visible = "off";
            app.EjeYEditFieldLabel.Text = "Dimension";
            
        end

        % Button pushed function: CuerdaButton
        function CuerdaButtonPushed(app, event)
            updateButtonColors(app, app.CuerdaButton);
            app.NumerodecalculosEditFieldLabel.Text = "Numero de particulas";
            app.DimensiondelamallaLabel.Visible = "off";
            app.EjeXEditField.Visible = "off";
            app.EjeXEditFieldLabel.Visible = "off";
            app.EjeYEditField.Visible = "off";
            app.EjeYEditFieldLabel.Visible = "off";
            app.EjeZEditField.Visible = "off";
            app.EjeZEditFieldLabel.Visible = "off";
            app.FuerzaexternaLabel.Visible = "on";
            app.EjeZSpinner.Visible = "on";
            app.EjeXSpinner.Visible = "on";
            app.EjeYSpinner.Visible = "on";
        end

        % Button pushed function: VigaButton
        function VigaButtonPushed(app, event)
            updateButtonColors(app, app.VigaButton);
            app.NumerodecalculosEditFieldLabel.Text = "Numero de calculos";
            app.DimensiondelamallaLabel.Visible = "on";
            app.EjeXEditField.Visible = "on";
            app.EjeXEditFieldLabel.Visible = "on";
            app.EjeYEditField.Visible = "on";
            app.EjeYEditFieldLabel.Visible = "on";
            app.EjeYEditFieldLabel.Text = "Eje Y";
            app.EjeZEditField.Visible = "on";
            app.EjeZEditFieldLabel.Visible = "on";
            app.NiveldeRompimientoSlider.Visible = "on";
            app.NiveldeRompimientoSliderLabel.Visible = "on";
            app.FuerzaexternaLabel.Visible = "on";
            app.EjeZSpinner.Visible = "on";
            app.EjeXSpinner.Visible = "on";
            app.EjeYSpinner.Visible = "on";
        end

        % Button pushed function: VigacompuestaButton
        function VigacompuestaButtonPushed(app, event)
            updateButtonColors(app, app.VigacompuestaButton);
            app.NumerodecalculosEditFieldLabel.Text = "Numero de calculos";
            app.DimensiondelamallaLabel.Visible = "on";
            app.EjeXEditField.Visible = "on";
            app.EjeXEditFieldLabel.Visible = "on";
            app.EjeYEditField.Visible = "on";
            app.EjeYEditFieldLabel.Visible = "on";
            app.EjeYEditFieldLabel.Text = "Eje Y";
            app.EjeZEditField.Visible = "on";
            app.EjeZEditFieldLabel.Visible = "on";
            app.NiveldeRompimientoSlider.Visible = "on";
            app.NiveldeRompimientoSliderLabel.Visible = "on";
            app.FuerzaexternaLabel.Visible = "on";
            app.EjeZSpinner.Visible = "on";
            app.EjeXSpinner.Visible = "on";
            app.EjeYSpinner.Visible = "on";
        end

        % Value changed function: ConstantederesorteSlider
        function ConstantederesorteSliderValueChanged(app, event)
            app.KResorte = app.ConstantederesorteSlider.Value;
        end

        % Value changed function: ConstantedeamortiguamientoSlider
        function ConstantedeamortiguamientoSliderValueChanged(app, event)
            app.KAmortiguamiento = app.ConstantedeamortiguamientoSlider.Value;
        end

        % Value changed function: IntervalodeTiempoSlider
        function IntervalodeTiempoSliderValueChanged(app, event)
            app.KTiempo = app.IntervalodeTiempoSlider.Value;
        end

        % Value changed function: NiveldeRompimientoSlider
        function NiveldeRompimientoSliderValueChanged(app, event)
            app.NivRompimiento = app.NiveldeRompimientoSlider.Value;
        end

        % Value changed function: EjeXEditField
        function EjeXEditFieldValueChanged(app, event)
            app.sizeEjeX = app.EjeXEditField.Value;
        end

        % Value changed function: EjeYEditField
        function EjeYEditFieldValueChanged(app, event)
            app.sizeEjeY = app.EjeYEditField.Value;
        end

        % Value changed function: EjeZEditField
        function EjeZEditFieldValueChanged(app, event)
            app.sizeEjeZ = app.EjeZEditField.Value;
        end

        % Value changed function: EjeXSpinner
        function EjeXSpinnerValueChanged(app, event)
            app.FuerzaEjeX = app.EjeXSpinner.Value;
        end

        % Value changed function: EjeYSpinner
        function EjeYSpinnerValueChanged(app, event)
            app.FuerzaEjeY = app.EjeYSpinner.Value;
        end

        % Value changed function: EjeZSpinner
        function EjeZSpinnerValueChanged(app, event)
            app.FuerzaEjeZ = app.EjeZSpinner.Value;
        end

        % Value changed function: NumerodecalculosEditField
        function NumerodecalculosEditFieldValueChanged(app, event)
            app.NumCal = app.NumerodecalculosEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Tag = 'SimulationAxes';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Tag = 'StartButton';
            app.SimularButton.Text = 'Simular';
            app.DetenersimulacinButton = uibutton(app.UIFigure, 'push');
            app.DetenersimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @DetenersimulacinButtonPushed, true);
            app.DetenersimulacinButton.Tag = 'StopButton';
            app.DetenersimulacinButton.Text = 'Detener simulación';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Tag = 'StopButton';
            app.LimpiarButton.Text = 'Limpiar';
            app.SimulacindevibracionesencuerposslidosLabel = uilabel(app.UIFigure);
            app.SimulacindevibracionesencuerposslidosLabel.Text = 'Simulación de vibraciones en cuerpos sólidos';
            app.SeleccionalaestructuraasimularLabel = uilabel(app.UIFigure);
            app.SeleccionalaestructuraasimularLabel.Text = 'Selecciona la estructura a simular';
            app.MembranaButton = uibutton(app.UIFigure, 'push');
            app.MembranaButton.ButtonPushedFcn = createCallbackFcn(app, @MembranaButtonPushed, true);
            app.MembranaButton.Tag = 'StartButton';
            app.MembranaButton.Text = 'Membrana';
            app.CuerdaButton = uibutton(app.UIFigure, 'push');
            app.CuerdaButton.ButtonPushedFcn = createCallbackFcn(app, @CuerdaButtonPushed, true);
            app.CuerdaButton.Tag = 'StartButton';
            app.CuerdaButton.Text = 'Cuerda';
            app.VigaButton = uibutton(app.UIFigure, 'push');
            app.VigaButton.ButtonPushedFcn = createCallbackFcn(app, @VigaButtonPushed, true);
            app.VigaButton.Tag = 'StartButton';
            app.VigaButton.Text = 'Viga';
            app.VigacompuestaButton = uibutton(app.UIFigure, 'push');
            app.VigacompuestaButton.ButtonPushedFcn = createCallbackFcn(app, @VigacompuestaButtonPushed, true);
            app.VigacompuestaButton.Tag = 'StartButton';
            app.VigacompuestaButton.Text = 'Viga compuesta';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ESCOMIPNLabel = uilabel(app.UIFigure);
            app.ESCOMIPNLabel.Text = 'ESCOM - IPN ';
            app.ConstantederesorteSliderLabel = uilabel(app.UIFigure);
            app.ConstantederesorteSliderLabel.Text = {'Constante de'; 'resorte'};
            app.ConstantederesorteSlider = uislider(app.UIFigure);
            app.ConstantederesorteSlider.Limits = [0 1500];
            app.ConstantederesorteSlider.ValueChangedFcn = createCallbackFcn(app, @ConstantederesorteSliderValueChanged, true);
            app.ConstantederesorteSlider.Tag = 'ConstanteresorteSlider';
            app.ConstantederesorteSlider.Value = 1500;
            app.ConstantedeamortiguamientoSliderLabel = uilabel(app.UIFigure);
            app.ConstantedeamortiguamientoSliderLabel.Text = {'Constante de '; 'amortiguamiento'};
            app.ConstantedeamortiguamientoSlider = uislider(app.UIFigure);
            app.ConstantedeamortiguamientoSlider.Limits = [0 1];
            app.ConstantedeamortiguamientoSlider.ValueChangedFcn = createCallbackFcn(app, @ConstantedeamortiguamientoSliderValueChanged, true);
            app.ConstantedeamortiguamientoSlider.Tag = 'ConstanteresorteSlider';
            app.ConstantedeamortiguamientoSlider.Value = 0.4;
            app.NiveldeRompimientoSliderLabel = uilabel(app.UIFigure);
            app.NiveldeRompimientoSliderLabel.Visible = 'off';
            app.NiveldeRompimientoSliderLabel.Text = {'Nivel de'; 'Rompimiento'};
            app.NiveldeRompimientoSlider = uislider(app.UIFigure);
            app.NiveldeRompimientoSlider.Limits = [0 2];
            app.NiveldeRompimientoSlider.ValueChangedFcn = createCallbackFcn(app, @NiveldeRompimientoSliderValueChanged, true);
            app.NiveldeRompimientoSlider.Tag = 'ConstanteresorteSlider';
            app.NiveldeRompimientoSlider.Visible = 'off';
            app.NiveldeRompimientoSlider.Value = 1.1;
            app.IntervalodeTiempoSlider = uislider(app.UIFigure);
            app.IntervalodeTiempoSlider.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider.ValueChangedFcn = createCallbackFcn(app, @IntervalodeTiempoSliderValueChanged, true);
            app.IntervalodeTiempoSlider.Tag = 'ConstanteresorteSlider';
            app.IntervalodeTiempoSlider.Value = 0.01;
            app.IntervalodeTiempoSliderLabel = uilabel(app.UIFigure);
            app.IntervalodeTiempoSliderLabel.Text = {'Intervalo de '; 'Tiempo'};
            app.EjeXSpinnerLabel = uilabel(app.UIFigure);
            app.EjeXSpinnerLabel.Visible = 'off';
            app.EjeXSpinnerLabel.Text = 'Eje X';
            app.EjeXSpinner = uispinner(app.UIFigure);
            app.EjeXSpinner.RoundFractionalValues = 'on';
            app.EjeXSpinner.ValueChangedFcn = createCallbackFcn(app, @EjeXSpinnerValueChanged, true);
            app.EjeXSpinner.Visible = 'off';
            app.EjeYSpinnerLabel = uilabel(app.UIFigure);
            app.EjeYSpinnerLabel.Visible = 'off';
            app.EjeYSpinnerLabel.Text = 'Eje Y';
            app.EjeYSpinner = uispinner(app.UIFigure);
            app.EjeYSpinner.RoundFractionalValues = 'on';
            app.EjeYSpinner.ValueChangedFcn = createCallbackFcn(app, @EjeYSpinnerValueChanged, true);
            app.EjeYSpinner.Visible = 'off';
            app.EjeZSpinnerLabel = uilabel(app.UIFigure);
            app.EjeZSpinnerLabel.Visible = 'off';
            app.EjeZSpinnerLabel.Text = 'Eje Z';
            app.EjeZSpinner = uispinner(app.UIFigure);
            app.EjeZSpinner.RoundFractionalValues = 'on';
            app.EjeZSpinner.ValueChangedFcn = createCallbackFcn(app, @EjeZSpinnerValueChanged, true);
            app.EjeZSpinner.Visible = 'off';
            app.EjeZSpinner.Value = -5;
            app.FuerzaexternaLabel = uilabel(app.UIFigure);
            app.FuerzaexternaLabel.Visible = 'off';
            app.FuerzaexternaLabel.Text = 'Fuerza externa';
            app.DimensiondelamallaLabel = uilabel(app.UIFigure);
            app.DimensiondelamallaLabel.Text = 'Dimension de la malla';
            app.EjeXEditFieldLabel = uilabel(app.UIFigure);
            app.EjeXEditFieldLabel.Text = 'Eje X';
            app.EjeXEditField = uieditfield(app.UIFigure, 'text');
            app.EjeXEditField.ValueChangedFcn = createCallbackFcn(app, @EjeXEditFieldValueChanged, true);
            app.EjeXEditField.Value = '10';
            app.EjeYEditFieldLabel = uilabel(app.UIFigure);
            app.EjeYEditFieldLabel.Text = 'Eje Y';
            app.EjeYEditField = uieditfield(app.UIFigure, 'text');
            app.EjeYEditField.ValueChangedFcn = createCallbackFcn(app, @EjeYEditFieldValueChanged, true);
            app.EjeYEditField.Value = '5';
            app.EjeZEditFieldLabel = uilabel(app.UIFigure);
            app.EjeZEditFieldLabel.Text = 'Eje Z';
            app.EjeZEditField = uieditfield(app.UIFigure, 'text');
            app.EjeZEditField.ValueChangedFcn = createCallbackFcn(app, @EjeZEditFieldValueChanged, true);
            app.EjeZEditField.Value = '1';
            app.NumerodecalculosEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodecalculosEditFieldLabel.Text = 'Numero de calculos';
            app.NumerodecalculosEditField = uieditfield(app.UIFigure, 'text');
            app.NumerodecalculosEditField.ValueChangedFcn = createCallbackFcn(app, @NumerodecalculosEditFieldValueChanged, true);
            app.NumerodecalculosEditField.Value = '400';
            app.ParametrosLabel = uilabel(app.UIFigure);
            app.ParametrosLabel.Text = 'Parametros';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end