classdef TT < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LimpiarButton                   matlab.ui.control.Button
        GuardarsimulacinButton          matlab.ui.control.Button
        DetenerButton                   matlab.ui.control.Button
        SimularButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MembranaTab                     matlab.ui.container.Tab
        TiempoMembranaTextValue         matlab.ui.control.Label
        KAmortiguamientoMembranaTextValue  matlab.ui.control.Label
        KResorteMembranaTextValue       matlab.ui.control.Label
        ValorLabel_3                    matlab.ui.control.Label
        ValorLabel_2                    matlab.ui.control.Label
        ValorLabel                      matlab.ui.control.Label
        InfoTiempoMembrana              matlab.ui.control.Button
        InfoAmortiguamientoMembrana     matlab.ui.control.Button
        InfoResorteMembrana             matlab.ui.control.Button
        InfoDimensionMembrana           matlab.ui.control.Button
        InfoCalculosMembrana            matlab.ui.control.Button
        TiempoMembrana                  matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel    matlab.ui.control.Label
        AmortiguamientoMembrana         matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel  matlab.ui.control.Label
        ResorteMembrana                 matlab.ui.control.Slider
        ConstantederesorteSliderLabel   matlab.ui.control.Label
        DimMembrana                     matlab.ui.control.NumericEditField
        DimensindelamallaEditFieldLabel  matlab.ui.control.Label
        CalculosMembrana                matlab.ui.control.NumericEditField
        NmerodeclculosEditFieldLabel    matlab.ui.control.Label
        ParmetrosLabel                  matlab.ui.control.Label
        CuerdaTab                       matlab.ui.container.Tab
        TiempoCuerdaTextValue           matlab.ui.control.Label
        KAmortiguamientoCuerdaTextValue  matlab.ui.control.Label
        KResorteCuerdaTextValue         matlab.ui.control.Label
        ValorLabel_6                    matlab.ui.control.Label
        ValorLabel_5                    matlab.ui.control.Label
        ValorLabel_4                    matlab.ui.control.Label
        InfoFuerzaExternaCuerda         matlab.ui.control.Button
        InfoTiempoCuerda                matlab.ui.control.Button
        InfoAmortiguamientoCuerda       matlab.ui.control.Button
        InfoResorteCuerda               matlab.ui.control.Button
        InfoParticulasCuerda            matlab.ui.control.Button
        InfoCalculosCuerda              matlab.ui.control.Button
        CalculosCuerda                  matlab.ui.control.NumericEditField
        NmerodeclculosEditField_2Label  matlab.ui.control.Label
        ParticulasCuerda                matlab.ui.control.NumericEditField
        NmerodepartculasEditField_2Label  matlab.ui.control.Label
        FextZCuerda                     matlab.ui.control.Spinner
        ZSpinnerLabel_2                 matlab.ui.control.Label
        FextYCuerda                     matlab.ui.control.Spinner
        YSpinnerLabel_2                 matlab.ui.control.Label
        FextXCuerda                     matlab.ui.control.Spinner
        XSpinnerLabel_2                 matlab.ui.control.Label
        TiempoCuerda                    matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_2  matlab.ui.control.Label
        AmortiguamientoCuerda           matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_2  matlab.ui.control.Label
        ResorteCuerda                   matlab.ui.control.Slider
        ConstantederesorteSliderLabel_2  matlab.ui.control.Label
        FuerzaExternaLabel_2            matlab.ui.control.Label
        ParmetrosLabel_2                matlab.ui.control.Label
        VigaTab                         matlab.ui.container.Tab
        RompimientoVigaTextValue        matlab.ui.control.Label
        TiempoVigaTextValue             matlab.ui.control.Label
        KAmortiguamientoVigaTextValue   matlab.ui.control.Label
        KResorteVigaTextValue           matlab.ui.control.Label
        ValorLabel_10                   matlab.ui.control.Label
        ValorLabel_9                    matlab.ui.control.Label
        ValorLabel_8                    matlab.ui.control.Label
        ValorLabel_7                    matlab.ui.control.Label
        InfoRompimientoViga             matlab.ui.control.Button
        InfoTiempoViga                  matlab.ui.control.Button
        InfoAmortiguamientoViga         matlab.ui.control.Button
        InfoResorteViga                 matlab.ui.control.Button
        InfoDimensionViga               matlab.ui.control.Button
        InfoCalculosViga                matlab.ui.control.Button
        RompimientoViga                 matlab.ui.control.Slider
        NiveldeRompimientoSliderLabel   matlab.ui.control.Label
        DimZViga                        matlab.ui.control.Spinner
        ZSpinnerLabel_5                 matlab.ui.control.Label
        DimYViga                        matlab.ui.control.Spinner
        YSpinnerLabel_5                 matlab.ui.control.Label
        DimXViga                        matlab.ui.control.Spinner
        XSpinnerLabel_5                 matlab.ui.control.Label
        CalculosViga                    matlab.ui.control.NumericEditField
        NmerodecalculosEditFieldLabel   matlab.ui.control.Label
        TiempoViga                      matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_3  matlab.ui.control.Label
        AmortiguamientoViga             matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_3  matlab.ui.control.Label
        ResorteViga                     matlab.ui.control.Slider
        ConstantederesorteSliderLabel_3  matlab.ui.control.Label
        DimensindelamallaEditFieldLabel_3  matlab.ui.control.Label
        ParmetrosLabel_3                matlab.ui.control.Label
        VigaCompuestaTab                matlab.ui.container.Tab
        TiempoVigaCompuestaTextValue    matlab.ui.control.Label
        KAmortiguamientoVigaCompuestaTextValue  matlab.ui.control.Label
        KResorteVigaCompuestaTextValue  matlab.ui.control.Label
        ValorLabel_13                   matlab.ui.control.Label
        ValorLabel_12                   matlab.ui.control.Label
        ValorLabel_11                   matlab.ui.control.Label
        NmerodepartculasEditField_4     matlab.ui.control.NumericEditField
        NmerodepartculasEditField_4Label  matlab.ui.control.Label
        ZSpinner_4                      matlab.ui.control.Spinner
        ZSpinnerLabel_4                 matlab.ui.control.Label
        YSpinner_4                      matlab.ui.control.Spinner
        YSpinnerLabel_4                 matlab.ui.control.Label
        XSpinner_4                      matlab.ui.control.Spinner
        XSpinnerLabel_4                 matlab.ui.control.Label
        IntervalodeTiempoSlider_4       matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_4  matlab.ui.control.Label
        ConstantedeamortiguamientoSlider_4  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_4  matlab.ui.control.Label
        ConstantederesorteSlider_4      matlab.ui.control.Slider
        ConstantederesorteSliderLabel_4  matlab.ui.control.Label
        DimensindelamallaEditFieldLabel_4  matlab.ui.control.Label
        FuerzaExternaLabel_4            matlab.ui.control.Label
        ParmetrosLabel_4                matlab.ui.control.Label
        ESCOMIPNLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SimulacindevibracionesencuerposslidosLabel  matlab.ui.control.Label
        SimulationAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Flag to control the simulation loop
        StopFlag = false;
        SelectedTab string = 'Membrana';
        WarningFlag = false;

        % Constantes:
        KResorte = 5;
        KAmortiguamiento = 0.5;
        KTiempo = 0.01;
        NumCal =  1000;
        NumPart = 10;
        sizeEjeX = 10;
        sizeEjeY = 5;
        sizeEjeZ = 1;
        NivRompimiento = 0.4;
        FuerzaEjeX = 0;
        FuerzaEjeY = 0;
        FuerzaEjeZ = -5;
        DMalla = 60;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            app.WarningFlag = false;
            
            % Parameters validation (dimension de la malla)
            if app.DMalla <= 0
                uialert(app.UIFigure, 'La dimensión de la malla no puede ser 0 o negativa, por favor digite un valor mayor a 0', 'Dimensión de la malla', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end
        
            if app.WarningFlag == false
                % Simulation parameters
                vec = 2; % vecinos considerados en calculo
                K = app.KResorte; % Constante del resorte entre la masa
                K2 = app.KAmortiguamiento; % Amortiguamiento
                T = app.KTiempo; % Tiempo
                D = app.DMalla; % Dimension de la malla
                numFrames = 100; % Número de cuadros de la animación
                M(numFrames) = struct('cdata', [], 'colormap', []);
                numgrap=1;
    
                % Initialization
                H = zeros(D); % posición 
                Hf = zeros(D); % Auxiliar
                V = zeros(D); % velocidad
                H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
                H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso

                % Main loop for calculations
                for m = 1:app.NumCal
                    % Check if the stop button was pressed
                    if app.StopFlag
                        % cla(app.SimulationAxes); used for clean
                        break;
                    end
                    
                    if(mod(m,5)== 0)
                    % Plot the current state
                        surf(app.SimulationAxes, H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                        camlight(app.SimulationAxes, 'right');
                        axis(app.SimulationAxes, [0, D, 0, D, -1, 1]);
                        drawnow;
    
                      %  M(:,:,numgrap) = getframe(app.SimulationAxes);
		              %  numgrap = numgrap+1;
                    end;
                    % Perform the calculations
                    for j = vec+1:D-vec
                        for i = vec+1:D-vec
                            Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                            X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                            V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                            Hf(i, j) = H(i, j) + V(i, j) * T;
                        end
                    end
                    H = Hf;
                    pause(0.001);
                end
                    % Enable the "Repetir simulación" button
            end
        end
        
        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;
            app.WarningFlag = false;

            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            % Parameters validation (numero de particulas)
            if app.NumPart <= 0
                uialert(app.UIFigure, 'El número de particulas  no puede ser 0 o negativo, por favor digite un valor mayor a 0', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.WarningFlag == false
                %SIMULA CUERDA
                K1 = app.KResorte;   %%constante de resorte
                K2 = app.KAmortiguamiento;%%amortiguamiento
                rompimiento=100;%%nivel de rompimiento
                Fext=[app.FuerzaEjeX; app.FuerzaEjeY ; app.FuerzaEjeZ];%%Fuerza externa
                T = app.KTiempo;%%intervalo de tiempo
                numgrap=0;%%indice de graficas
                sonido=1;%%indice de onda
                numPart = app.NumPart;
                k=0;%%indice para part?culas
    
                %%%INICIALIZACI?N
                for x=1:numPart
                    k=k+1;
                    p(k).r=[x;0;0]; p(k).v=[0;0;0];
                    p(k).fija=0;
                    w=0;%%indice para vecinos
	                for nx=x-1:2:x+1% vecinos de la particula + ellamisma              
                       if (1<=nx & nx<=numPart)
       	                 w=w+1;
       	                 p(k).n(w).ind=nx;%indice del vecino w
                          p(k).n(w).disteq=0.1;
                       end
                    end
                    p(k).numVec=w;
                end%%Termina inicializaci?n
                p(1).fija=1;
                p(numPart).fija=1;
                pf=p;%auxiliar
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                numcalculos= app.NumCal;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
                Amp=0;
                for k=1:numcalculos%%CALCULOS
    
                    if app.StopFlag
                        break;
                    end
  	                %% CALCULOS
                   for in=1+1:numPart-1
                      if (in==floor(numPart/3) && k<70)
                         %Amp=Amp+.5;
                         Fext=[0;-30;0];
                      else
		                   Fext=[0;0;0];      
	                   end;%%libera sistema de fuerza externa
                      if (p(in).fija==0)%exeptua el calculo particulas fijas
      	                DX=[0;0;0]; %calcula el desplazamiento
     		                for w=1:p(in).numVec% vecinos de la particula + ellamisma
      		                if (p(in).n(w).ind ~= 0) %existe o no esta roto
      			                DR=p(in).r-p(p(in).n(w).ind).r;%vector posici?n relativa
                               dr=norm(DR);
                            end 
				                % if dr==0  in;dr=0.001;      end
        		                if 0%dr>(rompimiento*p(in).n(w).disteq)%rompimiento es la fracci?n a superar
           		                pf(in).n(w).ind=0;
        		                end            
     			                r=DR/dr;%unitario en la direccion de desplazamiento              
        		                DX=DX+(dr-p(in).n(w).disteq)*r;%vector desplazamiento
	    	                end        
       	                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       	                pf(in).v=p(in).v+(-K1*DX-K2*p(in).v+Fext)*T;
       	                pf(in).r=p(in).r+pf(in).v*T;              
                     end%%exeptua particulas fijas                  
                  end
                  p=pf;
                   %%%%%%%%%%%%%%%%%%%%% terminan calculos %%%%%%%%%%%%%%%   
    
                    if 1 %GRAFICACION
                        XX = zeros(1, numPart);
                        YY = zeros(1, numPart);
                        for x = 1:numPart
                            XX(x) = p(x).r(1);
                            YY(x) = p(x).r(2);
                        end
                        plot(app.SimulationAxes, XX, YY, '.-');
                        axis(app.SimulationAxes, [-1, numPart + 0.5, -1, 1]);
                        drawnow;
                  end%graficacion
                end;%%terminan k calculos
            end

        end

        function initializeViga(app)
            app.WarningFlag = false;
            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.WarningFlag == false
                numcalculos =  app.NumCal; %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
                K1 = app.KResorte; %%constante de resorte
                K2 = app.KAmortiguamiento; %%amortiguamiento
                rompimiento = app.NivRompimiento; %%nivel de rompimiento
                gravedad=[0;0;-4];%%Fuerza externa
                T = app.KTiempo; %%intervalo de tiempo
                d1 = app.sizeEjeX;%%dimension x de la malla
                d2 = app.sizeEjeY;%%dimensión y
                d3 = app.sizeEjeZ;%%dimensión z
                numpuntos = d1*d2*d3;
                indPtoMed = round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
                k = 0;%%indice para partículas
                numgrap = 1;%%indice de graficas
                sonido = 1;%%indice de onda
                %%Bloque para considerar mallas de espesor 1
                    if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                    end
                    if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                    end
                    if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                    end
                %%termina bloque
                for z=0:d3-1%%Bloque de Inicialización de posiciones
                   for y=0:d2-1
                      for x=1:d1
                         k=k+1;
                         p(k).r=[x;y;z];%%posición inicial de las partículas
                         p(k).v=[0;0;0];%%velocidad inicial
                         w=1;%%indice para vecinos
                         for nz=z-1:z+1
                            for ny=y-1:y+1
         		                for nx=x-1:x+1%26 vecinos de la particula + ellamisma              
                                  if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                     p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                     p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	                else p(k).n(w).ind=0;
                                  end
                                  w=w+1;
                               end
                            end
                         end
                         p(k).n(14).ind=0;%particula misma
                      end
                   end
                end%%Termina inicialización
                pf=p;%auxiliar
                for k=1:numcalculos%%calculos
                    %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
  	                %% CALCULOS del automata celular
	                for z=0:(d3-1)%%%%%todos 0:d3-1
     	                for y=0:(d2-1)%%%todos 0:d2-1
        	                for x=1:d1%%%todos 1:d1
                                ind=x+d1*y+d1*d2*z;
     			                DX=[0;0;0];
     			                for w=1:27% vecinos de la particula + ellamisma
        			                if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				                DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                        dr=norm(DR);
                                        if dr>(rompimiento*p(ind).n(w).disteq)
                                            pf(ind).n(w).ind=0;
                                        end            
                                        r=DR/dr;%unitario en la dirección de desplazamiento              
                                        DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                    end
                                end        
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                if (1<x & x<d1)%% & (0<y & y<d2-1)%fijo x=1 y x=d1
                                    if (x==round(d1/2)); gravedad=[0;0;-20];
                                    else gravedad=[0;0;-2];
                                    end;
  	  				                pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                    pf(ind).r=p(ind).r+pf(ind).v*T;              
                                end                  
     		                end%x
    	                end%y 
                    end%z    
                    p=pf;
                    %% terminan calculos del automata celular  
                    if (mod(k,5)==0)%para formar onda 
                      S(sonido)=p(indPtoMed).r(2);
                      sonido=sonido+1;
                    end
                    %%empieza graficación
                    if (mod(k,10)==0)%solo grafica cada 5 calculos por precisión  
   	                numpolig=0;clear X;clear Y;clear Z;
   	                if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		                for z=0:incz:lzp
                            for y=0:ly
         		                for x=1:lx
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+1+d1*y+d1*d2*z;
            			                ind3=x+1+d1*(y+1)+d1*d2*z;
            			                ind4=x+d1*(y+1)+d1*d2*z;
            			                XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			                X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			                Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			                Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
                                end%x
	  		                 end%y
   		                end%z
   	                end%%plano z
                    if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		                for y=0:incy:lyp
      		                for z=0:lz
         		                for x=1:lx
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+1+d1*y+d1*d2*z;
            			                ind3=x+1+d1*y+d1*d2*(z+1);
            			                ind4=x+d1*y+d1*d2*(z+1);
            			                XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			                   Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
		   	                    end%x
                             end%y
		                 end%z
                     end%%plano y
   	                if (d2>1 & d3>1)%%calcula poligonos de planos X
   		                for x=1:incx:lxp
      		                for z=0:lz
         		                for y=0:ly
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+d1*y+d1*d2*(z+1);
            			                ind3=x+d1*(y+1)+d1*d2*(z+1);
			                            ind4=x+d1*(y+1)+d1*d2*z;
         			                   XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			                   Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
                                end               
                            end
		                 end
                     end%%plano x
                      %%grafica en matlab
                        fill3(app.SimulationAxes,X,Y,Z,Z);
                        axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                        xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                        M(numgrap)=getframe(app.SimulationAxes);
		                numgrap=numgrap+1;
                   end%graficacion
                end;%%terminan k calculos
            end
        end

        function initializeVigaCompuesta(app)
            app.WarningFlag = false;
            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            % Parameters validation (dimension de la malla)
            if app.DMalla <= 0
                uialert(app.UIFigure, 'La dimensión de la malla no puede ser 0 o negativa, por favor digite un valor mayor a 0', 'Dimensión de la malla', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.WarningFlag == false
                numcalculos=round(str2double(app.NumCal)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
                K1=app.KResorte ;%%constante de resorte
                K2=app.KAmortiguamiento ;%%amortiguamiento
                rompimiento=app.RompimientoViga.Value ;%%nivel de rompimiento
                gravedad=[app.FuerzaEjeX;app.FuerzaEjeY;app.FuerzaEjeZ];%%Fuerza externa
                T=app.KTiempo ;%%intervalo de tiempo
                d1=round(str2double(app.sizeEjeX));%%dimension x de la malla
                d2=round(str2double(app.sizeEjeY));%%dimension y de la malla
                d3=round(str2double(app.sizeEjeZ));%%dimension z de la malla
                numpuntos=d1*d2*d3;
                indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
                k=0;%%indice para partículas
                numgrap=1;%%indice de graficas
                sonido=1;%%indice de onda
                %%Bloque para considerar mallas de espesor 1
                    if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                    end
                    if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                    end
                    if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                    end
                %%termina bloque
                for z=0:d3-1%%Bloque de Inicialización de posiciones
                   for y=0:d2-1
                      for x=1:d1
                         k=k+1;
                         p(k).r=[x;y;z];%%posición inicial de las partículas
                         p(k).v=[0;0;0];%%velocidad inicial
                         w=1;%%indice para vecinos
                         for nz=z-1:z+1
                            for ny=y-1:y+1
         		                for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                                  if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                     p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                     p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	                else p(k).n(w).ind=0;
                                  end
                                  w=w+1;
                               end
                            end
                         end
                         p(k).n(14).ind=0;%particula misma
                      end
                   end
                end%%Termina inicialización
                pf=p;%auxiliar
                for k=1:numcalculos%%calculos
                    k
                    %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
                    if app.StopFlag
                        % cla(app.SimulationAxes); used for clean
                        drawnow;
                        break;
                    end
  	                %% CALCULOS del automata celular
	                for z=0:(d3-1)%%%%%todos 0:d3-1
     	                for y=0:(d2-1)%%%todos 0:d2-1
        	                for x=1:d1%%%todos 1:d1
                                ind=x+d1*y+d1*d2*z;
     			                DX=[0;0;0];
     			                for w=1:27% vecinos de la particula + ellamisma
        			                if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				                DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                        dr=norm(DR);
                                        if dr>(rompimiento*p(ind).n(w).disteq)
                                            pf(ind).n(w).ind=0;
                                        end            
                                        r=DR/dr;%unitario en la dirección de desplazamiento              
                                        DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                    end
                                end        
                                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                                if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                     if (x==round(d1/2)); gravedad=[0;0;-20];
                                     else gravedad=[0;0;-2];
                                     end;
  	  				                pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                    pf(ind).r=p(ind).r+pf(ind).v*T;              
                                end                  
     		                end%x
    	                end%y 
                    end%z    
                    p=pf;
                    %% terminan calculos del automata celular  
                    if (mod(k,5)==0)%para formar onda 
                      S(sonido)=p(indPtoMed).r(2);
                      sonido=sonido+1;
                    end
                    %%empieza graficación
                    if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	                numpolig=0;clear X;clear Y;clear Z;
   	                if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		                for z=0:incz:lzp
                            for y=0:ly
         		                for x=1:lx
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+1+d1*y+d1*d2*z;
            			                ind3=x+1+d1*(y+1)+d1*d2*z;
            			                ind4=x+d1*(y+1)+d1*d2*z;
            			                XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			                X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			                Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			                Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
                                end%x
	  		                 end%y
   		                end%z
   	                end%%plano z
                    if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		                for y=0:incy:lyp
      		                for z=0:lz
         		                for x=1:lx
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+1+d1*y+d1*d2*z;
            			                ind3=x+1+d1*y+d1*d2*(z+1);
            			                ind4=x+d1*y+d1*d2*(z+1);
            			                XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			                   Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
		   	                    end%x
                             end%y
		                 end%z
                     end%%plano y
   	                if (d2>1 & d3>1)%%calcula poligonos de planos X
   		                for x=1:incx:lxp
      		                for z=0:lz
         		                for y=0:ly
            		                ind1=x+d1*y+d1*d2*z;
            		                if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			                numpolig=numpolig+1;
            			                ind2=x+d1*y+d1*d2*(z+1);
            			                ind3=x+d1*(y+1)+d1*d2*(z+1);
			                            ind4=x+d1*(y+1)+d1*d2*z;
         			                   XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			                   Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                    end
                                end               
                            end
		                 end
                     end%%plano x
                      
                      %%grafica en matlab
                        fill3(app.SimulationAxes,X,Y,Z,Z);
                        axis(app.SimulationAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                        xlabel(app.SimulationAxes,'x');   ylabel(app.SimulationAxes,'y');   zlabel(app.SimulationAxes,'z');
                        M(numgrap)=getframe(app.SimulationAxes);
		                numgrap=numgrap+1;
                   end%graficacion
                end;%%terminan k calculos
                movie(app.SimulationAxes,M);
            end
        end

        function playSimulation(app)
            for m = 1:app.NumCal
                if ~isempty(app.Frames(m).cdata)
                    image(app.SimulationAxes, app.Frames(m).cdata);
                    axis(app.SimulationAxes, [0, app.DMalla, 0, app.DMalla, -1, 1]);
                    pause(0.1); % Adjust this pause to control playback speed
                end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ResorteMembrana
        function ResorteMembranaValueChanged(app, event)
            %sliderValue = app.ResorteMembrana.Value;
            %app.KResorteMembranaTextValue.Text = num2str(sliderValue);
        end

        % Value changed function: AmortiguamientoMembrana
        function AmortiguamientoMembranaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoMembrana.Value;
            
        end

        % Value changed function: TiempoMembrana
        function TiempoMembranaValueChanged(app, event)
            app.KTiempo = app.TiempoMembrana.Value;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            app.StopFlag = false;
           
            if strcmp(app.SelectedTab, 'Membrana')
                app.KResorte = app.ResorteMembrana.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoMembrana.Value;
                app.KTiempo = app.TiempoMembrana.Value;
                app.DMalla = app.DimMembrana.Value;
                app.NumCal = app.CalculosMembrana.Value;

                app.initializeMalla();
            elseif strcmp(app.SelectedTab, 'Cuerda')
                app.KResorte = app.ResorteCuerda.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoCuerda.Value;
                app.KTiempo = app.TiempoCuerda.Value;
                app.NumCal = app.CalculosCuerda.Value;    
                app.NumPart = app.ParticulasCuerda.Value;
                app.FuerzaEjeX = app.FextXCuerda.Value;
                app.FuerzaEjeY = app.FextYCuerda.Value;
                app.FuerzaEjeZ = app.FextZCuerda.Value;

                app.initializeCuerda();
            elseif strcmp(app.SelectedTab, 'Viga')
                app.KResorte = app.ResorteViga.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoViga.Value;
                app.KTiempo = app.TiempoViga.Value;
                app.NumCal = app.CalculosViga.Value; 
                app.NivRompimiento = app.RompimientoViga.Value;
                app.sizeEjeX = app.DimXViga.Value;
                app.sizeEjeY = app.DimYViga.Value;
                app.sizeEjeZ = app.DimZViga.Value;

                app.initializeViga();
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            % Set the stop flag to true
            app.StopFlag = true;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.StopFlag = true;
            cla(app.SimulationAxes);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.SelectedTab = app.TabGroup.SelectedTab.Title;
        end

        % Value changed function: ResorteCuerda
        function ResorteCuerdaValueChanged(app, event)
            app.KResorte = app.ResorteCuerda.Value;           
        end

        % Value changed function: AmortiguamientoCuerda
        function AmortiguamientoCuerdaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoCuerda.Value;            
        end

        % Value changed function: TiempoCuerda
        function TiempoCuerdaValueChanged(app, event)
            app.KTiempo = app.TiempoCuerda.Value;
        end

        % Value changed function: DimMembrana
        function DimMembranaValueChanged(app, event)
            app.DMalla = app.DimMembrana.Value;
            
        end

        % Value changed function: CalculosMembrana
        function CalculosMembranaValueChanged(app, event)
            app.NumCal = app.CalculosMembrana.Value;
            
        end

        % Value changed function: ParticulasCuerda
        function ParticulasCuerdaValueChanged(app, event)
            app.NumPart = app.ParticulasCuerda.Value;
            
        end

        % Value changed function: FextXCuerda
        function FextXCuerdaValueChanged(app, event)
            app.FuerzaEjeX = app.FextXCuerda.Value;
            
        end

        % Value changed function: FextYCuerda
        function FextYCuerdaValueChanged(app, event)
            app.FuerzaEjeY = app.FextYCuerda.Value;
            
        end

        % Value changed function: FextZCuerda
        function FextZCuerdaValueChanged(app, event)
            app.FuerzaEjeZ = app.FextZCuerda.Value;
            
        end

        % Value changed function: CalculosCuerda
        function CalculosCuerdaValueChanged(app, event)
            app.NumCal = app.CalculosCuerda.Value;
        end

        % Callback function
        function XSpinner_3ValueChanged(app, event)
            app.sizeEjeX = app.XSpinner_3.Value;
            
        end

        % Callback function
        function YSpinner_3ValueChanged(app, event)
            app.sizeEjeY = app.YSpinner_3.Value;
            
        end

        % Callback function
        function ZSpinner_3ValueChanged(app, event)
            app.sizeEjeZ = app.ZSpinner_3.Value;
            
        end

        % Value changed function: ResorteViga
        function ResorteVigaValueChanged(app, event)
            app.KResorte = app.ResorteViga.Value;
            
        end

        % Value changed function: AmortiguamientoViga
        function AmortiguamientoVigaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoViga.Value;
            
        end

        % Value changed function: TiempoViga
        function TiempoVigaValueChanged(app, event)
            app.KTiempo = app.TiempoViga.Value;
            
        end

        % Value changed function: RompimientoViga
        function RompimientoVigaValueChanged(app, event)
            app.NivRompimiento = app.RompimientoViga.Value;
            
        end

        % Button pushed function: GuardarsimulacinButton
        function GuardarsimulacinButtonPushed(app, event)
            uialert(app.UIFigure, 'Aquí puedes ingresar tu información. Asegúrate de que sea correcta.', 'Información');
        end

        % Button pushed function: InfoResorteMembrana
        function InfoResorteMembranaButtonPushed(app, event)
            uialert(app.UIFigure, '* Valor Mínimo: Si KResorte es demasiado bajo, la malla será demasiado "suave" y no mostrará un comportamiento oscilatorio significativo. Un valor mínimo razonable podría ser K= 0.1 Valor Máximo: Si KResorte es demasiado alto, el sistema podría volverse inestable y oscilar violentamente, lo que podría causar deformaciones. Un valor máximo razonable podría ser K≈10.', 'Constante de Resorte', 'Icon', 'info');

        end

        % Value changing function: ResorteMembrana
        function ResorteMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteMembranaTextValue.Text = num2str(round(changingValue,2));
        end

        % Value changing function: AmortiguamientoMembrana
        function AmortiguamientoMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoMembranaTextValue.Text = num2str(round(changingValue,2));
        end

        % Value changing function: TiempoMembrana
        function TiempoMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoMembranaTextValue.Text = num2str(round(changingValue, 5));
        end

        % Value changing function: ResorteCuerda
        function ResorteCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteCuerdaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: AmortiguamientoCuerda
        function AmortiguamientoCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoCuerdaTextValue.Text = num2str(round(changingValue, 3));
        end

        % Value changing function: TiempoCuerda
        function TiempoCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoCuerdaTextValue.Text = num2str(round(changingValue, 4));
        end

        % Value changing function: ResorteViga
        function ResorteVigaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteVigaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: AmortiguamientoViga
        function AmortiguamientoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoVigaTextValue.Text = num2str(round(changingValue, 3));
        end

        % Value changing function: TiempoViga
        function TiempoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoVigaTextValue.Text = num2str(round(changingValue, 4));
        end

        % Value changing function: RompimientoViga
        function RompimientoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.RompimientoVigaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: ConstantederesorteSlider_4
        function ConstantederesorteSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteVigaCompuestaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: ConstantedeamortiguamientoSlider_4
        function ConstantedeamortiguamientoSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoVigaCompuestaTextValue.Text = num2str(round(changingValue, 3));
        end

        % Value changing function: IntervalodeTiempoSlider_4
        function IntervalodeTiempoSlider_4ValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoVigaCompuestaTextValue.Text = num2str(round(changingValue, 4));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Tag = 'SimulationAxes';
            app.SimulacindevibracionesencuerposslidosLabel = uilabel(app.UIFigure);
            app.SimulacindevibracionesencuerposslidosLabel.Text = 'Simulación de vibraciones en cuerpos sólidos';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ESCOMIPNLabel = uilabel(app.UIFigure);
            app.ESCOMIPNLabel.Text = 'ESCOM - IPN ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MembranaTab = uitab(app.TabGroup);
            app.MembranaTab.Title = 'Membrana';
            app.ParmetrosLabel = uilabel(app.MembranaTab);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.NmerodeclculosEditFieldLabel = uilabel(app.MembranaTab);
            app.NmerodeclculosEditFieldLabel.Text = 'Número de cálculos';
            app.CalculosMembrana = uieditfield(app.MembranaTab, 'numeric');
            app.CalculosMembrana.ValueChangedFcn = createCallbackFcn(app, @CalculosMembranaValueChanged, true);
            app.DimensindelamallaEditFieldLabel = uilabel(app.MembranaTab);
            app.DimensindelamallaEditFieldLabel.Text = 'Dimensión de la malla';
            app.DimMembrana = uieditfield(app.MembranaTab, 'numeric');
            app.DimMembrana.ValueChangedFcn = createCallbackFcn(app, @DimMembranaValueChanged, true);
            app.ConstantederesorteSliderLabel = uilabel(app.MembranaTab);
            app.ConstantederesorteSliderLabel.Text = 'Constante de resorte';
            app.ResorteMembrana = uislider(app.MembranaTab);
            app.ResorteMembrana.Limits = [0.1 10];
            app.ResorteMembrana.ValueChangedFcn = createCallbackFcn(app, @ResorteMembranaValueChanged, true);
            app.ResorteMembrana.ValueChangingFcn = createCallbackFcn(app, @ResorteMembranaValueChanging, true);
            app.ResorteMembrana.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel = uilabel(app.MembranaTab);
            app.ConstantedeamortiguamientoSliderLabel.Text = 'Constante de amortiguamiento';
            app.AmortiguamientoMembrana = uislider(app.MembranaTab);
            app.AmortiguamientoMembrana.Limits = [0.01 1];
            app.AmortiguamientoMembrana.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoMembranaValueChanged, true);
            app.AmortiguamientoMembrana.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoMembranaValueChanging, true);
            app.AmortiguamientoMembrana.Value = 0.01;
            app.IntervalodeTiempoSliderLabel = uilabel(app.MembranaTab);
            app.IntervalodeTiempoSliderLabel.Text = 'Intervalo de Tiempo';
            app.TiempoMembrana = uislider(app.MembranaTab);
            app.TiempoMembrana.Limits = [0.01 0.0199];
            app.TiempoMembrana.ValueChangedFcn = createCallbackFcn(app, @TiempoMembranaValueChanged, true);
            app.TiempoMembrana.ValueChangingFcn = createCallbackFcn(app, @TiempoMembranaValueChanging, true);
            app.TiempoMembrana.Value = 0.01;
            app.InfoCalculosMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoCalculosMembrana.Text = '?';
            app.InfoDimensionMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoDimensionMembrana.Text = '?';
            app.InfoResorteMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoResorteMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoResorteMembranaButtonPushed, true);
            app.InfoResorteMembrana.Text = '?';
            app.InfoAmortiguamientoMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoAmortiguamientoMembrana.Text = '?';
            app.InfoTiempoMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoTiempoMembrana.Text = '?';
            app.ValorLabel = uilabel(app.MembranaTab);
            app.ValorLabel.Text = 'Valor:';
            app.ValorLabel_2 = uilabel(app.MembranaTab);
            app.ValorLabel_2.Text = 'Valor:';
            app.ValorLabel_3 = uilabel(app.MembranaTab);
            app.ValorLabel_3.Text = 'Valor:';
            app.KResorteMembranaTextValue = uilabel(app.MembranaTab);
            app.KResorteMembranaTextValue.Text = '0';
            app.KAmortiguamientoMembranaTextValue = uilabel(app.MembranaTab);
            app.KAmortiguamientoMembranaTextValue.Text = '0';
            app.TiempoMembranaTextValue = uilabel(app.MembranaTab);
            app.TiempoMembranaTextValue.Text = '0';
            app.CuerdaTab = uitab(app.TabGroup);
            app.CuerdaTab.Title = 'Cuerda';
            app.ParmetrosLabel_2 = uilabel(app.CuerdaTab);
            app.ParmetrosLabel_2.Text = 'Parámetros';
            app.FuerzaExternaLabel_2 = uilabel(app.CuerdaTab);
            app.FuerzaExternaLabel_2.Text = 'Fuerza Externa';
            app.ConstantederesorteSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantederesorteSliderLabel_2.Text = 'Constante de resorte';
            app.ResorteCuerda = uislider(app.CuerdaTab);
            app.ResorteCuerda.Limits = [1 1500];
            app.ResorteCuerda.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ResorteCuerda.ValueChangedFcn = createCallbackFcn(app, @ResorteCuerdaValueChanged, true);
            app.ResorteCuerda.ValueChangingFcn = createCallbackFcn(app, @ResorteCuerdaValueChanging, true);
            app.ResorteCuerda.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantedeamortiguamientoSliderLabel_2.Text = 'Constante de amortiguamiento';
            app.AmortiguamientoCuerda = uislider(app.CuerdaTab);
            app.AmortiguamientoCuerda.Limits = [0.001 1];
            app.AmortiguamientoCuerda.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoCuerdaValueChanged, true);
            app.AmortiguamientoCuerda.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoCuerdaValueChanging, true);
            app.AmortiguamientoCuerda.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.IntervalodeTiempoSliderLabel_2.Text = 'Intervalo de Tiempo';
            app.TiempoCuerda = uislider(app.CuerdaTab);
            app.TiempoCuerda.Limits = [0.0001 0.01];
            app.TiempoCuerda.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.TiempoCuerda.ValueChangedFcn = createCallbackFcn(app, @TiempoCuerdaValueChanged, true);
            app.TiempoCuerda.ValueChangingFcn = createCallbackFcn(app, @TiempoCuerdaValueChanging, true);
            app.TiempoCuerda.Value = 0.0001;
            app.XSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.XSpinnerLabel_2.Text = 'X';
            app.FextXCuerda = uispinner(app.CuerdaTab);
            app.FextXCuerda.ValueChangedFcn = createCallbackFcn(app, @FextXCuerdaValueChanged, true);
            app.YSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.YSpinnerLabel_2.Text = 'Y';
            app.FextYCuerda = uispinner(app.CuerdaTab);
            app.FextYCuerda.ValueChangedFcn = createCallbackFcn(app, @FextYCuerdaValueChanged, true);
            app.ZSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.ZSpinnerLabel_2.Text = 'Z';
            app.FextZCuerda = uispinner(app.CuerdaTab);
            app.FextZCuerda.ValueChangedFcn = createCallbackFcn(app, @FextZCuerdaValueChanged, true);
            app.NmerodepartculasEditField_2Label = uilabel(app.CuerdaTab);
            app.NmerodepartculasEditField_2Label.Text = 'Número de partículas';
            app.ParticulasCuerda = uieditfield(app.CuerdaTab, 'numeric');
            app.ParticulasCuerda.ValueChangedFcn = createCallbackFcn(app, @ParticulasCuerdaValueChanged, true);
            app.NmerodeclculosEditField_2Label = uilabel(app.CuerdaTab);
            app.NmerodeclculosEditField_2Label.Text = 'Número de cálculos';
            app.CalculosCuerda = uieditfield(app.CuerdaTab, 'numeric');
            app.CalculosCuerda.ValueChangedFcn = createCallbackFcn(app, @CalculosCuerdaValueChanged, true);
            app.InfoCalculosCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoCalculosCuerda.Text = '?';
            app.InfoParticulasCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoParticulasCuerda.Text = '?';
            app.InfoResorteCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoResorteCuerda.Text = '?';
            app.InfoAmortiguamientoCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoAmortiguamientoCuerda.Text = '?';
            app.InfoTiempoCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoTiempoCuerda.Text = '?';
            app.InfoFuerzaExternaCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoFuerzaExternaCuerda.Text = '?';
            app.ValorLabel_4 = uilabel(app.CuerdaTab);
            app.ValorLabel_4.Text = 'Valor:';
            app.ValorLabel_5 = uilabel(app.CuerdaTab);
            app.ValorLabel_5.Text = 'Valor:';
            app.ValorLabel_6 = uilabel(app.CuerdaTab);
            app.ValorLabel_6.Text = 'Valor:';
            app.KResorteCuerdaTextValue = uilabel(app.CuerdaTab);
            app.KResorteCuerdaTextValue.Text = '0';
            app.KAmortiguamientoCuerdaTextValue = uilabel(app.CuerdaTab);
            app.KAmortiguamientoCuerdaTextValue.Text = '0';
            app.TiempoCuerdaTextValue = uilabel(app.CuerdaTab);
            app.TiempoCuerdaTextValue.Text = '0';
            app.VigaTab = uitab(app.TabGroup);
            app.VigaTab.Title = 'Viga';
            app.ParmetrosLabel_3 = uilabel(app.VigaTab);
            app.ParmetrosLabel_3.Text = 'Parámetros';
            app.DimensindelamallaEditFieldLabel_3 = uilabel(app.VigaTab);
            app.DimensindelamallaEditFieldLabel_3.Text = 'Dimensión de la malla';
            app.ConstantederesorteSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantederesorteSliderLabel_3.Text = 'Constante de resorte';
            app.ResorteViga = uislider(app.VigaTab);
            app.ResorteViga.Limits = [1 1500];
            app.ResorteViga.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ResorteViga.ValueChangedFcn = createCallbackFcn(app, @ResorteVigaValueChanged, true);
            app.ResorteViga.ValueChangingFcn = createCallbackFcn(app, @ResorteVigaValueChanging, true);
            app.ResorteViga.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantedeamortiguamientoSliderLabel_3.Text = 'Constante de amortiguamiento';
            app.AmortiguamientoViga = uislider(app.VigaTab);
            app.AmortiguamientoViga.Limits = [0.001 1];
            app.AmortiguamientoViga.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoVigaValueChanged, true);
            app.AmortiguamientoViga.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoVigaValueChanging, true);
            app.AmortiguamientoViga.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_3 = uilabel(app.VigaTab);
            app.IntervalodeTiempoSliderLabel_3.Text = 'Intervalo de Tiempo';
            app.TiempoViga = uislider(app.VigaTab);
            app.TiempoViga.Limits = [0.0001 0.01];
            app.TiempoViga.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.TiempoViga.ValueChangedFcn = createCallbackFcn(app, @TiempoVigaValueChanged, true);
            app.TiempoViga.ValueChangingFcn = createCallbackFcn(app, @TiempoVigaValueChanging, true);
            app.TiempoViga.Value = 0.0001;
            app.NmerodecalculosEditFieldLabel = uilabel(app.VigaTab);
            app.NmerodecalculosEditFieldLabel.Text = 'Número de calculos';
            app.CalculosViga = uieditfield(app.VigaTab, 'numeric');
            app.XSpinnerLabel_5 = uilabel(app.VigaTab);
            app.XSpinnerLabel_5.Text = 'X';
            app.DimXViga = uispinner(app.VigaTab);
            app.YSpinnerLabel_5 = uilabel(app.VigaTab);
            app.YSpinnerLabel_5.Text = 'Y';
            app.DimYViga = uispinner(app.VigaTab);
            app.ZSpinnerLabel_5 = uilabel(app.VigaTab);
            app.ZSpinnerLabel_5.Text = 'Z';
            app.DimZViga = uispinner(app.VigaTab);
            app.NiveldeRompimientoSliderLabel = uilabel(app.VigaTab);
            app.NiveldeRompimientoSliderLabel.Text = 'Nivel de Rompimiento';
            app.RompimientoViga = uislider(app.VigaTab);
            app.RompimientoViga.ValueChangedFcn = createCallbackFcn(app, @RompimientoVigaValueChanged, true);
            app.RompimientoViga.ValueChangingFcn = createCallbackFcn(app, @RompimientoVigaValueChanging, true);
            app.InfoCalculosViga = uibutton(app.VigaTab, 'push');
            app.InfoCalculosViga.Text = '?';
            app.InfoDimensionViga = uibutton(app.VigaTab, 'push');
            app.InfoDimensionViga.Text = '?';
            app.InfoResorteViga = uibutton(app.VigaTab, 'push');
            app.InfoResorteViga.Text = '?';
            app.InfoAmortiguamientoViga = uibutton(app.VigaTab, 'push');
            app.InfoAmortiguamientoViga.Text = '?';
            app.InfoTiempoViga = uibutton(app.VigaTab, 'push');
            app.InfoTiempoViga.Text = '?';
            app.InfoRompimientoViga = uibutton(app.VigaTab, 'push');
            app.InfoRompimientoViga.Text = '?';
            app.ValorLabel_7 = uilabel(app.VigaTab);
            app.ValorLabel_7.Text = 'Valor:';
            app.ValorLabel_8 = uilabel(app.VigaTab);
            app.ValorLabel_8.Text = 'Valor:';
            app.ValorLabel_9 = uilabel(app.VigaTab);
            app.ValorLabel_9.Text = 'Valor:';
            app.ValorLabel_10 = uilabel(app.VigaTab);
            app.ValorLabel_10.Text = 'Valor:   %';
            app.KResorteVigaTextValue = uilabel(app.VigaTab);
            app.KResorteVigaTextValue.Text = '0';
            app.KAmortiguamientoVigaTextValue = uilabel(app.VigaTab);
            app.KAmortiguamientoVigaTextValue.Text = '0';
            app.TiempoVigaTextValue = uilabel(app.VigaTab);
            app.TiempoVigaTextValue.Text = '0';
            app.RompimientoVigaTextValue = uilabel(app.VigaTab);
            app.RompimientoVigaTextValue.Text = '0';
            app.VigaCompuestaTab = uitab(app.TabGroup);
            app.VigaCompuestaTab.Title = 'Viga Compuesta';
            app.ParmetrosLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ParmetrosLabel_4.Text = 'Parámetros';
            app.FuerzaExternaLabel_4 = uilabel(app.VigaCompuestaTab);
            app.FuerzaExternaLabel_4.Text = 'Fuerza Externa';
            app.DimensindelamallaEditFieldLabel_4 = uilabel(app.VigaCompuestaTab);
            app.DimensindelamallaEditFieldLabel_4.Text = 'Dimensión de la malla';
            app.ConstantederesorteSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantederesorteSliderLabel_4.Text = 'Constante de resorte';
            app.ConstantederesorteSlider_4 = uislider(app.VigaCompuestaTab);
            app.ConstantederesorteSlider_4.Limits = [1 1500];
            app.ConstantederesorteSlider_4.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ConstantederesorteSlider_4.ValueChangingFcn = createCallbackFcn(app, @ConstantederesorteSlider_4ValueChanging, true);
            app.ConstantederesorteSlider_4.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantedeamortiguamientoSliderLabel_4.Text = 'Constante de amortiguamiento';
            app.ConstantedeamortiguamientoSlider_4 = uislider(app.VigaCompuestaTab);
            app.ConstantedeamortiguamientoSlider_4.Limits = [0.001 1];
            app.ConstantedeamortiguamientoSlider_4.ValueChangingFcn = createCallbackFcn(app, @ConstantedeamortiguamientoSlider_4ValueChanging, true);
            app.ConstantedeamortiguamientoSlider_4.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.IntervalodeTiempoSliderLabel_4.Text = 'Intervalo de Tiempo';
            app.IntervalodeTiempoSlider_4 = uislider(app.VigaCompuestaTab);
            app.IntervalodeTiempoSlider_4.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider_4.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.IntervalodeTiempoSlider_4.ValueChangingFcn = createCallbackFcn(app, @IntervalodeTiempoSlider_4ValueChanging, true);
            app.IntervalodeTiempoSlider_4.Value = 0.0001;
            app.XSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.XSpinnerLabel_4.Text = 'X';
            app.XSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.YSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.YSpinnerLabel_4.Text = 'Y';
            app.YSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.ZSpinnerLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ZSpinnerLabel_4.Text = 'Z';
            app.ZSpinner_4 = uispinner(app.VigaCompuestaTab);
            app.NmerodepartculasEditField_4Label = uilabel(app.VigaCompuestaTab);
            app.NmerodepartculasEditField_4Label.Text = 'Número de partículas';
            app.NmerodepartculasEditField_4 = uieditfield(app.VigaCompuestaTab, 'numeric');
            app.ValorLabel_11 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_11.Text = 'Valor:';
            app.ValorLabel_12 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_12.Text = 'Valor:';
            app.ValorLabel_13 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_13.Text = 'Valor:';
            app.KResorteVigaCompuestaTextValue = uilabel(app.VigaCompuestaTab);
            app.KResorteVigaCompuestaTextValue.Text = '0';
            app.KAmortiguamientoVigaCompuestaTextValue = uilabel(app.VigaCompuestaTab);
            app.KAmortiguamientoVigaCompuestaTextValue.Text = '0';
            app.TiempoVigaCompuestaTextValue = uilabel(app.VigaCompuestaTab);
            app.TiempoVigaCompuestaTextValue.Text = '0';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GuardarsimulacinButton = uibutton(app.UIFigure, 'push');
            app.GuardarsimulacinButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarsimulacinButtonPushed, true);
            app.GuardarsimulacinButton.Text = 'Guardar simulación';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TT

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end