classdef viga_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PararSimulacionButton           matlab.ui.control.Button
        EjeZEditField                   matlab.ui.control.EditField
        EjeZEditFieldLabel              matlab.ui.control.Label
        EjeYEditField                   matlab.ui.control.EditField
        EjeYEditFieldLabel              matlab.ui.control.Label
        EjeXEditField                   matlab.ui.control.EditField
        EjeXEditFieldLabel              matlab.ui.control.Label
        EjeZSpinner_2                   matlab.ui.control.Spinner
        EjeZSpinner_2Label              matlab.ui.control.Label
        EjeYSpinner_2                   matlab.ui.control.Spinner
        EjeYSpinner_2Label              matlab.ui.control.Label
        EjeXSpinner_2                   matlab.ui.control.Spinner
        EjeXSpinner_2Label              matlab.ui.control.Label
        FuerzaexternaLabel              matlab.ui.control.Label
        NumerodecalculosEditField       matlab.ui.control.EditField
        NumerodecalculosEditFieldLabel  matlab.ui.control.Label
        DimensiondelamallaLabel         matlab.ui.control.Label
        IntervalodeTiempoSlider         matlab.ui.control.Slider
        IntervalodeTiempoLabel          matlab.ui.control.Label
        NiveldeRompimientoSlider        matlab.ui.control.Slider
        NiveldeRompimientoSliderLabel   matlab.ui.control.Label
        ConstantedeamortiguamientoSlider  matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel  matlab.ui.control.Label
        ConstantederesorteSlider        matlab.ui.control.Slider
        ConstantedeResorteLabel         matlab.ui.control.Label
        SeleccionaelsolidoasimularDropDown  matlab.ui.control.DropDown
        SelectSimulationLabel           matlab.ui.control.Label
        IniciarsimulacionButton         matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        % Flag to control the simulation loop
        StopFlag = false;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            
            % Simulation parameters
            vec = 2; % vecinos considerados en calculo
            K = 5; % Constante del resorte entre la masa
            K2 = 0.5; % Amortiguamiento
            T = 0.01; % Tiempo
            D = 60; % Dimension
            
            % Initialization
            H = zeros(D); % posición 
            Hf = zeros(D); % Auxiliar
            V = zeros(D); % velocidad
            H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
            H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso
        
            % Main loop for calculations
            for m = 1:1000
                % Check if the stop button was pressed
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                end

                % Plot the current state
                surf(H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                camlight('right');
                axis([0, D, 0, D, -1, 1]);
                drawnow;
                
                % Perform the calculations
                for j = vec+1:D-vec
                    for i = vec+1:D-vec
                        Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                        X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                        V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                        Hf(i, j) = H(i, j) + V(i, j) * T;
                    end
                end
                H = Hf;
                pause(0.001);
            end
        end

        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;

            %SIMULA CUERDA
            K1=500;%%constante de resorte
            K2=.2;%%amortiguamiento
            rompimiento=100;%%nivel de rompimiento
            Fext=[0;-9.8;0];%%Fuerza externa
            T=0.01;%%intervalo de tiempo
            numgrap=0;%%indice de graficas
            sonido=1;%%indice de onda
            %%%INICIALIZACI?N
            numPart=10;
            k=0;%%indice para part?culas
            for x=1:numPart
                k=k+1;
                p(k).r=[x;0;0]; p(k).v=[0;0;0];
                p(k).fija=0;
                w=0;%%indice para vecinos
                for nx=x-1:2:x+1% vecinos de la particula + ellamisma              
                   if (1<=nx & nx<=numPart)
       	             w=w+1;
       	             p(k).n(w).ind=nx;%indice del vecino w
                      p(k).n(w).disteq=0.1;
                   end
                end
                p(k).numVec=w;
            end%%Termina inicializaci?n
            p(1).fija=1;
            p(numPart).fija=1;
            pf=p;%auxiliar
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            numcalculos=2000;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            Amp=0;
            for k=1:numcalculos%%CALCULOS
  	            %% CALCULOS
                for in=1+1:numPart-1
                    if (in==floor(numPart/3) && k<70)
                        %Amp=Amp+.5;
                        Fext=[0;-30;0];
                    else
                        Fext=[0;0;0];      
	                end;%%libera sistema de fuerza externa
                    if (p(in).fija==0)%exeptua el calculo particulas fijas
      	                DX=[0;0;0]; %calcula el desplazamiento
                        for w=1:p(in).numVec% vecinos de la particula + ellamisma
  		                    if (p(in).n(w).ind ~= 0) %existe o no esta roto
  			                    DR=p(in).r-p(p(in).n(w).ind).r;%vector posici?n relativa
                                dr=norm(DR);
                            end 
			                % if dr==0  in;dr=0.001;      end
        		            if 0%dr>(rompimiento*p(in).n(w).disteq)%rompimiento es la fracci?n a superar
           		                pf(in).n(w).ind=0;
        		            end            
     			            r=DR/dr;%unitario en la direccion de desplazamiento              
        		            DX=DX+(dr-p(in).n(w).disteq)*r;%vector desplazamiento
	    	            end        
       	                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       	                pf(in).v=p(in).v+(-K1*DX-K2*p(in).v+Fext)*T;
       	                pf(in).r=p(in).r+pf(in).v*T;              
                    end%%exeptua particulas fijas                  
                end
                p=pf;
               %%%%%%%%%%%%%%%%%%%%% terminan calculos %%%%%%%%%%%%%%%   
                if (mod(k,2)==0)%para formar onda 
                    S(sonido)=p(numPart/2).r(2);%4indPtoMed).r(2);
                    sonido=sonido+1;
                end
                if 1 %GRAFICACION
                    for x=1:numPart
                        XX(x)=p(x).r(1);
                        YY(x)=p(x).r(2);
                    end
                    plot(app.SimulationAxes,XX,YY,'.-');
                    axis([-1,numPart+.5,-1,1]);
                    pause(0.001);
                end%graficacion
            end;%%terminan k calculos
            % figure; plot(S);
        end

        function initializeViga(app)
            numcalculos=round(str2double(app.NumerodecalculosEditField.Value)) %24*3*5;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            K1=app.ConstantederesorteSlider.Value ;%%constante de resorte
            K2=app.ConstantedeamortiguamientoSlider.Value ;%%amortiguamiento
            rompimiento=app.NiveldeRompimientoSlider.Value ;%%nivel de rompimiento
            gravedad=[app.EjeXSpinner_2.Value;app.EjeYSpinner_2.Value;app.EjeZSpinner_2.Value];%%Fuerza externa
            T=app.IntervalodeTiempoSlider.Value ;%%intervalo de tiempo
            d1=round(str2double(app.EjeXEditField.Value));%%dimension x de la malla
            d2=round(str2double(app.EjeYEditField.Value));%%dimension y de la malla
            d3=round(str2double(app.EjeZEditField.Value));%%dimension z de la malla
            numpuntos=d1*d2*d3;
            indPtoMed=round((d1+d1*(d2-1)+d1*d2*(d3-1))/2);
            k=0;%%indice para partículas
            numgrap=1;%%indice de graficas
            sonido=1;%%indice de onda
            %%Bloque para considerar mallas de espesor 1
                if d1==1 lx=1;lxp=1;incx=1; else lx=d1-1;lxp=d1;incx=d1-1;
                end
                if d2==1 ly=0;lyp=0;incy=1; else ly=d2-2;lyp=d2-1;incy=d2-1;
                end
                if d3==1 lz=0;lzp=0;incz=1; else lz=d3-2;lzp=d3-1;incz=d3-1;
                end
            %%termina bloque
            for z=0:d3-1%%Bloque de Inicialización de posiciones
               for y=0:d2-1
                  for x=1:d1
                     k=k+1;
                     p(k).r=[x;y;z];%%posición inicial de las partículas
                     p(k).v=[0;0;0];%%velocidad inicial
                     w=1;%%indice para vecinos
                     for nz=z-1:z+1
                        for ny=y-1:y+1
         		            for nx=x-1:x+1%26 vecinos de la particula + ella misma              
                              if (1<=nx & nx<=d1)&(0<=ny & ny<=d2-1)&(0<=nz & nz<=d3-1)
                                 p(k).n(w).ind=nx+d1*ny+d1*d2*nz;%indice del vecino w
                                 p(k).n(w).disteq=norm(p(k).r-[nx;ny;nz]);%distancia de equilibrio con el vecino w
                  	            else p(k).n(w).ind=0;
                              end
                              w=w+1;
                           end
                        end
                     end
                     p(k).n(14).ind=0;%particula misma
                  end
               end
            end%%Termina inicialización
            pf=p;%auxiliar
            for k=1:numcalculos%%calculos
                k
                %if k>10; gravedad=[0;0;0]; end;%%libera sistema de fuerza externa
  	            %% CALCULOS del automata celular
	            for z=0:(d3-1)%%%%%todos 0:d3-1
     	            for y=0:(d2-1)%%%todos 0:d2-1
        	            for x=1:d1%%%todos 1:d1
                            ind=x+d1*y+d1*d2*z;
     			            DX=[0;0;0];
     			            for w=1:27% vecinos de la particula + ellamisma
        			            if (p(ind).n(w).ind ~= 0) %existe o no esta roto
        				            DR=(p(ind).r-p(p(ind).n(w).ind).r);%vector posición relativa
                                    dr=norm(DR);
                                    if dr>(rompimiento*p(ind).n(w).disteq)
                                        pf(ind).n(w).ind=0;
                                    end            
                                    r=DR/dr;%unitario en la dirección de desplazamiento              
                                    DX=DX+(dr-p(ind).n(w).disteq)*r;%vector desplazamiento
                                end
                            end        
                            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                            if (1<x) %(1<x & x<d1) & (0<y & y<d2-1)%fijo x=1 y x=d1
                                 if (x==round(d1/2)); gravedad=[0;0;-20];
                                 else gravedad=[0;0;-2];
                                 end;
  	  				            pf(ind).v=p(ind).v+(-K1*DX-K2*p(ind).v+gravedad)*T;
                                pf(ind).r=p(ind).r+pf(ind).v*T;              
                            end                  
     		            end%x
    	            end%y 
                end%z    
                p=pf;
                %% terminan calculos del automata celular  
                if (mod(k,5)==0)%para formar onda 
                  S(sonido)=p(indPtoMed).r(2);
                  sonido=sonido+1;
                end
                %%empieza graficación
                if (mod(k,5)==0)%solo grafica cada 5 calculos por precisión  
   	            numpolig=0;clear X;clear Y;clear Z;
   	            if (d1>1 & d2>1)%%calcula poligonos de planos Z
   		            for z=0:incz:lzp
                        for y=0:ly
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(18).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*(y+1)+d1*d2*z;
            			            ind4=x+d1*(y+1)+d1*d2*z;
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
            			            X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
            			            Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
            			            Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end%x
	  		             end%y
   		            end%z
   	            end%%plano z
                if (d1>1 & d3>1)%%calcula poligonos de planos Y  
		            for y=0:incy:lyp
      		            for z=0:lz
         		            for x=1:lx
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(15).ind ~= 0 & p(ind1).n(23).ind  ~= 0 & p(ind1).n(24).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+1+d1*y+d1*d2*z;
            			            ind3=x+1+d1*y+d1*d2*(z+1);
            			            ind4=x+d1*y+d1*d2*(z+1);
            			            XI(:,numpolig)=[ind1;ind2;ind3;ind4];            
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
		   	                end%x
                         end%y
		             end%z
                 end%%plano y
   	            if (d2>1 & d3>1)%%calcula poligonos de planos X
   		            for x=1:incx:lxp
      		            for z=0:lz
         		            for y=0:ly
            		            ind1=x+d1*y+d1*d2*z;
            		            if (p(ind1).n(23).ind ~= 0 & p(ind1).n(17).ind  ~= 0 & p(ind1).n(26).ind  ~= 0)                   
            			            numpolig=numpolig+1;
            			            ind2=x+d1*y+d1*d2*(z+1);
            			            ind3=x+d1*(y+1)+d1*d2*(z+1);
			                        ind4=x+d1*(y+1)+d1*d2*z;
         			               XI(:,numpolig)=[ind1;ind2;ind3;ind4];           
			                        X(:,numpolig)=[p(ind1).r(1);p(ind2).r(1);p(ind3).r(1);p(ind4).r(1)];
         			               Y(:,numpolig)=[p(ind1).r(2);p(ind2).r(2);p(ind3).r(2);p(ind4).r(2)];
			                        Z(:,numpolig)=[p(ind1).r(3);p(ind2).r(3);p(ind3).r(3);p(ind4).r(3)];
                                end
                            end               
                        end
		             end
                 end%%plano x
                  
                  %%grafica en matlab
                    fill3(app.UIAxes,X,Y,Z,Z);
                    axis(app.UIAxes,[-2,d1+2,-2,d2+2,-10,d3+2]);
                    xlabel(app.UIAxes,'x');   ylabel(app.UIAxes,'y');   zlabel(app.UIAxes,'z');
                    M(numgrap)=getframe(app.UIAxes);
		            numgrap=numgrap+1;
               end%graficacion
            end;%%terminan k calculos
            movie(app.UIAxes,M);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarsimulacionButton
        function IniciarsimulacionButtonPushed(app, event)
            selectedSimulation = app.SeleccionaelsolidoasimularDropDown.Value;
            
            if strcmp(selectedSimulation, ' ')
                fig = uifigure;
                message = ["Ningun solido seleccionado","Por favor selecciona uno"];
                uialert(fig,message,"Warning", ...
                    "Icon","warning");
            end

            if strcmp(selectedSimulation, 'Malla')
                app.initializeMalla();
            elseif strcmp(selectedSimulation, 'Cuerda')
                app.initializeCuerda();
            elseif strcmp(selectedSimulation, 'Viga')
                app.UIAxes.Visible="on";
                app.PararSimulacionButton.Visible="on";
                app.EjeZEditField.Visible = "off";
                app.EjeZEditFieldLabel.Visible = "off";
                app.EjeYEditField.Visible = "off";
                app.EjeYEditFieldLabel.Visible = "off";
                app.EjeXEditField.Visible = "off";
                app.EjeXEditFieldLabel.Visible = "off";
                app.EjeZSpinner_2.Visible ="off";
                app.EjeZSpinner_2Label.Visible = "off";
                app.EjeYSpinner_2.Visible ="off";
                app.EjeYSpinner_2Label.Visible = "off";
                app.EjeXSpinner_2.Visible ="off";
                app.EjeXSpinner_2Label.Visible = "off";
                app.FuerzaexternaLabel.Visible = "off";
                app.NumerodecalculosEditField.Visible = "off";
                app.NumerodecalculosEditFieldLabel.Visible = "off";
                app.DimensiondelamallaLabel.Visible = "off";
                app.IntervalodeTiempoSlider.Visible = "off";
                app.IntervalodeTiempoLabel.Visible = "off";
                app.NiveldeRompimientoSlider.Visible = "off";
                app.NiveldeRompimientoSliderLabel.Visible = "off";
                app.ConstantedeamortiguamientoSlider.Visible = "off";
                app.ConstantedeamortiguamientoSliderLabel.Visible = "off";
                app.ConstantederesorteSlider.Visible = "off";
                app.ConstantedeResorteLabel.Visible = "off";
                app.SeleccionaelsolidoasimularDropDown.Visible = "off";
                app.SelectSimulationLabel.Visible = "off";
                app.IniciarsimulacionButton.Visible = "off";
                app.initializeViga();
            end
        end

        % Callback function
        function StopsimulationButtonPushed(app, event)
            % Set the stop flag to true
            app.StopFlag = true;
        end

        % Callback function
        function CleanaxesButtonPushed(app, event)
            app.StopFlag = true;
            cla(app.SimulationAxes);
        end

        % Value changed function: SeleccionaelsolidoasimularDropDown
        function SeleccionaelsolidoasimularDropDownValueChanged(app, event)
 
        end

        % Value changed function: ConstantederesorteSlider
        function ConstantederesorteSliderValueChanged(app, event)
            disp('Constante de resorte ->');
            disp(app.ConstantederesorteSlider.Value);
        end

        % Button pushed function: PararSimulacionButton
        function PararSimulacionButtonPushed(app, event)
            app.PararSimulacionButton.Visible = "off";
            app.UIAxes.Visible = "off";
            app.EjeZEditField.Visible = "on";
            app.EjeZEditFieldLabel.Visible = "on";
            app.EjeYEditField.Visible = "on";
            app.EjeYEditFieldLabel.Visible = "on";
            app.EjeXEditField.Visible = "on";
            app.EjeXEditFieldLabel.Visible = "on";
            app.EjeZSpinner_2.Visible ="on";
            app.EjeZSpinner_2Label.Visible = "on";
            app.EjeYSpinner_2.Visible ="on";
            app.EjeYSpinner_2Label.Visible = "on";
            app.EjeXSpinner_2.Visible ="on";
            app.EjeXSpinner_2Label.Visible = "on";
            app.FuerzaexternaLabel.Visible = "on";
            app.NumerodecalculosEditField.Visible = "on";
            app.NumerodecalculosEditFieldLabel.Visible = "on";
            app.DimensiondelamallaLabel.Visible = "on";
            app.IntervalodeTiempoSlider.Visible = "on";
            app.IntervalodeTiempoLabel.Visible = "on";
            app.NiveldeRompimientoSlider.Visible = "on";
            app.NiveldeRompimientoSliderLabel.Visible = "on";
            app.ConstantedeamortiguamientoSlider.Visible = "on";
            app.ConstantedeamortiguamientoSliderLabel.Visible = "on";
            app.ConstantederesorteSlider.Visible = "on";
            app.ConstantedeResorteLabel.Visible = "on";
            app.SeleccionaelsolidoasimularDropDown.Visible = "on";
            app.SelectSimulationLabel.Visible = "on";
            app.IniciarsimulacionButton.Visible = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulacion')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.Visible = 'off';
            app.IniciarsimulacionButton = uibutton(app.UIFigure, 'push');
            app.IniciarsimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarsimulacionButtonPushed, true);
            app.IniciarsimulacionButton.Tag = 'StartButton';
            app.IniciarsimulacionButton.Text = 'Iniciar simulacion';
            app.SelectSimulationLabel = uilabel(app.UIFigure);
            app.SelectSimulationLabel.Text = 'Selecciona el solido a simular:';
            app.SeleccionaelsolidoasimularDropDown = uidropdown(app.UIFigure);
            app.SeleccionaelsolidoasimularDropDown.Items = {'', 'Malla', 'Cuerda', 'Viga'};
            app.SeleccionaelsolidoasimularDropDown.ValueChangedFcn = createCallbackFcn(app, @SeleccionaelsolidoasimularDropDownValueChanged, true);
            app.SeleccionaelsolidoasimularDropDown.Value = '';
            app.ConstantedeResorteLabel = uilabel(app.UIFigure);
            app.ConstantedeResorteLabel.Text = {'Constante de'; 'resorte'};
            app.ConstantederesorteSlider = uislider(app.UIFigure);
            app.ConstantederesorteSlider.Limits = [0 1500];
            app.ConstantederesorteSlider.ValueChangedFcn = createCallbackFcn(app, @ConstantederesorteSliderValueChanged, true);
            app.ConstantederesorteSlider.Tag = 'ConstanteresorteSlider';
            app.ConstantederesorteSlider.Value = 1500;
            app.ConstantedeamortiguamientoSliderLabel = uilabel(app.UIFigure);
            app.ConstantedeamortiguamientoSliderLabel.Text = {'Constante de '; 'amortiguamiento'};
            app.ConstantedeamortiguamientoSlider = uislider(app.UIFigure);
            app.ConstantedeamortiguamientoSlider.Limits = [0 1];
            app.ConstantedeamortiguamientoSlider.Tag = 'ConstanteresorteSlider';
            app.ConstantedeamortiguamientoSlider.Value = 0.4;
            app.NiveldeRompimientoSliderLabel = uilabel(app.UIFigure);
            app.NiveldeRompimientoSliderLabel.Text = {'Nivel de'; 'Rompimiento'};
            app.NiveldeRompimientoSlider = uislider(app.UIFigure);
            app.NiveldeRompimientoSlider.Limits = [0 2];
            app.NiveldeRompimientoSlider.Tag = 'ConstanteresorteSlider';
            app.NiveldeRompimientoSlider.Value = 1.1;
            app.IntervalodeTiempoLabel = uilabel(app.UIFigure);
            app.IntervalodeTiempoLabel.Text = {'Intervalo de '; 'Tiempo'};
            app.IntervalodeTiempoSlider = uislider(app.UIFigure);
            app.IntervalodeTiempoSlider.Limits = [0.0001 0.01];
            app.IntervalodeTiempoSlider.Tag = 'ConstanteresorteSlider';
            app.IntervalodeTiempoSlider.Value = 0.01;
            app.DimensiondelamallaLabel = uilabel(app.UIFigure);
            app.DimensiondelamallaLabel.Text = 'Dimension de la malla';
            app.NumerodecalculosEditFieldLabel = uilabel(app.UIFigure);
            app.NumerodecalculosEditFieldLabel.Text = 'Numero de calculos';
            app.NumerodecalculosEditField = uieditfield(app.UIFigure, 'text');
            app.NumerodecalculosEditField.Value = '400';
            app.FuerzaexternaLabel = uilabel(app.UIFigure);
            app.FuerzaexternaLabel.Text = 'Fuerza externa';
            app.EjeXSpinner_2Label = uilabel(app.UIFigure);
            app.EjeXSpinner_2Label.Text = 'Eje X';
            app.EjeXSpinner_2 = uispinner(app.UIFigure);
            app.EjeXSpinner_2.RoundFractionalValues = 'on';
            app.EjeYSpinner_2Label = uilabel(app.UIFigure);
            app.EjeYSpinner_2Label.Text = 'Eje Y';
            app.EjeYSpinner_2 = uispinner(app.UIFigure);
            app.EjeYSpinner_2.RoundFractionalValues = 'on';
            app.EjeZSpinner_2Label = uilabel(app.UIFigure);
            app.EjeZSpinner_2Label.Text = 'Eje Z';
            app.EjeZSpinner_2 = uispinner(app.UIFigure);
            app.EjeZSpinner_2.RoundFractionalValues = 'on';
            app.EjeZSpinner_2.Value = -5;
            app.EjeXEditFieldLabel = uilabel(app.UIFigure);
            app.EjeXEditFieldLabel.Text = 'Eje X';
            app.EjeXEditField = uieditfield(app.UIFigure, 'text');
            app.EjeXEditField.Value = '10';
            app.EjeYEditFieldLabel = uilabel(app.UIFigure);
            app.EjeYEditFieldLabel.Text = 'Eje Y';
            app.EjeYEditField = uieditfield(app.UIFigure, 'text');
            app.EjeYEditField.Value = '5';
            app.EjeZEditFieldLabel = uilabel(app.UIFigure);
            app.EjeZEditFieldLabel.Text = 'Eje Z';
            app.EjeZEditField = uieditfield(app.UIFigure, 'text');
            app.EjeZEditField.Value = '1';
            app.PararSimulacionButton = uibutton(app.UIFigure, 'push');
            app.PararSimulacionButton.ButtonPushedFcn = createCallbackFcn(app, @PararSimulacionButtonPushed, true);
            app.PararSimulacionButton.Tag = 'StartButton';
            app.PararSimulacionButton.Visible = 'off';
            app.PararSimulacionButton.Text = 'Parar Simulacion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viga_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end