classdef TTV2_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Hyperlink                       matlab.ui.control.Hyperlink
        LimpiarButton                   matlab.ui.control.Button
        GenerarondaygrficadeFourierButton  matlab.ui.control.Button
        DetenerButton                   matlab.ui.control.Button
        SimularButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        MembranaTab                     matlab.ui.container.Tab
        TiempoMembranaTextValue         matlab.ui.control.Label
        KAmortiguamientoMembranaTextValue  matlab.ui.control.Label
        KResorteMembranaTextValue       matlab.ui.control.Label
        ValorLabel_3                    matlab.ui.control.Label
        ValorLabel_2                    matlab.ui.control.Label
        ValorLabel                      matlab.ui.control.Label
        InfoTiempoMembrana              matlab.ui.control.Button
        InfoAmortiguamientoMembrana     matlab.ui.control.Button
        InfoResorteMembrana             matlab.ui.control.Button
        InfoDimensionMembrana           matlab.ui.control.Button
        InfoCalculosMembrana            matlab.ui.control.Button
        TiempoMembrana                  matlab.ui.control.Slider
        IntervalodeTiemposLabel         matlab.ui.control.Label
        AmortiguamientoMembrana         matlab.ui.control.Slider
        ConstantedeamortiguamientoNmLabel  matlab.ui.control.Label
        ResorteMembrana                 matlab.ui.control.Slider
        ConstantederesorteNmLabel       matlab.ui.control.Label
        DimMembrana                     matlab.ui.control.NumericEditField
        DimensindelamembranaLabel       matlab.ui.control.Label
        CalculosMembrana                matlab.ui.control.NumericEditField
        NmerodeclculosEditFieldLabel    matlab.ui.control.Label
        ParmetrosLabel                  matlab.ui.control.Label
        CuerdaTab                       matlab.ui.container.Tab
        TiempoCuerdaTextValue           matlab.ui.control.Label
        KAmortiguamientoCuerdaTextValue  matlab.ui.control.Label
        KResorteCuerdaTextValue         matlab.ui.control.Label
        ValorLabel_6                    matlab.ui.control.Label
        ValorLabel_5                    matlab.ui.control.Label
        ValorLabel_4                    matlab.ui.control.Label
        InfoFuerzaExternaCuerda         matlab.ui.control.Button
        InfoTiempoCuerda                matlab.ui.control.Button
        InfoAmortiguamientoCuerda       matlab.ui.control.Button
        InfoResorteCuerda               matlab.ui.control.Button
        InfoParticulasCuerda            matlab.ui.control.Button
        InfoCalculosCuerda              matlab.ui.control.Button
        CalculosCuerda                  matlab.ui.control.NumericEditField
        NmerodeclculosEditField_2Label  matlab.ui.control.Label
        ParticulasCuerda                matlab.ui.control.NumericEditField
        NmerodepartculasEditField_2Label  matlab.ui.control.Label
        FextYCuerda                     matlab.ui.control.Spinner
        YSpinnerLabel_2                 matlab.ui.control.Label
        FextXCuerda                     matlab.ui.control.Spinner
        XSpinnerLabel_2                 matlab.ui.control.Label
        TiempoCuerda                    matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_2  matlab.ui.control.Label
        AmortiguamientoCuerda           matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_2  matlab.ui.control.Label
        ResorteCuerda                   matlab.ui.control.Slider
        ConstantederesorteSliderLabel_2  matlab.ui.control.Label
        FuerzaExternaenNewtonsLabel     matlab.ui.control.Label
        ParmetrosLabel_2                matlab.ui.control.Label
        VigaTab                         matlab.ui.container.Tab
        RompimientoVigaTextValue        matlab.ui.control.Label
        TiempoVigaTextValue             matlab.ui.control.Label
        KAmortiguamientoVigaTextValue   matlab.ui.control.Label
        KResorteVigaTextValue           matlab.ui.control.Label
        ValorLabel_10                   matlab.ui.control.Label
        ValorLabel_9                    matlab.ui.control.Label
        ValorLabel_8                    matlab.ui.control.Label
        ValorLabel_7                    matlab.ui.control.Label
        InfoRompimientoViga             matlab.ui.control.Button
        InfoTiempoViga                  matlab.ui.control.Button
        InfoAmortiguamientoViga         matlab.ui.control.Button
        InfoResorteViga                 matlab.ui.control.Button
        InfoDimensionViga               matlab.ui.control.Button
        InfoCalculosViga                matlab.ui.control.Button
        RompimientoViga                 matlab.ui.control.Slider
        NiveldeRompimientoSliderLabel   matlab.ui.control.Label
        DimZViga                        matlab.ui.control.Spinner
        ZSpinnerLabel_5                 matlab.ui.control.Label
        DimYViga                        matlab.ui.control.Spinner
        YSpinnerLabel_5                 matlab.ui.control.Label
        DimXViga                        matlab.ui.control.Spinner
        XSpinnerLabel_5                 matlab.ui.control.Label
        CalculosViga                    matlab.ui.control.NumericEditField
        NmerodecalculosEditFieldLabel   matlab.ui.control.Label
        TiempoViga                      matlab.ui.control.Slider
        IntervalodeTiempoSliderLabel_3  matlab.ui.control.Label
        AmortiguamientoViga             matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_3  matlab.ui.control.Label
        ResorteViga                     matlab.ui.control.Slider
        ConstantederesorteSliderLabel_3  matlab.ui.control.Label
        DimensindelamallaEditFieldLabel_3  matlab.ui.control.Label
        ParmetrosLabel_3                matlab.ui.control.Label
        VigaCompuestaTab                matlab.ui.container.Tab
        KAmortiguamientoVigaCompuestaTextValue_2  matlab.ui.control.Label
        ValorLabel_15                   matlab.ui.control.Label
        TiempoentreoscilacionesSSlider  matlab.ui.control.Slider
        TiempodeoscilacionSLabel        matlab.ui.control.Label
        InfoDebilitamientoVigaComp_2    matlab.ui.control.Button
        ValorLabel_14                   matlab.ui.control.Label
        ValorLabel_13                   matlab.ui.control.Label
        InfoDebilitamientoVigaComp      matlab.ui.control.Button
        InfoRompimientoVigaComp         matlab.ui.control.Button
        KAmortiguamientoVigaCompuestaTextValue  matlab.ui.control.Label
        KResorteVigaCompuestaTextValue  matlab.ui.control.Label
        DebilitamientoVigaCompuesta     matlab.ui.control.Slider
        ConstantedeamortiguamientoSliderLabel_4  matlab.ui.control.Label
        RompimientoVigaCompuesta        matlab.ui.control.Slider
        ConstantederesorteSliderLabel_4  matlab.ui.control.Label
        ParmetrosLabel_4                matlab.ui.control.Label
        ESCOMIPNLabel                   matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        SimulacindevibracionesencuerposslidosLabel  matlab.ui.control.Label
        SimulationAxes                  matlab.ui.control.UIAxes
    end

    properties (Access = private)
        % Flag to control the simulation loop
        StopFlag = false;
        SelectedTab string = 'Membrana';
        WarningFlag = false;
        CreatingFlag = false;
        arraySonido;

        % Constantes:
        KResorte = 5;
        KAmortiguamiento = 0.5;
        KTiempo = 0.01;
        NumCal =  1000;
        NumPart = 10;
        sizeEjeX = 10;
        sizeEjeY = 5;
        sizeEjeZ = 1;
        NivRompimiento = 0.4;
        FuerzaEjeX = 0;
        FuerzaEjeY = 0;
        FuerzaEjeZ = -5;
        DMalla = 60;
        KDebilitamiento = 0.2;
        TOscilacion = 0.01;
        fuerzaSis = 0.1;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            app.WarningFlag = false;
            
            % Validacion de parametros (dimension de la malla)
            if app.DMalla <= 0
                uialert(app.UIFigure, 'La dimensión de la membrana no puede ser 0 o negativa, por favor digite un valor mayor a 0', 'Dimensión de la malla', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end
        
            % Validacion de parametros (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end
            
            % Validacion de parametros
            if app.NumCal >= 1100
                uialert(app.UIFigure, 'Un número de cálculos mayor a 1100 convierte a la membrana en una estructura compleja de visualizar, disminuye la cantidad', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end
            
            % Validacion de parametros
            if app.DMalla >= 80
                uialert(app.UIFigure, 'Un tamaño de membrana mayor a 80 convierte la membrana en una estructura compleja de visualizar, disminuye la cantidad', 'Dimensión de la membrana', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 
        
            if ~app.WarningFlag
                % Parametros de la simulacion
                vec = 2; % vecinos considerados en calculo
                K = app.KResorte; % Constante del resorte entre la masa
                K2 = app.KAmortiguamiento; % Amortiguamiento
                T = app.KTiempo; % Tiempo
                D = app.DMalla; % Dimension de la malla
        
                % Inicializacion
                H = zeros(D); % posición 
                Hf = zeros(D); % Auxiliar
                V = zeros(D); % velocidad
                H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
                H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso
        
                % Array para tomar valores para el FTT
                S = zeros(1, app.NumCal); % Recoletamos el array para FFT
                sonido = 1; % Indice para datos de sonido
        
                % Loop principal para iteraciones
                for m = 1:app.NumCal
                    % Checar boton stop apretado
                    if app.StopFlag
                        break;
                    end
                    
                    if mod(m, 5) == 0
                        % Graficar el estado actual
                        surf(app.SimulationAxes, H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                        camlight(app.SimulationAxes, 'right');
                        axis(app.SimulationAxes, [0, D, 0, D, -1, 1]);
                        drawnow;
                    end
        
                    % Perform the calculations
                    for j = vec+1:D-vec
                        for i = vec+1:D-vec
                            Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                            X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                            V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                            Hf(i, j) = H(i, j) + V(i, j) * T;
                        end
                    end
                    H = Hf;
                    
                    % Collect data for FFT every 2 iterations
                    if mod(m, 2) == 0
                        S(sonido) = H(round(D/2), round(D/2)); % Collects the wave in the center of the mesh
                        sonido = sonido + 1;
                    end
        
                    pause(0.001);
                end
        
                % Guardar el array de sonido (omitiendo ceros) y mandarlo
                % para poder graficar con Fourier
                S = S(1:sonido-1);
                app.arraySonido = S;
                app.GenerarondaygrficadeFourierButton.Visible = "on";
            end
        end

        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;
            app.WarningFlag = false;

            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.NumCal >= 500
                uialert(app.UIFigure, 'Un número de cálculos mayor a 500 convierte la cuerda en una estructura compleja de visualizar, disminuye la cantidad', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.NumPart >= 99
                uialert(app.UIFigure, 'Un número de partículas mayor a 99 convierte la cuerda en una estructura compleja de visualizar, disminuye la cantidad', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            % Parameters validation (numero de particulas)
            if app.NumPart <= 2
                uialert(app.UIFigure, 'El número de particulas  no puede ser menor a 2 porque no se mostraría una simulación, por favor digite un valor mayor a 2', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end


            if ~app.WarningFlag
                K1 = app.KResorte; % Constante de resorte
                K2 = app.KAmortiguamiento; % Constante de amortiguamiento
                rompimiento = 100; % Nivel de rompimiento
                Fext = [app.FuerzaEjeX; app.FuerzaEjeY ; app.FuerzaEjeZ]; % Fuerza externa
                T = app.KTiempo; % Intervalo de tiempo
                numPart = app.NumPart;
                sonido=1; % Indice de onda
                k = 0; % Indice para particulas

                % Inicialización de las partículas y vecinos
                p = struct('r', cell(1, numPart), 'v', cell(1, numPart), 'fija', cell(1, numPart), 'n', cell(1, numPart));
                for x = 1:numPart
                    k = k + 1;
                    p(k).r = [x; 0; 0];
                    p(k).v = [0; 0; 0];
                    p(k).fija = 0;
                    w = 0; % Indice para vecinos
                    for nx = x-1:2:x+1 % Vecinos de la particula + ellamisma
                        if (1 <= nx && nx <= numPart)
                            w = w + 1;
                            p(k).n(w).ind = nx; % Indice del vecino w
                            p(k).n(w).disteq = 0.1;
                        end
                    end
                    p(k).numVec = w;
                end
                p(1).fija = 1;
                p(numPart).fija = 1;
                pf = p; % aux
                numcalculos = app.NumCal; % 24cuad p/seg * 10 seg * 5 calculos por cuadro

                for k = 1:numcalculos % Inician Calculos
                    if app.StopFlag
                        break;
                    end
                    for in = 2:numPart-1 % Excluir los cálculos para las partículas fija
                        if (p(in).fija == 0) % Exeptua el calculo particulas fijas
                            DX = [0; 0; 0]; % Calcula el desplazamiento
                            for w = 1:p(in).numVec % Vecinos de la particula + ellamisma
                                if (p(in).n(w).ind ~= 0) % Existe o no esta roto
                                    DR = p(in).r - p(p(in).n(w).ind).r; % Vector posicion relativa
                                    dr = norm(DR);
                                    if dr > (rompimiento * p(in).n(w).disteq) % rompimiento es la fraccion a superar
                                        pf(in).n(w).ind = 0;
                                    end
                                    r = DR / dr; % Unitario en la direccion de desplazamiento              
                                    DX = DX + (dr - p(in).n(w).disteq) * r; % Vector desplazamiento
                                end
                            end
                            pf(in).v = p(in).v + (-K1 * DX - K2 * p(in).v + Fext) * T;
                            pf(in).r = p(in).r + pf(in).v * T;              
                        end % Exeptua particulas fijas                  
                    end % Terminan calculos
                    p = pf;

                    if (mod(k,2)==0) % Para formar onda 
                      S(sonido)=p(numPart/2).r(2); % 4indPtoMed).r(2);
                      sonido=sonido+1;
                    end

                    if 1 % Graficacion
                        XX = zeros(1, numPart);
                        YY = zeros(1, numPart);
                        for x = 1:numPart
                            XX(x) = p(x).r(1);
                            YY(x) = p(x).r(2);
                        end
                        plot(app.SimulationAxes, XX, YY, '.-');
                        axis(app.SimulationAxes, [-1, numPart + 0.5, -1, 1]);
                        drawnow;
                    end % Termina graficacion
                end % Terminan k calculos
                % Asignar array de sonido para Fourier
                app.arraySonido = S;
                app.GenerarondaygrficadeFourierButton.Visible = "on";
            end
        end

        function initializeViga(app)
            app.StopFlag = false;
            app.WarningFlag = false;
            
            % Parameters validation (numero de calculos)
            if app.NumCal <= 0
                uialert(app.UIFigure, 'El numero de cálculos no puede ser 0 o negativos, por favor digite un valor mayor a 0', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.NumCal >= 500
                uialert(app.UIFigure, 'Un número de cálculos mayor a 500 convierte la cuerda en una estructura compleja de visualizar, disminuye la cantidad', 'Número de cálculos', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end

            if app.sizeEjeX >= 20 || app.sizeEjeY >=20 
                uialert(app.UIFigure, 'La dimensión del eje X o Y mayor a 20 convierte la viga en una estructura compleja de visualizar, disminuye la cantidad', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 

            if app.sizeEjeZ > 5
                uialert(app.UIFigure, 'La dimensión del eje Z mayor a 5 convierte la viga en una estructura compleja de visualizar, disminuye la cantidad', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 

            if app.sizeEjeZ < 2
                uialert(app.UIFigure, 'La dimensión del eje Z menor a 2 convierte la viga en una estructura no visible, aumenta la dimension', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 

            if app.sizeEjeX < 2
                uialert(app.UIFigure, 'La dimensión del eje X menor a 2 convierte la viga en una estructura no visible, aumenta la dimension', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 

            if app.sizeEjeX < 1 || app.sizeEjeY < 1 || app.sizeEjeZ < 1
                uialert(app.UIFigure, 'La dimensión de cualquier eje no puede ser menor a 1, aumenta la cantidad', 'Número de partículas', ...
                    'Icon', 'warning');
                app.WarningFlag = true;
            end 

            if app.WarningFlag == false
                numcalculos = app.NumCal; % 24*3*5; % 24cuad p/seg * 10 seg * 5 calculos por cuadro
                K1 = app.KResorte; % Constante de resorte
                K2 = app.KAmortiguamiento; % Amortiguamiento
                rompimiento = app.NivRompimiento; % Nivel de rompimiento
                gravedad = [0; 0; -4]; % Fuerza externa
                T = app.KTiempo; % Intervalo de tiempo
                d1 = app.sizeEjeX; % Dimension x de la malla
                d2 = app.sizeEjeY; % Dimensión y
                d3 = app.sizeEjeZ; % Dimensión z
                numpuntos = d1 * d2 * d3;
                indPtoMed = round((d1 + d1 * (d2 - 1) + d1 * d2 * (d3 - 1)) / 2);
                k = 0; % Indice para partículas
                numgrap = 1; % Indice de graficas
                sonido = 1; % Indice de onda
                % Bloque para considerar mallas de espesor 1
                if d1 == 1, lx = 1; lxp = 1; incx = 1; else, lx = d1 - 1; lxp = d1; incx = d1 - 1; end
                if d2 == 1, ly = 0; lyp = 0; incy = 1; else, ly = d2 - 2; lyp = d2 - 1; incy = d2 - 1; end
                if d3 == 1, lz = 0; lzp = 0; incz = 1; else, lz = d3 - 2; lzp = d3 - 1; incz = d3 - 1; end
                % Termina bloque
        
                % Inicialización de posiciones
                for z = 0:d3-1
                    for y = 0:d2-1
                        for x = 1:d1
                            k = k + 1;
                            p(k).r = [x; y; z]; % Posición inicial de las partículas
                            p(k).v = [0; 0; 0]; % Velocidad inicial
                            w = 1; % Indice para vecinos
                            for nz = z-1:z+1
                                for ny = y-1:y+1
                                    for nx = x-1:x+1 % 26 vecinos de la particula + ellamisma              
                                        if (1 <= nx && nx <= d1) && (0 <= ny && ny <= d2-1) && (0 <= nz && nz <= d3-1)
                                            p(k).n(w).ind = nx + d1 * ny + d1 * d2 * nz; %indice del vecino w
                                            p(k).n(w).disteq = norm(p(k).r - [nx; ny; nz]); %distancia de equilibrio con el vecino w
                                        else
                                            p(k).n(w).ind = 0;
                                        end
                                        w = w + 1;
                                    end
                                end
                            end
                            p(k).n(14).ind = 0; % Particula misma
                        end
                    end
                end % Termina inicialización
        
                pf = p; % Auxiliar
                S = zeros(1, numcalculos); % Inicializar la onda para el sonido
                for k = 1:numcalculos % Calculos
                    if app.StopFlag
                        break;
                    end
                    for z = 0:(d3-1) % Inician calculos del automata celular
                        for y = 0:(d2-1)
                            for x = 1:d1
                                ind = x + d1 * y + d1 * d2 * z;
                                DX = [0; 0; 0];
                                for w = 1:27 % Vecinos de la particula + ellamisma
                                    if (p(ind).n(w).ind ~= 0) % Existe o no esta roto
                                        DR = (p(ind).r - p(p(ind).n(w).ind).r); % Vector posición relativa
                                        dr = norm(DR);
                                        if dr > (rompimiento * p(ind).n(w).disteq)
                                            pf(ind).n(w).ind = 0;
                                        end            
                                        r = DR / dr; % Unitario en la dirección de desplazamiento              
                                        DX = DX + (dr - p(ind).n(w).disteq) * r; % Vector desplazamiento
                                    end
                                end        
                                if (1 < x && x < d1)
                                    if (x == round(d1 / 2)), gravedad = [0; 0; -20]; else, gravedad = [0; 0; -2]; end
                                    pf(ind).v = p(ind).v + (-K1 * DX - K2 * p(ind).v + gravedad) * T;
                                    pf(ind).r = p(ind).r + pf(ind).v * T;              
                                end                  
                            end % x
                        end % y 
                    end % z    
                    p = pf; % Terminan calculos del automata
                    if (mod(k,5) == 0) % Para formar onda 
                        S(sonido) = p(indPtoMed).r(2);
                        sonido = sonido + 1;
                    end
                    % Empieza graficación
                    if (mod(k,10) == 0) % Solo grafica cada 10 calculos por precisión
                        numpolig = 0;
                        X = [];
                        Y = [];
                        Z = [];
                        
                        % Acumular coordenadas de los polígonos para cada plano
                        if (d1 > 1 && d2 > 1) % Calcula poligonos de planos Z
                            for z = 0:incz:lzp
                                for y = 0:ly
                                    for x = 1:lx
                                        ind1 = x + d1 * y + d1 * d2 * z;
                                        if (p(ind1).n(15).ind ~= 0 && p(ind1).n(17).ind ~= 0 && p(ind1).n(18).ind ~= 0)                   
                                            numpolig = numpolig + 1;
                                            ind2 = x + 1 + d1 * y + d1 * d2 * z;
                                            ind3 = x + 1 + d1 * (y + 1) + d1 * d2 * z;
                                            ind4 = x + d1 * (y + 1) + d1 * d2 * z;
                                            X(:, numpolig) = [p(ind1).r(1); p(ind2).r(1); p(ind3).r(1); p(ind4).r(1)];
                                            Y(:, numpolig) = [p(ind1).r(2); p(ind2).r(2); p(ind3).r(2); p(ind4).r(2)];
                                            Z(:, numpolig) = [p(ind1).r(3); p(ind2).r(3); p(ind3).r(3); p(ind4).r(3)];
                                        end
                                    end %x
                                end %y
                            end %z
                        end % Plano z
        
                        if (d1 > 1 && d3 > 1) % calcula poligonos de planos Y  
                            for y = 0:incy:lyp
                                for z = 0:lz
                                    for x = 1:lx
                                        ind1 = x + d1 * y + d1 * d2 * z;
                                        if (p(ind1).n(15).ind ~= 0 && p(ind1).n(23).ind ~= 0 && p(ind1).n(24).ind ~= 0)                   
                                            numpolig = numpolig + 1;
                                            ind2 = x + 1 + d1 * y + d1 * d2 * z;
                                            ind3 = x + 1 + d1 * y + d1 * d2 * (z + 1);
                                            ind4 = x + d1 * y + d1 * d2 * (z + 1);
                                            X(:, numpolig) = [p(ind1).r(1); p(ind2).r(1); p(ind3).r(1); p(ind4).r(1)];
                                            Y(:, numpolig) = [p(ind1).r(2); p(ind2).r(2); p(ind3).r(2); p(ind4).r(2)];
                                            Z(:, numpolig) = [p(ind1).r(3); p(ind2).r(3); p(ind3).r(3); p(ind4).r(3)];
                                        end
                                    end %x
                                end %y
                            end %z
                        end % Plano y
        
                        if (d2 > 1 && d3 > 1) % Calcula poligonos de planos X
                            for x = 1:incx:lxp
                                for z = 0:lz
                                    for y = 0:ly
                                        ind1 = x + d1 * y + d1 * d2 * z;
                                        if (p(ind1).n(23).ind ~= 0 && p(ind1).n(17).ind ~= 0 && p(ind1).n(26).ind ~= 0)                   
                                            numpolig = numpolig + 1;
                                            ind2 = x + d1 * y + d1 * d2 * (z + 1);
                                            ind3 = x + d1 * (y + 1) + d1 * d2 * (z + 1);
                                            ind4 = x + d1 * (y + 1) + d1 * d2 * z;
                                            X(:, numpolig) = [p(ind1).r(1); p(ind2).r(1); p(ind3).r(1); p(ind4).r(1)];
                                            Y(:, numpolig) = [p(ind1).r(2); p(ind2).r(2); p(ind3).r(2); p(ind4).r(2)];
                                            Z(:, numpolig) = [p(ind1).r(3); p(ind2).r(3); p(ind3).r(3); p(ind4).r(3)];
                                        end
                                    end               
                                end
                            end
                        end % Plano x
                        
                        % Graficar en Axes
                        fill3(app.SimulationAxes, X, Y, Z, Z);
                        axis(app.SimulationAxes, [-2, d1 + 2, -2, d2 + 2, -10, d3 + 2]);
                        xlabel(app.SimulationAxes, 'x');   
                        ylabel(app.SimulationAxes, 'y');   
                        zlabel(app.SimulationAxes, 'z');
                        drawnow;
                    end % Termina graficacion
                end % Terminan k calculos
                % Calcular y graficar la transformada de Fourier
                app.arraySonido = S;
                app.GenerarondaygrficadeFourierButton.Visible = "on";
            end
        end

        function initializeVigaCompuesta(app)
            app.StopFlag = false;
            app.CreatingFlag = true;
          
            % Inicialización de constantes
            rompimiento = app.NivRompimiento; % Nivel de rompimiento
            debilitamiento = app.KDebilitamiento * -1; % Nivel para debilitar el material
            T = 0.001; % Intervalo de tiempo (delta T) segundos

            % Creación de estructura
            Amplitud = 0.5; % del bloque
            p(1).r = [0;0;0];
            % Techo
            p = bloque(app, [-2.25;0;2.75], [2.25;0;2.75], Amplitud, 4.5, p, 1); % Techo
            % Vigas de soporte.
            p = bloque(app, [2;-2;2], [2;-2;0], Amplitud, Amplitud, p, 1); % Columna
            p = bloque(app, [2;2;2], [2;2;0], Amplitud, Amplitud, p, 1); % Columna
            p = bloque(app, [-2;-2;2], [-2;-2;0], Amplitud, Amplitud, p, 1); % Columna
            p = bloque(app, [-2;2;2], [-2;2;0], Amplitud, Amplitud, p, 1); % Columna

            p = unePuntos(app, p, Amplitud);
        
            IntervaloGraf = 5; % ceil(GraficarCada/T);
            PO = .01; % Periodo de oscilación segundos
            numPart = length(p);
        
            % Inicializar array para almacenar posiciones y datos para FFT
            posiciones = cell(1, ceil(1000 / IntervaloGraf));
            S = zeros(1, 1000); % Array para recolectar datos para FFT
            sonido = 1; % Índice de onda
        
            % Crear el diálogo de progreso
            progressDlg = uiprogressdlg(app.UIFigure, 'Title', 'Porfavor espere', ...
                'Message', 'Generando simulación...', 'Indeterminate', 'off', 'Cancelable', 'on');

            % Número de iteraciones de la tarea
            numIter = 300;

            % Calculo de Iteración
            for k = 1:300 % Número de cálculos para todo el sólido
                % Verificar si el usuario ha cancelado el diálogo
                if progressDlg.CancelRequested
                    break;
                end
                if app.StopFlag
                    break;
                end
                for ind1 = 1:numPart % Para cada partícula
                    if app.StopFlag
                        break;
                    end
        
                    p = InteraccionConVecinos(app, ind1, p, rompimiento, debilitamiento); % Calcula fuerza
        
                    if p(ind1).r(3) < 0.1 % Las que están en el suelo se aplica mov. oscilatorio
                        p(ind1).r(1) = p(ind1).r(1) + 0.1 * sin(app.TOscilacion * pi * k * T / PO); % Oscilación de la base 0 a 10 segundos;%mov eje x
                    else
                        p(ind1).v = p(ind1).v + p(ind1).Acel * T;
                        p(ind1).r = p(ind1).r + p(ind1).v * T + p(ind1).Acel * T^2 / 2;
                    end
        
                    p(ind1).Acel = [0;0;0]; % Inicializa para siguiente ciclo
        
                end % Para cada partícula
        
                if (mod(k, IntervaloGraf) == 0) % Almacena cada N cálculos por precisión
                    posiciones{k / IntervaloGraf} = p;
                end
        
                if (mod(k, 2) == 0) % Recolectar datos para FFT cada 2 cálculos
                    S(sonido) = p(round(numPart/2)).r(2); % Ejemplo de recolección de un punto en el centro
                    sonido = sonido + 1;
                end

                % Actualizar la barra de progreso
                progressDlg.Value = k / numIter;
                progressDlg.Message = sprintf('Generando... (%d/%d)', k, numIter);
             end % Cálculos

            % Mostrar mensaje si la tarea fue cancelada
            if progressDlg.CancelRequested
                uialert(app.UIFigure, 'La simulación fue cancelada', 'Task Canceled');
            end

            % Cerrar el diálogo de progreso
            close(progressDlg);

            % Graficar todas las posiciones almacenadas
            for i = 1:length(posiciones)
                if app.StopFlag
                    break;
                end
                if ~isempty(posiciones{i})
                    cla(app.SimulationAxes);
                    GraficaAlambres(app, posiciones{i}, app.SimulationAxes);
                    pause(0.1);
                end
            end

            % Calcular y graficar la transformada de Fourier
            app.arraySonido = S;
            app.GenerarondaygrficadeFourierButton.Visible = "on";
        end
        
        function p = bloque(app, p1, p2, Alto, Ancho, p, Material)
            puntoRes = p2 - p1;
            normaXYZ = norm(puntoRes);
            normaXY = norm([puntoRes(1);puntoRes(2)]);
            senFi = puntoRes(3) / normaXYZ;
            cosFi = normaXY / normaXYZ;
            if normaXY == 0
                senTeta = 0;
                cosTeta = 1;
            else
                senTeta = puntoRes(2) / normaXY;
                cosTeta = puntoRes(1) / normaXY;
            end
            % Matrices de Rotación
            rotacionY = [cosFi, 0, -senFi; 0, 1, 0; senFi, 0, cosFi];
            rotacionZ = [cosTeta, -senTeta, 0; senTeta, cosTeta, 0; 0, 0, 1];
            R1 = rotacionZ * rotacionY;
            % Construye un paralelepípedo
            k = length(p); % Índice para partículas
            if k == 1
                k = 0;
            end
            incAlto = 0.5;
            incAncho = 0.5; % Ancho/2;%eje z
            incLargo = 0.5; % NormaXYZ/5;%eje x
            if Material == 1
                masaMaterial = 2; % 20000;
            elseif Material == 2
                masaMaterial = 8500;
            end
            for z = -Alto/2:incAlto:Alto/2 % Bloque de Inicialización de posiciones
                if app.StopFlag
                    break;
                end                
                for y = -Ancho/2:incAncho:Ancho/2
                    if app.StopFlag
                        break;
                    end
                    for x = 0:incLargo:normaXYZ
                        if app.StopFlag
                            break;
                        end
                        k = k + 1;
                        p(k).r = R1 * [x; y; z] + p1; % Posición inicial de las partículas
                        p(k).v = [0; 0; 0]; % Velocidad inicial
                        p(k).Acel = [0; 0; 0]; % Fuerza
                        p(k).Masa = masaMaterial; % Masa
                        p(k).Material = Material;
                    end
                end
            end
            return;
        end
        
        function p = unePuntos(app, p, Amplitud)
            % Bloque que elimina las partículas muy cercanas
            i = 1;
            numPart = length(p);
            while i < numPart
                if app.StopFlag
                    break;
                end                
                j = i + 1;
                while j <= numPart
                    if app.StopFlag
                        break;
                    end
                    distEq = norm(p(i).r - p(j).r);
                    if distEq < Amplitud * 0.8 % Menores al mínimo
                        p(j) = []; % Se elimina
                        numPart = numPart - 1; % Disminuye
                    else
                        j = j + 1;
                    end
                end
                i = i + 1;
            end
            % Define vecinos cercanos
            for i = 1:numPart
                if app.StopFlag
                    break;
                end                
                w = 0; % Índice para vecinos de la k-ésima partícula
                for j = i + 1:numPart
                    if app.StopFlag
                        break;
                    end                  
                    distEq = norm(p(i).r - p(j).r);
                    if distEq <= Amplitud * 1.5
                        w = w + 1;
                        p(i).n(w).ind = j; % Índice del vecino w
                        p(i).n(w).disteq = distEq; % Distancia de equilibrio con el vecino w
                    end
                end
                p(i).numVec = w; % Número de vecinos de la partícula k
            end % Termina inicialización
            return;     
        end
        
        function GraficaAlambres(app, p, ax)
            numPart = length(p);
            hold(ax, 'on');
        
            % Inicializar arrays para almacenar las coordenadas de las líneas
            X = [];
            Y = [];
            Z = [];
        
            for ind1 = 1:numPart
                if app.StopFlag
                    break;
                end
                for j = 1:p(ind1).numVec
                    if app.StopFlag
                        break;
                    end
                    ind2 = p(ind1).n(j).ind;
                    if (ind2 > ind1)
                        % Acumular las coordenadas de las líneas
                        X = [X, p(ind1).r(1), p(ind2).r(1), NaN];
                        Y = [Y, p(ind1).r(2), p(ind2).r(2), NaN];
                        Z = [Z, p(ind1).r(3), p(ind2).r(3), NaN];
                    end
                end
            end
        
            % Graficar todas las líneas en una sola llamada a plot3
            plot3(ax, X, Y, Z);
        
            % Configurar la vista y los ejes
            view(ax, 3);
            axis(ax, 'equal');
            xlabel(ax, 'x');
            ylabel(ax, 'y');
            zlabel(ax, 'z');
            grid(ax, 'on');
        end
        
        % Calcula la fuerza de interacción de una partícula con sus vecinos
        function p = InteraccionConVecinos(app, ind1, p, rompimiento, debilitamiento)
            for ind2 = 1:p(ind1).numVec % Para todos los vecinos de la partícula ind1-ésima
                if app.StopFlag
                    break;
                end
                indVec = p(ind1).n(ind2).ind;
                if (indVec > ind1) % Si no está roto y no se ha calculado
                    DR = (p(ind1).r - p(indVec).r); % Vector posición relativa
                    dr = norm(DR);
                    delta = (dr - p(ind1).n(ind2).disteq) / p(ind1).n(ind2).disteq;
        
                    if delta < debilitamiento % Si se comprime mucho se debilita el material
                        p(ind1).n(ind2).ind = 0; % Rompimiento
                    end
        
                    if dr > 0
                        ru = DR / dr; % Unitario en la dirección de desplazamiento
                        Acelij = (delta * (1.6325e+4) * ru) / p(ind1).Masa;
                        p(ind1).Acel = p(ind1).Acel - Acelij; % Fuerza en la partícula
                        p(indVec).Acel = p(indVec).Acel + Acelij; % Fuerza en su vecina(reacción)
                    end
                end % (indVec > ind1) % Si no está roto
            end % Termina para todos los vecinos
        
            L = length(p);
        
            for indVec = ind1 + 1:L % Para todas las partículas
                if app.StopFlag
                    break;
                end
                DR = (p(ind1).r - p(indVec).r); % Vector posición relativa
                dr = norm(DR);
                if dr < .47 % en metros(rango de choque)
                    if dr > 0
                        ru = DR / dr; % Unitario en la dirección de desplazamiento
                        Acelij = 1000 * ru / (dr);
                        p(ind1).Acel = p(ind1).Acel + Acelij; % Fuerza en la partícula
                        p(indVec).Acel = p(indVec).Acel - Acelij; % Fuerza en su vecina(reacción)
                    end
                end % (indVec > ind1) % Si no está roto
            end % Termina para todos los vecinos
            AcelExt = [0;0;-9.8]; % Fuerza externa (gravedad)
            K2 = 0.2; % Amortiguamiento
            Ffric = -K2 * p(ind1).v;
            p(ind1).Acel = p(ind1).Acel + AcelExt + Ffric;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ResorteMembrana
        function ResorteMembranaValueChanged(app, event)
            app.KResorte = app.ResorteMembrana.Value;
        end

        % Value changed function: AmortiguamientoMembrana
        function AmortiguamientoMembranaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoMembrana.Value;
            
        end

        % Value changed function: TiempoMembrana
        function TiempoMembranaValueChanged(app, event)
            app.KTiempo = app.TiempoMembrana.Value;
            
        end

        % Button pushed function: SimularButton
        function SimularButtonPushed(app, event)
            app.StopFlag = false;
            app.GenerarondaygrficadeFourierButton.Visible = "off";
           
            if strcmp(app.SelectedTab, 'Membrana')
                app.KResorte = app.ResorteMembrana.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoMembrana.Value;
                app.KTiempo = app.TiempoMembrana.Value;
                app.DMalla = app.DimMembrana.Value;
                app.NumCal = app.CalculosMembrana.Value;

                app.initializeMalla();
            elseif strcmp(app.SelectedTab, 'Cuerda')
                app.KResorte = app.ResorteCuerda.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoCuerda.Value;
                app.KTiempo = app.TiempoCuerda.Value;
                app.NumCal = app.CalculosCuerda.Value;    
                app.NumPart = app.ParticulasCuerda.Value;
                app.FuerzaEjeX = app.FextXCuerda.Value;
                app.FuerzaEjeY = app.FextYCuerda.Value;

                app.initializeCuerda();
            elseif strcmp(app.SelectedTab, 'Viga')
                app.KResorte = app.ResorteViga.Value; % Actualiza KResorte antes de iniciar la simulación
                app.KAmortiguamiento = app.AmortiguamientoViga.Value;
                app.KTiempo = app.TiempoViga.Value;
                app.NumCal = app.CalculosViga.Value; 
                app.NivRompimiento = app.RompimientoViga.Value;
                app.sizeEjeX = app.DimXViga.Value;
                app.sizeEjeY = app.DimYViga.Value;
                app.sizeEjeZ = app.DimZViga.Value;

                app.initializeViga();

            elseif strcmp(app.SelectedTab, 'Viga Compuesta')
                app.NivRompimiento = app.RompimientoVigaCompuesta.Value;
                app.KDebilitamiento = app.DebilitamientoVigaCompuesta.Value;
                app.fuerzaSis = app.FuerzadelmovimientoSlider.Value;
                app.TOscilacion = app.TiempoentreoscilacionesSSlider.Value;

                app.initializeVigaCompuesta();
            end
        end

        % Button pushed function: DetenerButton
        function DetenerButtonPushed(app, event)
            % Set the stop flag to true
            app.GenerarondaygrficadeFourierButton.Visible = "off";
            app.StopFlag = true;
        end

        % Button pushed function: LimpiarButton
        function LimpiarButtonPushed(app, event)
            app.GenerarondaygrficadeFourierButton.Visible = "off";
            app.StopFlag = true;
            cla(app.SimulationAxes);
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.SelectedTab = app.TabGroup.SelectedTab.Title;
        end

        % Value changed function: ResorteCuerda
        function ResorteCuerdaValueChanged(app, event)
            app.KResorte = app.ResorteCuerda.Value;           
        end

        % Value changed function: AmortiguamientoCuerda
        function AmortiguamientoCuerdaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoCuerda.Value;            
        end

        % Value changed function: TiempoCuerda
        function TiempoCuerdaValueChanged(app, event)
            app.KTiempo = app.TiempoCuerda.Value;
        end

        % Value changed function: DimMembrana
        function DimMembranaValueChanged(app, event)
            app.DMalla = app.DimMembrana.Value;
            
        end

        % Value changed function: CalculosMembrana
        function CalculosMembranaValueChanged(app, event)
            app.NumCal = app.CalculosMembrana.Value;
            
        end

        % Value changed function: ParticulasCuerda
        function ParticulasCuerdaValueChanged(app, event)
            app.NumPart = app.ParticulasCuerda.Value;
            
        end

        % Value changed function: FextXCuerda
        function FextXCuerdaValueChanged(app, event)
            app.FuerzaEjeX = app.FextXCuerda.Value;
            
        end

        % Value changed function: FextYCuerda
        function FextYCuerdaValueChanged(app, event)
            app.FuerzaEjeY = app.FextYCuerda.Value;
            
        end

        % Callback function
        function FextZCuerdaValueChanged(app, event)
            app.FuerzaEjeZ = app.FextZCuerda.Value;
            
        end

        % Value changed function: CalculosCuerda
        function CalculosCuerdaValueChanged(app, event)
            app.NumCal = app.CalculosCuerda.Value;
        end

        % Value changed function: ResorteViga
        function ResorteVigaValueChanged(app, event)
            app.KResorte = app.ResorteViga.Value;
            
        end

        % Value changed function: AmortiguamientoViga
        function AmortiguamientoVigaValueChanged(app, event)
            app.KAmortiguamiento = app.AmortiguamientoViga.Value;
            
        end

        % Value changed function: TiempoViga
        function TiempoVigaValueChanged(app, event)
            app.KTiempo = app.TiempoViga.Value;
            
        end

        % Value changed function: RompimientoViga
        function RompimientoVigaValueChanged(app, event)
            app.NivRompimiento = app.RompimientoViga.Value;
            
        end

        % Button pushed function: GenerarondaygrficadeFourierButton
        function GenerarondaygrficadeFourierButtonPushed(app, event)
            % Plot the generated wave
            figure;
            plot(app.arraySonido);
            title('Onda generada');
            xlabel('Tiempo');
            ylabel('Amplitud');
            F = fft(app.arraySonido);
            figure;
            plot(abs(F));
            title('Transformada de Fourier de la onda');
            xlabel('Frecuencia');
            ylabel('Amplitud');
        end

        % Button pushed function: InfoResorteMembrana
        function InfoResorteMembranaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuán fuerte es el resorte virtual que une las partículas, es decir, cuánta fuerza necesita para estirarse o comprimirse.', 'Constante de Resorte', 'Icon', 'info');
        end

        % Button pushed function: InfoResorteCuerda
        function InfoResorteCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuán fuerte es el resorte virtual que une las partículas, es decir, cuánta fuerza necesita para estirarse o comprimirse.', 'Constante de Resorte', 'Icon', 'info');
        end

        % Button pushed function: InfoResorteViga
        function InfoResorteVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuán fuerte es el resorte virtual que une las partículas, es decir, cuánta fuerza necesita para estirarse o comprimirse.', 'Constante de Resorte', 'Icon', 'info');            
        end

        % Button pushed function: InfoAmortiguamientoMembrana
        function InfoAmortiguamientoMembranaButtonPushed(app, event)
            uialert(app.UIFigure, 'Controla cuánta resistencia hay al movimiento, como una fricción que desacelera las partículas.', 'Constante de Amortiguamiento', 'Icon', 'info');            
        end

        % Button pushed function: InfoAmortiguamientoCuerda
        function InfoAmortiguamientoCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Controla cuánta resistencia hay al movimiento, como una fricción que desacelera las partículas.', 'Constante de Amortiguamiento', 'Icon', 'info');                        
        end

        % Button pushed function: InfoAmortiguamientoViga
        function InfoAmortiguamientoVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Controla cuánta resistencia hay al movimiento, como una fricción que desacelera las partículas.', 'Constante de Amortiguamiento', 'Icon', 'info');                        
        end

        % Button pushed function: InfoTiempoMembrana
        function InfoTiempoMembranaButtonPushed(app, event)
            uialert(app.UIFigure, 'Indica el tiempo que pasa en cada paso de la simulación, afectando la rapidez y precisión de los cálculos.', 'Constante de Tiempo', 'Icon', 'info');                        
        end

        % Button pushed function: InfoTiempoCuerda
        function InfoTiempoCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Indica el tiempo que pasa en cada paso de la simulación, afectando la rapidez y precisión de los cálculos.', 'Constante de Tiempo', 'Icon', 'info');                                    
        end

        % Button pushed function: InfoTiempoViga
        function InfoTiempoVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Indica el tiempo que pasa en cada paso de la simulación, afectando la rapidez y precisión de los cálculos.', 'Constante de Tiempo', 'Icon', 'info');                                    
        end

        % Callback function
        function InfoTiempoVigaCompButtonPushed(app, event)
            uialert(app.UIFigure, 'Indica el tiempo que pasa en cada paso de la simulación, afectando la rapidez y precisión de los cálculos.', 'Constante de Tiempo', 'Icon', 'info');                                                
        end

        % Button pushed function: InfoCalculosMembrana
        function InfoCalculosMembranaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuántos pasos de cálculo se realizan en la simulación, determinando su duración total.', 'Número de cálculos', 'Icon', 'info');                                                
        end

        % Button pushed function: InfoCalculosCuerda
        function InfoCalculosCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuántos pasos de cálculo se realizan en la simulación, determinando su duración total.', 'Número de cálculos', 'Icon', 'info');                                                            
        end

        % Button pushed function: InfoCalculosViga
        function InfoCalculosVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Define cuántos pasos de cálculo se realizan en la simulación, determinando su duración total.', 'Número de cálculos', 'Icon', 'info');                                                            
        end

        % Button pushed function: InfoDimensionMembrana
        function InfoDimensionMembranaButtonPushed(app, event)
            uialert(app.UIFigure, 'Determina el tamaño que tendrá la malla donde se mostrará la membrana (nxn).', 'Dimension de la Membrana', 'Icon', 'info');                                                                        
        end

        % Button pushed function: InfoParticulasCuerda
        function InfoParticulasCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Especifica cuántas partículas hay en la simulación, afectando la complejidad y detalle del modelo.', 'Número de partículas', 'Icon', 'info');                                                                      
        end

        % Button pushed function: InfoFuerzaExternaCuerda
        function InfoFuerzaExternaCuerdaButtonPushed(app, event)
            uialert(app.UIFigure, 'Determina en qué eje (X,Y) se le aplicará una fuerza al iniciar el movimiento.', 'Fuerza Externa', 'Icon', 'info');                                                                                  
        end

        % Button pushed function: InfoDimensionViga
        function InfoDimensionVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Determina las dimensiones que tendrá en cada uno de sus ejes (X,Y,Z).', 'Dimensión de la Viga', 'Icon', 'info');                                                                                
        end

        % Button pushed function: InfoRompimientoViga
        function InfoRompimientoVigaButtonPushed(app, event)
            uialert(app.UIFigure, 'Establece el límite de cuánto pueden estirarse las conexiones entre las partículas antes de romperse.', 'Constante de Rompimiento', 'Icon', 'info');                                                                                            
        end

        % Button pushed function: InfoRompimientoVigaComp
        function InfoRompimientoVigaCompButtonPushed(app, event)
            uialert(app.UIFigure, 'Establece el límite de cuánto pueden estirarse las conexiones entre las partículas de la viga antes de romperse.', 'Constante de Rompimiento', 'Icon', 'info');                                                                                                        
        end

        % Button pushed function: InfoDebilitamientoVigaComp
        function InfoDebilitamientoVigaCompButtonPushed(app, event)
            uialert(app.UIFigure, ' Controla cuánto se debilita el material cuando se aplica fuerza de oscilamiento.', 'Constante de Debilitamiento', 'Icon', 'info');                                                                                                        
        end

        % Value changing function: ResorteMembrana
        function ResorteMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteMembranaTextValue.Text = num2str(round(changingValue,2));
        end

        % Value changing function: AmortiguamientoMembrana
        function AmortiguamientoMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoMembranaTextValue.Text = num2str(round(changingValue,2));
        end

        % Value changing function: TiempoMembrana
        function TiempoMembranaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoMembranaTextValue.Text = num2str(round(changingValue, 5));
        end

        % Value changing function: ResorteCuerda
        function ResorteCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteCuerdaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: AmortiguamientoCuerda
        function AmortiguamientoCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoCuerdaTextValue.Text = num2str(round(changingValue, 3));
        end

        % Value changing function: TiempoCuerda
        function TiempoCuerdaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoCuerdaTextValue.Text = num2str(round(changingValue, 4));
        end

        % Value changing function: ResorteViga
        function ResorteVigaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteVigaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: AmortiguamientoViga
        function AmortiguamientoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoVigaTextValue.Text = num2str(round(changingValue, 3));
        end

        % Value changing function: TiempoViga
        function TiempoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoVigaTextValue.Text = num2str(round(changingValue, 4));
        end

        % Value changing function: RompimientoViga
        function RompimientoVigaValueChanging(app, event)
            changingValue = event.Value;
            app.RompimientoVigaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: RompimientoVigaCompuesta
        function RompimientoVigaCompuestaValueChanging(app, event)
            changingValue = event.Value;
            app.KResorteVigaCompuestaTextValue.Text = num2str(round(changingValue));
        end

        % Value changing function: DebilitamientoVigaCompuesta
        function DebilitamientoVigaCompuestaValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoVigaCompuestaTextValue.Text = num2str(round(changingValue));
        end

        % Callback function
        function TiempoVigaCompuestaValueChanging(app, event)
            changingValue = event.Value;
            app.TiempoVigaCompuestaTextValue.Text = num2str(round(changingValue, 4));
        end

        % Value changed function: RompimientoVigaCompuesta
        function RompimientoVigaCompuestaValueChanged(app, event)
            app.NivRompimiento = app.RompimientoVigaCompuesta.Value;
            
        end

        % Value changed function: DebilitamientoVigaCompuesta
        function DebilitamientoVigaCompuestaValueChanged(app, event)
            app.KDebilitamiento = app.DebilitamientoVigaCompuesta.Value;
            
        end

        % Callback function
        function TiempoVigaCompuestaValueChanged(app, event)
            app.KTiempo = app.TiempoVigaCompuesta.Value;
            
        end

        % Value changing function: TiempoentreoscilacionesSSlider
        function TiempoentreoscilacionesSSliderValueChanging(app, event)
            changingValue = event.Value;
            app.KAmortiguamientoVigaCompuestaTextValue_2.Text = num2str(round(changingValue, 1));
        end

        % Button pushed function: InfoDebilitamientoVigaComp_2
        function InfoDebilitamientoVigaComp_2ButtonPushed(app, event)
            uialert(app.UIFigure, ' Controla el intervalo entre oscilaciones que se mueven en el Eje X de la estructura.', 'Tiempo en oscilaciones', 'Icon', 'info');
        end

        % Callback function: not associated with a component
        function InfoDebilitamientoVigaComp_3ButtonPushed(app, event)
            uialert(app.UIFigure, ' Controla la fuerza con la que oscila el eje X en la interfaz.', 'Fuerza del movimiento', 'Icon', 'info');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Tag = 'SimulationAxes';
            app.SimulacindevibracionesencuerposslidosLabel = uilabel(app.UIFigure);
            app.SimulacindevibracionesencuerposslidosLabel.Text = 'Simulación de vibraciones en cuerpos sólidos';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.ESCOMIPNLabel = uilabel(app.UIFigure);
            app.ESCOMIPNLabel.Text = 'ESCOM - IPN ';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.MembranaTab = uitab(app.TabGroup);
            app.MembranaTab.Title = 'Membrana';
            app.ParmetrosLabel = uilabel(app.MembranaTab);
            app.ParmetrosLabel.Text = 'Parámetros';
            app.NmerodeclculosEditFieldLabel = uilabel(app.MembranaTab);
            app.NmerodeclculosEditFieldLabel.Text = 'Número de cálculos';
            app.CalculosMembrana = uieditfield(app.MembranaTab, 'numeric');
            app.CalculosMembrana.ValueChangedFcn = createCallbackFcn(app, @CalculosMembranaValueChanged, true);
            app.DimensindelamembranaLabel = uilabel(app.MembranaTab);
            app.DimensindelamembranaLabel.Text = 'Dimensión de la membrana';
            app.DimMembrana = uieditfield(app.MembranaTab, 'numeric');
            app.DimMembrana.ValueChangedFcn = createCallbackFcn(app, @DimMembranaValueChanged, true);
            app.ConstantederesorteNmLabel = uilabel(app.MembranaTab);
            app.ConstantederesorteNmLabel.Text = 'Constante de resorte (N/m)';
            app.ResorteMembrana = uislider(app.MembranaTab);
            app.ResorteMembrana.Limits = [0.1 10];
            app.ResorteMembrana.ValueChangedFcn = createCallbackFcn(app, @ResorteMembranaValueChanged, true);
            app.ResorteMembrana.ValueChangingFcn = createCallbackFcn(app, @ResorteMembranaValueChanging, true);
            app.ResorteMembrana.Value = 0.1;
            app.ConstantedeamortiguamientoNmLabel = uilabel(app.MembranaTab);
            app.ConstantedeamortiguamientoNmLabel.Text = 'Constante de amortiguamiento (N/m)';
            app.AmortiguamientoMembrana = uislider(app.MembranaTab);
            app.AmortiguamientoMembrana.Limits = [0.01 1];
            app.AmortiguamientoMembrana.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoMembranaValueChanged, true);
            app.AmortiguamientoMembrana.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoMembranaValueChanging, true);
            app.AmortiguamientoMembrana.Value = 0.01;
            app.IntervalodeTiemposLabel = uilabel(app.MembranaTab);
            app.IntervalodeTiemposLabel.Text = 'Intervalo de Tiempo (s)';
            app.TiempoMembrana = uislider(app.MembranaTab);
            app.TiempoMembrana.Limits = [0.01 0.0199];
            app.TiempoMembrana.ValueChangedFcn = createCallbackFcn(app, @TiempoMembranaValueChanged, true);
            app.TiempoMembrana.ValueChangingFcn = createCallbackFcn(app, @TiempoMembranaValueChanging, true);
            app.TiempoMembrana.Value = 0.01;
            app.InfoCalculosMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoCalculosMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoCalculosMembranaButtonPushed, true);
            app.InfoCalculosMembrana.Text = '?';
            app.InfoDimensionMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoDimensionMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoDimensionMembranaButtonPushed, true);
            app.InfoDimensionMembrana.Text = '?';
            app.InfoResorteMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoResorteMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoResorteMembranaButtonPushed, true);
            app.InfoResorteMembrana.Text = '?';
            app.InfoAmortiguamientoMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoAmortiguamientoMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoAmortiguamientoMembranaButtonPushed, true);
            app.InfoAmortiguamientoMembrana.Text = '?';
            app.InfoTiempoMembrana = uibutton(app.MembranaTab, 'push');
            app.InfoTiempoMembrana.ButtonPushedFcn = createCallbackFcn(app, @InfoTiempoMembranaButtonPushed, true);
            app.InfoTiempoMembrana.Text = '?';
            app.ValorLabel = uilabel(app.MembranaTab);
            app.ValorLabel.Text = 'Valor:';
            app.ValorLabel_2 = uilabel(app.MembranaTab);
            app.ValorLabel_2.Text = 'Valor:';
            app.ValorLabel_3 = uilabel(app.MembranaTab);
            app.ValorLabel_3.Text = 'Valor:';
            app.KResorteMembranaTextValue = uilabel(app.MembranaTab);
            app.KResorteMembranaTextValue.Text = '0';
            app.KAmortiguamientoMembranaTextValue = uilabel(app.MembranaTab);
            app.KAmortiguamientoMembranaTextValue.Text = '0';
            app.TiempoMembranaTextValue = uilabel(app.MembranaTab);
            app.TiempoMembranaTextValue.Text = '0';
            app.CuerdaTab = uitab(app.TabGroup);
            app.CuerdaTab.Title = 'Cuerda';
            app.ParmetrosLabel_2 = uilabel(app.CuerdaTab);
            app.ParmetrosLabel_2.Text = 'Parámetros';
            app.FuerzaExternaenNewtonsLabel = uilabel(app.CuerdaTab);
            app.FuerzaExternaenNewtonsLabel.Text = 'Fuerza Externa (en Newtons)';
            app.ConstantederesorteSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantederesorteSliderLabel_2.Text = 'Constante de resorte              (N/m)';
            app.ResorteCuerda = uislider(app.CuerdaTab);
            app.ResorteCuerda.Limits = [100 300];
            app.ResorteCuerda.ValueChangedFcn = createCallbackFcn(app, @ResorteCuerdaValueChanged, true);
            app.ResorteCuerda.ValueChangingFcn = createCallbackFcn(app, @ResorteCuerdaValueChanging, true);
            app.ResorteCuerda.Value = 100;
            app.ConstantedeamortiguamientoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.ConstantedeamortiguamientoSliderLabel_2.Text = 'Constante de amortiguamiento (N/m)';
            app.AmortiguamientoCuerda = uislider(app.CuerdaTab);
            app.AmortiguamientoCuerda.Limits = [0.001 0.2];
            app.AmortiguamientoCuerda.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoCuerdaValueChanged, true);
            app.AmortiguamientoCuerda.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoCuerdaValueChanging, true);
            app.AmortiguamientoCuerda.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_2 = uilabel(app.CuerdaTab);
            app.IntervalodeTiempoSliderLabel_2.Text = 'Intervalo de Tiempo (s)';
            app.TiempoCuerda = uislider(app.CuerdaTab);
            app.TiempoCuerda.Limits = [0.0001 0.03];
            app.TiempoCuerda.ValueChangedFcn = createCallbackFcn(app, @TiempoCuerdaValueChanged, true);
            app.TiempoCuerda.ValueChangingFcn = createCallbackFcn(app, @TiempoCuerdaValueChanging, true);
            app.TiempoCuerda.Value = 0.0001;
            app.XSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.XSpinnerLabel_2.Text = 'X';
            app.FextXCuerda = uispinner(app.CuerdaTab);
            app.FextXCuerda.ValueChangedFcn = createCallbackFcn(app, @FextXCuerdaValueChanged, true);
            app.YSpinnerLabel_2 = uilabel(app.CuerdaTab);
            app.YSpinnerLabel_2.Text = 'Y';
            app.FextYCuerda = uispinner(app.CuerdaTab);
            app.FextYCuerda.ValueChangedFcn = createCallbackFcn(app, @FextYCuerdaValueChanged, true);
            app.NmerodepartculasEditField_2Label = uilabel(app.CuerdaTab);
            app.NmerodepartculasEditField_2Label.Text = 'Número de partículas';
            app.ParticulasCuerda = uieditfield(app.CuerdaTab, 'numeric');
            app.ParticulasCuerda.ValueChangedFcn = createCallbackFcn(app, @ParticulasCuerdaValueChanged, true);
            app.NmerodeclculosEditField_2Label = uilabel(app.CuerdaTab);
            app.NmerodeclculosEditField_2Label.Text = 'Número de cálculos';
            app.CalculosCuerda = uieditfield(app.CuerdaTab, 'numeric');
            app.CalculosCuerda.ValueChangedFcn = createCallbackFcn(app, @CalculosCuerdaValueChanged, true);
            app.InfoCalculosCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoCalculosCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoCalculosCuerdaButtonPushed, true);
            app.InfoCalculosCuerda.Text = '?';
            app.InfoParticulasCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoParticulasCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoParticulasCuerdaButtonPushed, true);
            app.InfoParticulasCuerda.Text = '?';
            app.InfoResorteCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoResorteCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoResorteCuerdaButtonPushed, true);
            app.InfoResorteCuerda.Text = '?';
            app.InfoAmortiguamientoCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoAmortiguamientoCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoAmortiguamientoCuerdaButtonPushed, true);
            app.InfoAmortiguamientoCuerda.Text = '?';
            app.InfoTiempoCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoTiempoCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoTiempoCuerdaButtonPushed, true);
            app.InfoTiempoCuerda.Text = '?';
            app.InfoFuerzaExternaCuerda = uibutton(app.CuerdaTab, 'push');
            app.InfoFuerzaExternaCuerda.ButtonPushedFcn = createCallbackFcn(app, @InfoFuerzaExternaCuerdaButtonPushed, true);
            app.InfoFuerzaExternaCuerda.Text = '?';
            app.ValorLabel_4 = uilabel(app.CuerdaTab);
            app.ValorLabel_4.Text = 'Valor:';
            app.ValorLabel_5 = uilabel(app.CuerdaTab);
            app.ValorLabel_5.Text = 'Valor:';
            app.ValorLabel_6 = uilabel(app.CuerdaTab);
            app.ValorLabel_6.Text = 'Valor:';
            app.KResorteCuerdaTextValue = uilabel(app.CuerdaTab);
            app.KResorteCuerdaTextValue.Text = '0';
            app.KAmortiguamientoCuerdaTextValue = uilabel(app.CuerdaTab);
            app.KAmortiguamientoCuerdaTextValue.Text = '0';
            app.TiempoCuerdaTextValue = uilabel(app.CuerdaTab);
            app.TiempoCuerdaTextValue.Text = '0';
            app.VigaTab = uitab(app.TabGroup);
            app.VigaTab.Title = 'Viga';
            app.ParmetrosLabel_3 = uilabel(app.VigaTab);
            app.ParmetrosLabel_3.Text = 'Parámetros';
            app.DimensindelamallaEditFieldLabel_3 = uilabel(app.VigaTab);
            app.DimensindelamallaEditFieldLabel_3.Text = 'Dimensión de la viga (en metros)';
            app.ConstantederesorteSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantederesorteSliderLabel_3.Text = 'Constante de resorte (N/m)';
            app.ResorteViga = uislider(app.VigaTab);
            app.ResorteViga.Limits = [1 1500];
            app.ResorteViga.MajorTicks = [1 250 500 750 1000 1250 1500];
            app.ResorteViga.ValueChangedFcn = createCallbackFcn(app, @ResorteVigaValueChanged, true);
            app.ResorteViga.ValueChangingFcn = createCallbackFcn(app, @ResorteVigaValueChanging, true);
            app.ResorteViga.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_3 = uilabel(app.VigaTab);
            app.ConstantedeamortiguamientoSliderLabel_3.Text = 'Constante de amortiguamiento (N/m)';
            app.AmortiguamientoViga = uislider(app.VigaTab);
            app.AmortiguamientoViga.Limits = [0.001 1];
            app.AmortiguamientoViga.ValueChangedFcn = createCallbackFcn(app, @AmortiguamientoVigaValueChanged, true);
            app.AmortiguamientoViga.ValueChangingFcn = createCallbackFcn(app, @AmortiguamientoVigaValueChanging, true);
            app.AmortiguamientoViga.Value = 0.001;
            app.IntervalodeTiempoSliderLabel_3 = uilabel(app.VigaTab);
            app.IntervalodeTiempoSliderLabel_3.Text = 'Intervalo de Tiempo (s)';
            app.TiempoViga = uislider(app.VigaTab);
            app.TiempoViga.Limits = [0.0001 0.01];
            app.TiempoViga.MajorTicks = [0.0001 0.0034 0.0067 0.01];
            app.TiempoViga.ValueChangedFcn = createCallbackFcn(app, @TiempoVigaValueChanged, true);
            app.TiempoViga.ValueChangingFcn = createCallbackFcn(app, @TiempoVigaValueChanging, true);
            app.TiempoViga.Value = 0.0001;
            app.NmerodecalculosEditFieldLabel = uilabel(app.VigaTab);
            app.NmerodecalculosEditFieldLabel.Text = 'Número de calculos';
            app.CalculosViga = uieditfield(app.VigaTab, 'numeric');
            app.XSpinnerLabel_5 = uilabel(app.VigaTab);
            app.XSpinnerLabel_5.Text = 'X';
            app.DimXViga = uispinner(app.VigaTab);
            app.DimXViga.Limits = [0 Inf];
            app.DimXViga.Value = 2;
            app.YSpinnerLabel_5 = uilabel(app.VigaTab);
            app.YSpinnerLabel_5.Text = 'Y';
            app.DimYViga = uispinner(app.VigaTab);
            app.DimYViga.Limits = [0 Inf];
            app.DimYViga.Value = 1;
            app.ZSpinnerLabel_5 = uilabel(app.VigaTab);
            app.ZSpinnerLabel_5.Text = 'Z';
            app.DimZViga = uispinner(app.VigaTab);
            app.DimZViga.Limits = [2 Inf];
            app.DimZViga.Value = 2;
            app.NiveldeRompimientoSliderLabel = uilabel(app.VigaTab);
            app.NiveldeRompimientoSliderLabel.Text = 'Nivel de Rompimiento';
            app.RompimientoViga = uislider(app.VigaTab);
            app.RompimientoViga.ValueChangedFcn = createCallbackFcn(app, @RompimientoVigaValueChanged, true);
            app.RompimientoViga.ValueChangingFcn = createCallbackFcn(app, @RompimientoVigaValueChanging, true);
            app.InfoCalculosViga = uibutton(app.VigaTab, 'push');
            app.InfoCalculosViga.ButtonPushedFcn = createCallbackFcn(app, @InfoCalculosVigaButtonPushed, true);
            app.InfoCalculosViga.Text = '?';
            app.InfoDimensionViga = uibutton(app.VigaTab, 'push');
            app.InfoDimensionViga.ButtonPushedFcn = createCallbackFcn(app, @InfoDimensionVigaButtonPushed, true);
            app.InfoDimensionViga.Text = '?';
            app.InfoResorteViga = uibutton(app.VigaTab, 'push');
            app.InfoResorteViga.ButtonPushedFcn = createCallbackFcn(app, @InfoResorteVigaButtonPushed, true);
            app.InfoResorteViga.Text = '?';
            app.InfoAmortiguamientoViga = uibutton(app.VigaTab, 'push');
            app.InfoAmortiguamientoViga.ButtonPushedFcn = createCallbackFcn(app, @InfoAmortiguamientoVigaButtonPushed, true);
            app.InfoAmortiguamientoViga.Text = '?';
            app.InfoTiempoViga = uibutton(app.VigaTab, 'push');
            app.InfoTiempoViga.ButtonPushedFcn = createCallbackFcn(app, @InfoTiempoVigaButtonPushed, true);
            app.InfoTiempoViga.Text = '?';
            app.InfoRompimientoViga = uibutton(app.VigaTab, 'push');
            app.InfoRompimientoViga.ButtonPushedFcn = createCallbackFcn(app, @InfoRompimientoVigaButtonPushed, true);
            app.InfoRompimientoViga.Text = '?';
            app.ValorLabel_7 = uilabel(app.VigaTab);
            app.ValorLabel_7.Text = 'Valor:';
            app.ValorLabel_8 = uilabel(app.VigaTab);
            app.ValorLabel_8.Text = 'Valor:';
            app.ValorLabel_9 = uilabel(app.VigaTab);
            app.ValorLabel_9.Text = 'Valor:';
            app.ValorLabel_10 = uilabel(app.VigaTab);
            app.ValorLabel_10.Text = 'Valor:   %';
            app.KResorteVigaTextValue = uilabel(app.VigaTab);
            app.KResorteVigaTextValue.Text = '1';
            app.KAmortiguamientoVigaTextValue = uilabel(app.VigaTab);
            app.KAmortiguamientoVigaTextValue.Text = '0.001';
            app.TiempoVigaTextValue = uilabel(app.VigaTab);
            app.TiempoVigaTextValue.Text = '0.0001';
            app.RompimientoVigaTextValue = uilabel(app.VigaTab);
            app.RompimientoVigaTextValue.Text = '0';
            app.VigaCompuestaTab = uitab(app.TabGroup);
            app.VigaCompuestaTab.Title = 'Viga Compuesta';
            app.ParmetrosLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ParmetrosLabel_4.Text = 'Parámetros';
            app.ConstantederesorteSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantederesorteSliderLabel_4.Text = 'Nivel de rompimiento';
            app.RompimientoVigaCompuesta = uislider(app.VigaCompuestaTab);
            app.RompimientoVigaCompuesta.Limits = [0.001 10];
            app.RompimientoVigaCompuesta.ValueChangedFcn = createCallbackFcn(app, @RompimientoVigaCompuestaValueChanged, true);
            app.RompimientoVigaCompuesta.ValueChangingFcn = createCallbackFcn(app, @RompimientoVigaCompuestaValueChanging, true);
            app.RompimientoVigaCompuesta.Value = 1;
            app.ConstantedeamortiguamientoSliderLabel_4 = uilabel(app.VigaCompuestaTab);
            app.ConstantedeamortiguamientoSliderLabel_4.Text = 'Constante de amortiguamiento (N/m):';
            app.DebilitamientoVigaCompuesta = uislider(app.VigaCompuestaTab);
            app.DebilitamientoVigaCompuesta.Limits = [0 20];
            app.DebilitamientoVigaCompuesta.ValueChangedFcn = createCallbackFcn(app, @DebilitamientoVigaCompuestaValueChanged, true);
            app.DebilitamientoVigaCompuesta.ValueChangingFcn = createCallbackFcn(app, @DebilitamientoVigaCompuestaValueChanging, true);
            app.KResorteVigaCompuestaTextValue = uilabel(app.VigaCompuestaTab);
            app.KResorteVigaCompuestaTextValue.Text = '0.001';
            app.KAmortiguamientoVigaCompuestaTextValue = uilabel(app.VigaCompuestaTab);
            app.KAmortiguamientoVigaCompuestaTextValue.Text = '0';
            app.InfoRompimientoVigaComp = uibutton(app.VigaCompuestaTab, 'push');
            app.InfoRompimientoVigaComp.ButtonPushedFcn = createCallbackFcn(app, @InfoRompimientoVigaCompButtonPushed, true);
            app.InfoRompimientoVigaComp.Text = '?';
            app.InfoDebilitamientoVigaComp = uibutton(app.VigaCompuestaTab, 'push');
            app.InfoDebilitamientoVigaComp.ButtonPushedFcn = createCallbackFcn(app, @InfoDebilitamientoVigaCompButtonPushed, true);
            app.InfoDebilitamientoVigaComp.Text = '?';
            app.ValorLabel_13 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_13.Text = 'Valor:';
            app.ValorLabel_14 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_14.Text = 'Valor:  ';
            app.InfoDebilitamientoVigaComp_2 = uibutton(app.VigaCompuestaTab, 'push');
            app.InfoDebilitamientoVigaComp_2.ButtonPushedFcn = createCallbackFcn(app, @InfoDebilitamientoVigaComp_2ButtonPushed, true);
            app.InfoDebilitamientoVigaComp_2.Text = '?';
            app.TiempodeoscilacionSLabel = uilabel(app.VigaCompuestaTab);
            app.TiempodeoscilacionSLabel.Text = 'Tiempo entre oscilaciones (S):';
            app.TiempoentreoscilacionesSSlider = uislider(app.VigaCompuestaTab);
            app.TiempoentreoscilacionesSSlider.Limits = [1 10];
            app.TiempoentreoscilacionesSSlider.ValueChangingFcn = createCallbackFcn(app, @TiempoentreoscilacionesSSliderValueChanging, true);
            app.TiempoentreoscilacionesSSlider.Value = 1;
            app.ValorLabel_15 = uilabel(app.VigaCompuestaTab);
            app.ValorLabel_15.Text = 'Valor:   ';
            app.KAmortiguamientoVigaCompuestaTextValue_2 = uilabel(app.VigaCompuestaTab);
            app.KAmortiguamientoVigaCompuestaTextValue_2.Text = '1';
            app.SimularButton = uibutton(app.UIFigure, 'push');
            app.SimularButton.ButtonPushedFcn = createCallbackFcn(app, @SimularButtonPushed, true);
            app.SimularButton.Text = 'Simular';
            app.DetenerButton = uibutton(app.UIFigure, 'push');
            app.DetenerButton.ButtonPushedFcn = createCallbackFcn(app, @DetenerButtonPushed, true);
            app.DetenerButton.Text = 'Detener';
            app.GenerarondaygrficadeFourierButton = uibutton(app.UIFigure, 'push');
            app.GenerarondaygrficadeFourierButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarondaygrficadeFourierButtonPushed, true);
            app.GenerarondaygrficadeFourierButton.Visible = 'off';
            app.GenerarondaygrficadeFourierButton.Text = 'Generar onda y gráfica de Fourier';
            app.LimpiarButton = uibutton(app.UIFigure, 'push');
            app.LimpiarButton.ButtonPushedFcn = createCallbackFcn(app, @LimpiarButtonPushed, true);
            app.LimpiarButton.Text = 'Limpiar';
            app.Hyperlink = uihyperlink(app.UIFigure);
            app.Hyperlink.URL = 'https://simusis.helpdocsonline.com/home';
            app.Hyperlink.Text = 'Manual de usuario';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TTV2_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end