classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SelectSimulationDropDown  matlab.ui.control.DropDown
        SelectSimulationLabel     matlab.ui.control.Label
        CleanaxesButton           matlab.ui.control.Button
        StopsimulationButton      matlab.ui.control.Button
        StartsimulationButton     matlab.ui.control.Button
        SimulationAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description
        % Flag to control the simulation loop
        StopFlag = false;
    end
    
    methods (Access = private)
        
        function initializeMalla(app)
            % Reset the stop flag
            app.StopFlag = false;
            
            % Simulation parameters
            vec = 2; % vecinos considerados en calculo
            K = 5; % Constante del resorte entre la masa
            K2 = 0.5; % Amortiguamiento
            T = 0.01; % Tiempo
            D = 60; % Dimension
            
            % Initialization
            H = zeros(D); % posición 
            Hf = zeros(D); % Auxiliar
            V = zeros(D); % velocidad
            H(D/4-1:D/4+1, D/4-1:D/4+1) = 1; % impulso
            H(3*D/4-1:3*D/4+1, 3*D/4-1:3*D/4+1) = 1; % impulso
        
            % Main loop for calculations
            for m = 1:1000
                % Check if the stop button was pressed
                if app.StopFlag
                    % cla(app.SimulationAxes); used for clean
                    break;
                end

                % Plot the current state
                surf(app.SimulationAxes, H, 'FaceColor', 'interp', 'FaceLighting', 'phong');
                camlight(app.SimulationAxes, 'right');
                axis(app.SimulationAxes, [0, D, 0, D, -1, 1]);
                drawnow;
                
                % Perform the calculations
                for j = vec+1:D-vec
                    for i = vec+1:D-vec
                        Hm = sum(sum(H(i-vec:i+vec, j-vec:j+vec))) - H(i, j);
                        X = ((2*vec) + 1)^2 * H(i, j) - Hm;
                        V(i, j) = V(i, j) - (K * X + K2 * V(i, j)) * T;
                        Hf(i, j) = H(i, j) + V(i, j) * T;
                    end
                end
                H = Hf;
                pause(0.001);
            end
        end

        function initializeCuerda(app)
            % Reset the stop flag
            app.StopFlag = false;

            %SIMULA CUERDA
            clc
            clearvars;
            close all;
            K1=500;%%constante de resorte
            K2=.2;%%amortiguamiento
            rompimiento=100;%%nivel de rompimiento
            Fext=[0;-9.8;0];%%Fuerza externa
            T=0.01;%%intervalo de tiempo
            numgrap=0;%%indice de graficas
            sonido=1;%%indice de onda
            %%%INICIALIZACI?N
            numPart=10;
            k=0;%%indice para part?culas
            for x=1:numPart
                k=k+1;
                p(k).r=[x;0;0]; p(k).v=[0;0;0];
                p(k).fija=0;
                w=0;%%indice para vecinos
                for nx=x-1:2:x+1% vecinos de la particula + ellamisma              
                   if (1<=nx & nx<=numPart)
       	             w=w+1;
       	             p(k).n(w).ind=nx;%indice del vecino w
                      p(k).n(w).disteq=0.1;
                   end
                end
                p(k).numVec=w;
            end%%Termina inicializaci?n
            p(1).fija=1;
            p(numPart).fija=1;
            pf=p;%auxiliar
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            numcalculos=2000;%%24cuad p/seg * 10 seg * 5 calculos por cuadro
            Amp=0;
            for k=1:numcalculos%%CALCULOS
  	            %% CALCULOS
                for in=1+1:numPart-1
                    if (in==floor(numPart/3) && k<70)
                        %Amp=Amp+.5;
                        Fext=[0;-30;0];
                    else
                        Fext=[0;0;0];      
	                end;%%libera sistema de fuerza externa
                    if (p(in).fija==0)%exeptua el calculo particulas fijas
      	                DX=[0;0;0]; %calcula el desplazamiento
                        for w=1:p(in).numVec% vecinos de la particula + ellamisma
  		                    if (p(in).n(w).ind ~= 0) %existe o no esta roto
  			                    DR=p(in).r-p(p(in).n(w).ind).r;%vector posici?n relativa
                                dr=norm(DR);
                            end 
			                % if dr==0  in;dr=0.001;      end
        		            if 0%dr>(rompimiento*p(in).n(w).disteq)%rompimiento es la fracci?n a superar
           		                pf(in).n(w).ind=0;
        		            end            
     			            r=DR/dr;%unitario en la direccion de desplazamiento              
        		            DX=DX+(dr-p(in).n(w).disteq)*r;%vector desplazamiento
	    	            end        
       	                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
       	                pf(in).v=p(in).v+(-K1*DX-K2*p(in).v+Fext)*T;
       	                pf(in).r=p(in).r+pf(in).v*T;              
                    end%%exeptua particulas fijas                  
                end
                p=pf;
               %%%%%%%%%%%%%%%%%%%%% terminan calculos %%%%%%%%%%%%%%%   
                if (mod(k,2)==0)%para formar onda 
                    S(sonido)=p(numPart/2).r(2);%4indPtoMed).r(2);
                    sonido=sonido+1;
                end
                if 1 %GRAFICACION
                    for x=1:numPart
                        XX(x)=p(x).r(1);
                        YY(x)=p(x).r(2);
                    end
                    plot(app.SimulationAxes,XX,YY,'.-');
                    axis([-1,numPart+.5,-1,1]);
                    pause(0.001);
                end%graficacion
            end;%%terminan k calculos
            % figure; plot(S);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartsimulationButton
        function StartsimulationButtonPushed(app, event)
            selectedSimulation = app.SelectSimulationDropDown.Value;
            
            if strcmp(selectedSimulation, 'Malla')
                app.initializeMalla();
            elseif strcmp(selectedSimulation, 'Cuerda')
                app.initializeCuerda();
            end
        end

        % Button pushed function: StopsimulationButton
        function StopsimulationButtonPushed(app, event)
            % Set the stop flag to true
            app.StopFlag = true;
        end

        % Button pushed function: CleanaxesButton
        function CleanaxesButtonPushed(app, event)
            app.StopFlag = true;
            cla(app.SimulationAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SimulationAxes = uiaxes(app.UIFigure);
            title(app.SimulationAxes, 'Simulation')
            xlabel(app.SimulationAxes, 'X')
            ylabel(app.SimulationAxes, 'Y')
            zlabel(app.SimulationAxes, 'Z')
            app.SimulationAxes.Tag = 'SimulationAxes';
            app.StartsimulationButton = uibutton(app.UIFigure, 'push');
            app.StartsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StartsimulationButtonPushed, true);
            app.StartsimulationButton.Tag = 'StartButton';
            app.StartsimulationButton.Text = 'Start simulation';
            app.StopsimulationButton = uibutton(app.UIFigure, 'push');
            app.StopsimulationButton.ButtonPushedFcn = createCallbackFcn(app, @StopsimulationButtonPushed, true);
            app.StopsimulationButton.Tag = 'StopButton';
            app.StopsimulationButton.Text = 'Stop simulation';
            app.CleanaxesButton = uibutton(app.UIFigure, 'push');
            app.CleanaxesButton.ButtonPushedFcn = createCallbackFcn(app, @CleanaxesButtonPushed, true);
            app.CleanaxesButton.Tag = 'StopButton';
            app.CleanaxesButton.Text = 'Clean axes';
            app.SelectSimulationLabel = uilabel(app.UIFigure);
            app.SelectSimulationLabel.Text = 'Select Simulation:';
            app.SelectSimulationDropDown = uidropdown(app.UIFigure);
            app.SelectSimulationDropDown.Items = {'', 'Malla', 'Cuerda', 'Viga 1', 'Viga 2'};
            app.SelectSimulationDropDown.Value = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end